classdef opto_analyser_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        opto_analyserUIFigure           matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        ImageIOLabel                    matlab.ui.control.Label
        NoofseedpointsLabel             matlab.ui.control.Label
        ConfirmSelectionLabel           matlab.ui.control.Label
        ErrorMessagesLabel              matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        SeedpointsselectedLabel         matlab.ui.control.Label
        LoadReleaseDataButton           matlab.ui.control.Button
        LoadRecoveryDataButton          matlab.ui.control.Button
        OKButton                        matlab.ui.control.Button
        RESETButton                     matlab.ui.control.Button
        CenterPanel                     matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        ChannelselectionTab             matlab.ui.container.Tab
        Channel1Label                   matlab.ui.control.Label
        FrameSliderLabel                matlab.ui.control.Label
        FrameSlider                     matlab.ui.control.Slider
        Channel1ButtonGroup             matlab.ui.container.ButtonGroup
        NoneButton_5                    matlab.ui.control.RadioButton
        MitochondriaButton              matlab.ui.control.RadioButton
        ProteinButton                   matlab.ui.control.RadioButton
        NucleusButton                   matlab.ui.control.RadioButton
        Channel2ButtonGroup             matlab.ui.container.ButtonGroup
        NoneButton_6                    matlab.ui.control.RadioButton
        MitochondriaButton_2            matlab.ui.control.RadioButton
        ProteinButton_2                 matlab.ui.control.RadioButton
        NucleusButton_2                 matlab.ui.control.RadioButton
        Channel3ButtonGroup             matlab.ui.container.ButtonGroup
        NoneButton_7                    matlab.ui.control.RadioButton
        MitochondriaButton_3            matlab.ui.control.RadioButton
        ProteinButton_3                 matlab.ui.control.RadioButton
        NucleusButton_3                 matlab.ui.control.RadioButton
        Channel4ButtonGroup             matlab.ui.container.ButtonGroup
        NoneButton_8                    matlab.ui.control.RadioButton
        MitochondriaButton_4            matlab.ui.control.RadioButton
        ProteinButton_4                 matlab.ui.control.RadioButton
        NucleusButton_4                 matlab.ui.control.RadioButton
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        Channel2Label                   matlab.ui.control.Label
        Channel3Label                   matlab.ui.control.Label
        Channel4Label                   matlab.ui.control.Label
        AcceptChannelsandCropImageButton  matlab.ui.control.Button
        AcceptCropButton                matlab.ui.control.Button
        CompartmentviewingpercentilesTab  matlab.ui.container.Tab
        UIAxes7                         matlab.ui.control.UIAxes
        UIAxes7_2                       matlab.ui.control.UIAxes
        PercentileSlider_2Label         matlab.ui.control.Label
        PercentileSlider_2              matlab.ui.control.Slider
        PercentileSlider_3Label         matlab.ui.control.Label
        PercentileSlider_3              matlab.ui.control.Slider
        NucleusLabel                    matlab.ui.control.Label
        MitochondriaLabel               matlab.ui.control.Label
        SeedpointSlider_2Label          matlab.ui.control.Label
        SeedpointSlider_2               matlab.ui.control.Slider
        SeedpointSlider_3Label          matlab.ui.control.Label
        SeedpointSlider_3               matlab.ui.control.Slider
        UIAxes7_3                       matlab.ui.control.UIAxes
        PercentileSliderLabel           matlab.ui.control.Label
        PercentileSlider                matlab.ui.control.Slider
        SeedpointSliderLabel            matlab.ui.control.Label
        SeedpointSlider                 matlab.ui.control.Slider
        AllcompartmentsLabel            matlab.ui.control.Label
        LoadNextWindowProfileButton     matlab.ui.control.Button
        AcceptPercentilesButton         matlab.ui.control.Button
        CellboundarycropTab             matlab.ui.container.Tab
        UIAxes5                         matlab.ui.control.UIAxes
        UIAxes6                         matlab.ui.control.UIAxes
        UIAxes6_2                       matlab.ui.control.UIAxes
        UIAxes6_3                       matlab.ui.control.UIAxes
        ImageSelectionButtonGroup       matlab.ui.container.ButtonGroup
        NullButton_2                    matlab.ui.control.ToggleButton
        Image1Button                    matlab.ui.control.ToggleButton
        Image2Button                    matlab.ui.control.ToggleButton
        Image3Button                    matlab.ui.control.ToggleButton
        Image4Button                    matlab.ui.control.ToggleButton
        UIAxes6_4                       matlab.ui.control.UIAxes
        AllframesLabel                  matlab.ui.control.Label
        RegionframesLabel               matlab.ui.control.Label
        MaxintensityallchannelsLabel    matlab.ui.control.Label
        MaxintensityallchannelsLabel_2  matlab.ui.control.Label
        MaxintensityproteinchannelLabel  matlab.ui.control.Label
        MaxintensityproteinchannelLabel_2  matlab.ui.control.Label
        RoughCropButton                 matlab.ui.control.Button
        ConfirmRoughCropButton          matlab.ui.control.Button
        CellthresholdingTab             matlab.ui.container.Tab
        UIAxes8                         matlab.ui.control.UIAxes
        UIAxes8_2                       matlab.ui.control.UIAxes
        UIAxes8_3                       matlab.ui.control.UIAxes
        Thresholdscale01SliderLabel     matlab.ui.control.Label
        Thresholdscale01Slider          matlab.ui.control.Slider
        Thresholdscale001SliderLabel    matlab.ui.control.Label
        Thresholdscale001Slider         matlab.ui.control.Slider
        Thresholdscale0001SliderLabel   matlab.ui.control.Label
        Thresholdscale0001Slider        matlab.ui.control.Slider
        Thresholdscale00001SliderLabel  matlab.ui.control.Label
        Thresholdscale00001Slider       matlab.ui.control.Slider
        Thresholdvalue000000Label       matlab.ui.control.Label
        AcceptthresholdButton           matlab.ui.control.Button
        CompartmentthresholdingTab      matlab.ui.container.Tab
        UIAxes9                         matlab.ui.control.UIAxes
        UIAxes10                        matlab.ui.control.UIAxes
        UIAxes10_2                      matlab.ui.control.UIAxes
        UIAxes10_3                      matlab.ui.control.UIAxes
        UIAxes10_4                      matlab.ui.control.UIAxes
        UIAxes10_5                      matlab.ui.control.UIAxes
        UIAxes10_6                      matlab.ui.control.UIAxes
        Mitochondriathresholdscale01SliderLabel  matlab.ui.control.Label
        Mitochondriathresholdscale01Slider  matlab.ui.control.Slider
        Mitochondriathresholdscale001SliderLabel  matlab.ui.control.Label
        Mitochondriathresholdscale001Slider  matlab.ui.control.Slider
        Mitochondriathresholdscale0001SliderLabel  matlab.ui.control.Label
        Mitochondriathresholdscale0001Slider  matlab.ui.control.Slider
        Mitochondriathresholdscale00001SliderLabel  matlab.ui.control.Label
        Mitochondriathresholdscale00001Slider  matlab.ui.control.Slider
        Nucleusthresholdscale01SliderLabel  matlab.ui.control.Label
        Nucleusthresholdscale01Slider   matlab.ui.control.Slider
        Nucleusthresholdscale001SliderLabel  matlab.ui.control.Label
        Nucleusthresholdscale001Slider  matlab.ui.control.Slider
        Nucleusthresholdscale0001SliderLabel  matlab.ui.control.Label
        Nucleusthresholdscale0001Slider  matlab.ui.control.Slider
        Nucleusthresholdscale00001SliderLabel  matlab.ui.control.Label
        Nucleusthresholdscale00001Slider  matlab.ui.control.Slider
        Mitochondrialthresholdvalue000000Label  matlab.ui.control.Label
        AcceptthresholdsButton          matlab.ui.control.Button
        InterpolationTab                matlab.ui.container.Tab
        InterpolatedOutputLabel         matlab.ui.control.Label
        UIAxes11_5                      matlab.ui.control.UIAxes
        UIAxes11_6                      matlab.ui.control.UIAxes
        UIAxes11_7                      matlab.ui.control.UIAxes
        UIAxes11_8                      matlab.ui.control.UIAxes
        SavedataandquitButton           matlab.ui.control.Button
        ProceedtonormalisationButton    matlab.ui.control.Button
        BackgroundsubtractionTab        matlab.ui.container.Tab
        UIAxes12                        matlab.ui.control.UIAxes
        ProteinchannelLabel             matlab.ui.control.Label
        FramenumberSliderLabel          matlab.ui.control.Label
        FramenumberSlider               matlab.ui.control.Slider
        SelectBackgroundRegionButton    matlab.ui.control.Button
        AcceptBackgroundRegionButton    matlab.ui.control.Button
        IncludebackgroundsubtractionCheckBox  matlab.ui.control.CheckBox
        NormalisationregionsTab         matlab.ui.container.Tab
        UIAxes13                        matlab.ui.control.UIAxes
        UIAxes13_2                      matlab.ui.control.UIAxes
        ReleasestartLabel               matlab.ui.control.Label
        ReleasestartSlider              matlab.ui.control.Slider
        ReleaseendLabel                 matlab.ui.control.Label
        ReleaseendSlider                matlab.ui.control.Slider
        RecoverystartLabel              matlab.ui.control.Label
        RecoverystartSlider             matlab.ui.control.Slider
        RecoveryendLabel                matlab.ui.control.Label
        RecoveryendSlider               matlab.ui.control.Slider
        AcceptNormalisationRegionsButton  matlab.ui.control.Button
        NumberofwindowsLabel            matlab.ui.control.Label
        NumberofwindowsSlider           matlab.ui.control.Slider
        UIAxes13_3                      matlab.ui.control.UIAxes
        TimeandSSinputTab               matlab.ui.container.Tab
        UIAxes14                        matlab.ui.control.UIAxes
        SteadystateendframeSpinnerLabel  matlab.ui.control.Label
        SteadystateendframeSpinner      matlab.ui.control.Spinner
        TimeinformationButtonGroup      matlab.ui.container.ButtonGroup
        UseframesastimeButton           matlab.ui.control.ToggleButton
        LoadtimedataButton              matlab.ui.control.ToggleButton
        ConfirmButton                   matlab.ui.control.Button
        UIAxes14_2                      matlab.ui.control.UIAxes
        NumberofwindowsSlider_2Label    matlab.ui.control.Label
        NumberofwindowsSlider_2         matlab.ui.control.Slider
        NormalisationplottingTab        matlab.ui.container.Tab
        UIAxes15                        matlab.ui.control.UIAxes
        UIAxes15_2                      matlab.ui.control.UIAxes
        UIAxes15_3                      matlab.ui.control.UIAxes
        UIAxes15_4                      matlab.ui.control.UIAxes
        NormalisationButtonGroup_3      matlab.ui.container.ButtonGroup
        SinglelinearButton              matlab.ui.control.RadioButton
        DoublelinearButton              matlab.ui.control.RadioButton
        NumberofwindowsSlider_3Label    matlab.ui.control.Label
        NumberofwindowsSlider_3         matlab.ui.control.Slider
        ProceedtomodelfitButton         matlab.ui.control.Button
        ButtonGroup                     matlab.ui.container.ButtonGroup
        PrenormalisationButton          matlab.ui.control.RadioButton
        PostnormalisationButton         matlab.ui.control.RadioButton
        SaveandquitButton_2             matlab.ui.control.Button
        ModelfittingTab                 matlab.ui.container.Tab
        LitoffrateEditFieldLabel        matlab.ui.control.Label
        LitoffrateEditField             matlab.ui.control.NumericEditField
        UnlitoffrateEditFieldLabel      matlab.ui.control.Label
        UnlitoffrateEditField           matlab.ui.control.NumericEditField
        LitonrateEditFieldLabel         matlab.ui.control.Label
        LitonrateEditField              matlab.ui.control.NumericEditField
        ImportrateEditFieldLabel        matlab.ui.control.Label
        ImportrateEditField             matlab.ui.control.NumericEditField
        ExportrateEditFieldLabel        matlab.ui.control.Label
        ExportrateEditField             matlab.ui.control.NumericEditField
        UITable2                        matlab.ui.control.Table
        FitmodelButton                  matlab.ui.control.Button
        SaveandquitButton_3             matlab.ui.control.Button
        UnlitonrateEditFieldLabel       matlab.ui.control.Label
        UnlitonrateEditField            matlab.ui.control.NumericEditField
        ResetGuessesButton              matlab.ui.control.Button
        ModelfittingoutputTab           matlab.ui.container.Tab
        UIAxes17                        matlab.ui.control.UIAxes
        UIAxes17_2                      matlab.ui.control.UIAxes
        UIAxes17_3                      matlab.ui.control.UIAxes
        NormalisationButtonGroup_4      matlab.ui.container.ButtonGroup
        SinglelinearButton_3            matlab.ui.control.RadioButton
        DoublelinearButton_3            matlab.ui.control.RadioButton
        NumberofwindowsLabel_2          matlab.ui.control.Label
        NumberofwindowsSlider_5         matlab.ui.control.Slider
        RightPanel                      matlab.ui.container.Panel
        OutputLabel                     matlab.ui.control.Label
        UIAxes11                        matlab.ui.control.UIAxes
        UIAxes11_2                      matlab.ui.control.UIAxes
        UIAxes11_3                      matlab.ui.control.UIAxes
        UIAxes11_4                      matlab.ui.control.UIAxes
        UITable                         matlab.ui.control.Table
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        compartment_data %Structure to store all data for all window profiles
        
        %Release and recovery file input
        release_file
        release_file_path
        recovery_file
        recovery_file_path
        
        %Initial LSM input
        LSM_release % LSM release matrix 
        LSM_recovery %LSM recovery matrix
        total_channels %Total number of channels in the LSM
        LSM_vert_size %Vertical dimension of LSM frame
        LSM_horz_size %Horizontal dimension of LSM frame
        
        %Time info
        total_release_frames %Total number of frames in release
        total_recovery_frames %Total number of frames in recovery
        total_frames %Total number of frames 
        
        %Window profiles info
        seed_points_total %Vector of user determined number of seed points used to determine size of moving window
        window_profile_id % Keeps track of which window profile is currently being analysed.
        seed_point_id %Keeps track of which seed point interval in the current window profile is currently being analysed.
        
        %Channel info
        nuclear_channel %Tracks which channel has the nuclear label
        mitochondrial_channel %Tracks which channel has the mitochondrial label
        protein_channel %Tracks which channel has the protein label
        
        %LSM protein data
        LSM_protein_release %All protein frames of release
        LSM_protein_recovery %All protein frames of recovery
        LSM_protein_all_frames %All protein frames of the whole movie
        
        %ROI adjusted variables
        LSM_protein_frame1 %Protein channel frame 1. Used for zoom ROI
        vertical_min_ROI %Minimum vertical value of ROI
        vertical_max_ROI %Maximum vertical value of ROI
        horizontal_min_ROI %Minimum horizontal value of ROI
        horizontal_max_ROI %Maximum horizontal value of ROI
        LSM_nucleus_release_ROI %Zoomed in nuclear frames for release
        LSM_protein_release_ROI %Zoomed in protein frames for release
        LSM_mitochondria_release_ROI %Zoomed in mitochondrial frames for release
        LSM_nuclear_recovery_ROI %Zoomed in nuclear frames for recovery
        LSM_protein_recovery_ROI %Zoomed in protein frames for release
        LSM_mitochondria_recovery_ROI %Zoomed in mitochondrial frames for release
        LSM_nuclear_movie_ROI % All frames for nuclear channel (release and recovery) at ROI
        LSM_protein_movie_ROI % All frames for protein channel (release and recovery) at ROI
        LSM_mitochondria_movie_ROI % All frames for mitochondrial channel (release and recovery) at ROI
        
        %Maximum intensity projections of ROI
        max_int_release_frames_ROI %Frame by frame maximum intensity projection of the three channels in the ROI during release
        max_int_recovery_frames_ROI %Frame by frame maximum intensity projection of the three channels in the ROI during recovery
        max_int_release_all_ROI %Maximum intensity of all frames in max_int_release_frames_ROI. I.e. this gives the maximum intensity over all channels and all frames of release.
        max_int_recovery_all_ROI %Maximum intensity of all frames in max_int_recovery_frames_ROI. I.e. this gives the maximum intensity over all channels and all frames of recovery.
        max_int_all_movie_ROI %Maximum intensity of max_int_release_all_ROI and max_int_recovery_frames_ROI. I.e. this gives the maximum intensity over all channels and all frames of the whole movie.
        max_int_protein_all_movie_ROI %Maximum intensity of LSM_protein_release_ROI and LSM_protein_recovery_ROI. I.e. this gives the maximum intensity all frames of the protein channel.
        max_int_all_frames_ROI %Max intensity over all channels for each frame in the movie

        
        %Window profiles
        window_start_points %Start points for seedpoint windows in a current window profile
        window_mid_points %Mid points for seedpoint windows in a current window profile
        window_max_nucleus_ROI %Maximum intensity projection of nuclear channel over all frames in that seedpoint window
        window_max_mitochondria_ROI %Maximum intensity projection of mitochondrial channel over all frames in that window
        window_max_protein_ROI %Maximum intensity projection of protein channel over all frames in that window
        window_max_all_channels_ROI %Maximum intensity projection of all channels over all frames in that window
        
        %Percentile allocation
        whole_cell_percentile %Percentile of intensity for the hybrid channel of maximum intensity of protein, mitochondria and nucleus combined for the given window-split
        nuclear_percentile %Percentile of intensity for the nuclear channel for the given window-split 
        mitochondria_percentile %Percentile of intensity for the mitochondrial channel for the given window-split 
        whole_cell_window_percentile_projection %Percentile projection of all frames in each window of the whole cell. Produces an array with z dimension equal to number of windows in that window split
        nucleus_window_percentile_projection %Percentile projection of all frames in each window of the nucleus. Produces an array with z dimension equal to number of windows in that window split
        mitochondria_window_percentile_projection
        whole_cell_window_index %Index of window in window-split that is currently being displayed for whole cell image. Index used for image display purposes only.
        nuclear_window_index %Index of window in window-split that is currently being displayed for nuclear image. Index used for image display purposes only.
        mitochondria_window_index %Index of window in window-split that is currently being displayed for mitochondrial image. Index used for image display purposes only.

        %Binary arrays
        rough_cell_boundary_window % Rough cell boundary for each window of the window-split, generated via user cropping.
        whole_cell_binary_window %Thresholded binary image for the whole cell for each window
        mitochondria_binary_window %Thresholded binary image for the mitochondria for each window
        nucleus_binary_window %Thresholded binary image for the nucleus for each window
        
        %Threshold values
        whole_cell_threshold %Overall threshold for whole cell in that window
        whole_cell_threshold_0_1 %Threshold contribution for 0.1 level slider for whole cell in that window
        whole_cell_threshold_0_01 %Threshold contribution for 0.01 level slider for whole cell in that window
        whole_cell_threshold_0_001 %Threshold contribution for 0.001 level slider for whole cell in that window
        whole_cell_threshold_0_0001 %Threshold contribution for 0.0001 level slider for whole cell in that window
        mitochondria_threshold %Overall threshold for mitochondria in that window
        mitochondria_threshold_0_1 %Threshold contribution for 0.1 level slider for mitochondria in that window
        mitochondria_threshold_0_01 %Threshold contribution for 0.01 level slider for mitochondria in that window
        mitochondria_threshold_0_001 %Threshold contribution for 0.001 level slider for mitochondria in that window
        mitochondria_threshold_0_0001 %Threshold contribution for 0.0001 level slider for mitochondria in that window
        nucleus_threshold %Overall threshold for mitochondria in that window
        nucleus_threshold_0_1 %Threshold contribution for 0.1 level slider for nucleus in that window
        nucleus_threshold_0_01 %Threshold contribution for 0.01 level slider for nucleus in that window
        nucleus_threshold_0_001 %Threshold contribution for 0.001 level slider for nucleus in that window
        nucleus_threshold_0_0001 %Threshold contribution for 0.0001 level slider for nucleus in that window
        whole_cell_thresholds %All threshold levels for whole cell for each windowin current window-split
        mitochondria_thresholds %Mitochondria threshold levels for whole cell for each windowin current window-split
        nucleus_thresholds %Nucleus threshold levels for whole cell for each windowin current window-split
        
        %Compartment populations versus time
        population_cytoplasm %Cytoplasmic total intensity versus time for current window-split
        population_mitochondria %Mitochondrial total intensity versus time for current window-split
        population_nucleus %Nuclear total intensity versus time for current window-split
        population_total %Whole cell total intensity versus time for current window-split
        historical_population_cytoplasm %Cytoplasmic total intensity versus time for all previously completed window-splits
        historical_population_mitochondria %Mitochondrial total intensity versus time for all previously completed window-splits
        historical_population_nucleus %Nuclear total intensity versus time for all previously completed window-splits
        historical_population_total %Whole cell total intensity versus time for all previously completed window-splits
        
        %Segmentation Data table properties
        segmentation_table_input %Input data for table
        segmentation_table_background_colour %Store colourmap for each window in each window-split so row colours in table match plot colours
        
        %Interpolated data
        rough_cell_boundary_interpolated %Rough cell boundary interpolated over all frames from rough_cell_boundary_window
        whole_cell_thresholds_interp %Interpolated threshold levels for whole cell for every frame from whole_cell_thresholds
        mitochondria_thresholds_interp %Interpolated threshold levels for mitochondria for every frame from mitochondria_thresholds
        nucleus_thresholds_interp %Interpolated threshold levels for nucleus for every frame from nucleus_thresholds
        whole_cell_interpolated_region_bw %Interpolated frame by frame whole cell boundary
        nucleus_interpolated_region_bw %Interpolated frame by frame nucleus boundary
        mitochondria_interpolated_region_bw %Interpolated frame by frame mitochondria boundary
        
        
        %Interpolation of percentile intensity projections from windows to
        %all frames. Done by taking a moving-percentile of number of frames
        %equal to window length and the percentile the relevant percentile
        %for that channel for every frame in movie.
        whole_cell_windows_interp % Interpolated percentile intensity projections for every frame for the whole cell.  
        nucleus_windows_interp % Interpolated percentile intensity projections for every frame for the nucleus.
        mitochondria_windows_interp % Interpolated percentile intensity projections for every frame for the mitochondria.
        
        %Error coding
        app_stage % Only allows app functionality to be implemented in the correct order
        input_flag % flags whether files are both of lsm type (=1) or not (=0).
        error_counter %Keeps track of number of simultaneous errors
        cropping_stage %There are two cropping stages.
        % cropping_stage==1 corresponds to zooming into a ROI
        % cropping_stage==2 corresponds to rough cropping around the cell
        %cropping_stage == 3 corresponds to selecting a background region
        zoom_ROI_created_flag %Flag for creation of zoom ROI
        rough_cell_boundary_created %Flags whether the rough cell boundary has been created using the roipoly command
        whole_cell_threshold_slider_flag %Flags that the threshold slider for the whole cell has been adjusted by the user
        mitochondria_threshold_slider_flag %Flags that the threshold slider for the mitochondria has been adjusted by the user
        nucleus_threshold_slider_flag %Flags that the threshold slider for the nucleus has been adjusted by the user
        whole_cell_slider_percentile_flag %Flags that the percentile slider for the whole cell has been adjusted by the user
        nuclear_slider_percentile_flag %Flags that the percentile slider for the nucleus has been adjusted by the user
        mitochondria_slider_percentile_flag %Flags that the percentile slider for the mitochondria has been adjusted by the user
        background_ROI_created_flag %Flags whether the background region has been created
        release_start_flag %flag to say that normalisation slider has been moved
        release_end_flag %flag to say that normalisation slider has been moved
        recovery_start_flag %flag to say that normalisation slider has been moved
        recovery_end_flag %flag to say that normalisation slider has been moved
        
        
        %Normalisation
        background_location %Indices of background pixels
        background_mean % Mean value of background
        intensity_plot_window % The window-split selected for viewing intensities for bleach linear fitting
        release_linear_start % Start of linear change in total intensity in release phase
        release_linear_end % End of linear change in total intensity in release phase
        recovery_linear_start % Start of linear change in total intensity in recovery phase
        recovery_linear_end % End of linear change in total intensity in recovery phase
        intensity_plot_window_ss % The window-split selected for viewing intensities for ss fitting
        steady_state_end %Final frame of steady-state prior to release
        time %Store time data either as frames or loaded from spreadsheet
        normalisation_plot_windows %Output from given number of windows for normalisation
       
        
       %Model Fitting Data table properties
        model_fitting_table_input %Input data for model fitting table
        model_fitting_table_background_colour %Colormap of model fitting success
        model_fitting_flag %Says whether model fitting has been carried out once. If so then it reads guess values from table
        k_off_lit_guess %Guess vector for illuminated release in model fitting. Vector allows different guess for different run
        k_off_unlit_guess %Guess vector for steady state release in model fitting. Vector allows different guess for different run
        k_on_lit_guess %Guess vector for illuminated recovery in model fitting. Vector allows different guess for different run
        k_on_unlit_guess %Guess vector for steady state recovery in model fitting. Vector allows different guess for different run
        %k_rec_guess %Guess vector for recovery in model fitting. Vector allows different guess for different run
        k_imp_guess %Guess vector for import in model fitting. Vector allows different guess for different run
        k_exp_guess %Guess vector for export in model fitting. Vector allows different guess for different run
        model_fitting_plot_windows %Output from given number of windows for model fitting
    end
    
    methods (Access = private)
        
        function stage_error_counter = stage_error(app,current_stage,required_stage,cropping_stage)
        
            stage_error_counter = 0;
            if cropping_stage == 1
                info_label = 'Error Messages';
                lsm_type_error = 'Error. Complete square crop in new image window.';
                stage_error_counter = 1;
                info_label = [info_label newline '(1)' lsm_type_error];
                app.ErrorMessagesLabel.Text = info_label;
                app.ErrorMessagesLabel.BackgroundColor = 'k';
            elseif cropping_stage == 2
                info_label = 'Error Messages';
                lsm_type_error = ['Error. Complete rough crop around cell in ' newline 'new image window.'];
                stage_error_counter = 1;
                info_label = [info_label newline '(1)' lsm_type_error];
                app.ErrorMessagesLabel.Text = info_label;
                app.ErrorMessagesLabel.BackgroundColor = 'k';
            elseif cropping_stage == 3
                info_label = 'Error Messages';
                lsm_type_error = ['Error. Complete background region selection' newline 'in new image window.'];
                stage_error_counter = 1;
                info_label = [info_label newline '(1)' lsm_type_error];
                app.ErrorMessagesLabel.Text = info_label;
                app.ErrorMessagesLabel.BackgroundColor = 'k';    
            else
                if current_stage == 1 && required_stage ~= 1
                    info_label = 'Error Messages';
                    lsm_type_error = 'Error. Complete I/O correctly.';
                    stage_error_counter = 1;
                    info_label = [info_label newline '(1)' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                elseif current_stage == 2 && required_stage ~= 2
                    info_label = 'Error Messages';
                    lsm_type_error = ['Error. Complete channel allocation and select' newline '"Accept Channels and Crop Image" in' newline 'Channel selection tab.'];
                    stage_error_counter = 1;
                    info_label = [info_label newline '(1)' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                elseif current_stage == 3 && required_stage ~= 3
                    info_label = 'Error Messages';
                    lsm_type_error = 'Error. Select "Accept crop" in Channel selection tab.';
                    stage_error_counter = 1;
                    info_label = [info_label newline '(1)' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                elseif current_stage == 4 && required_stage ~= 4
                    info_label = 'Error Messages';
                    lsm_type_error = 'Error. Select "Load Next Iteration" in Compartment viewing percentiles tab.';
                    stage_error_counter = 1;
                    info_label = [info_label newline '(1)' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                elseif current_stage == 5 && required_stage ~= 5
                    info_label = 'Error Messages';
                    lsm_type_error = ['Error. Select best image and carry out rough' newline 'cropping in Cell boundary crop tab.'];
                    stage_error_counter = 1;
                    info_label = [info_label newline '(1)' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                elseif current_stage == 6 && required_stage ~= 6
                    info_label = 'Error Messages';
                    lsm_type_error = ['Error. Choose cell threshold and select "Accept'  newline 'threshold" in Cell thresholding tab.'];
                    stage_error_counter = 1;
                    info_label = [info_label newline '(1)' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                elseif current_stage == 7 && required_stage ~= 7
                    info_label = 'Error Messages';
                    lsm_type_error = ['Error. Choose mitochondria and nuclear' newline 'thresholds and select "Accept'  newline 'thresholds" in Compartment thresholding tab.'];
                    stage_error_counter = 1;
                    info_label = [info_label newline '(1)' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                elseif current_stage == 8 && required_stage ~= 8
                    info_label = 'Error Messages';
                    lsm_type_error = ['Error. Segmentation completed.' newline 'Either save and quit or proceed to' newline 'normalisation in Interpolation tab.'];
                    stage_error_counter = 1;
                    info_label = [info_label newline '(1)' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                elseif current_stage == 9 && required_stage ~= 9
                    info_label = 'Error Messages';
                    lsm_type_error = ['Error. In background subtraction stage.' newline 'Select a background region' newline ' in Background subtraction tab.'];
                    stage_error_counter = 1;
                    info_label = [info_label newline '(1)' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                elseif current_stage == 10 && required_stage ~= 10
                    info_label = 'Error Messages';
                    lsm_type_error = ['Error. Select temporal regions to' newline 'determine passive bleaching rates' newline 'in Normalisation regions tab.'];
                    stage_error_counter = 1;
                    info_label = [info_label newline '(1)' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                elseif current_stage == 11 && required_stage ~= 11
                    info_label = 'Error Messages';
                    lsm_type_error = ['Error. Define steady-state window' newline 'and select time data in' newline 'in Time and SS input tab.'];
                    stage_error_counter = 1;
                    info_label = [info_label newline '(1)' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                elseif current_stage == 12 && required_stage ~= 12
                    info_label = 'Error Messages';
                    lsm_type_error = ['Error. View normalisation output in' newline 'Normalisation plotting tab.'];
                    stage_error_counter = 1;
                    info_label = [info_label newline '(1)' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                elseif current_stage == 13 && required_stage ~= 13
                    info_label = 'Error Messages';
                    lsm_type_error = ['Error. Complete model fitting in' newline 'Model fitting tab.'];
                    stage_error_counter = 1;
                    info_label = [info_label newline '(1)' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';    
                end      
        
            end

        end


        %Plotting functions
        function plot_imagesc(~,UIAxes,input_image)
            title(UIAxes, []);
            xlabel(UIAxes, []);
            ylabel(UIAxes, []);
            UIAxes.XAxis.TickLabels = {};
            UIAxes.YAxis.TickLabels = {};
            % Display image and stretch to fill axes
            I = imagesc(input_image, 'Parent', UIAxes, ...
                'XData', [1 UIAxes.Position(3)], ...
                'YData', [1 UIAxes.Position(4)]);
            % Set limits of axes
            UIAxes.XLim = [0 I.XData(2)];
            UIAxes.YLim = [0 I.YData(2)];
            axis(UIAxes, 'equal');
            
        end
        
        function plot_imshow(app,UIAxes,input_image)
            title(UIAxes, []);
            xlabel(UIAxes, []);
            ylabel(UIAxes, []);
            UIAxes.XAxis.TickLabels = {};
            UIAxes.YAxis.TickLabels = {};
            % Display image and stretch to fill axes
            I = imshow(input_image, 'Parent', UIAxes, ...
                'XData', [1 UIAxes.Position(3)], ...
                'YData', [1 UIAxes.Position(4)]);
            % Set limits of axes
            UIAxes.XLim = [0 I.XData(2)];
            UIAxes.YLim = [0 I.YData(2)];
            axis(UIAxes, 'equal');
            
        end

        function intensity_plotting(app)
            cmap = jet(length(app.seed_points_total));
                if app.app_stage == 7
                    plot_function1 ='h=plot(app.UIAxes11,';
                    plot_function2 ='h=plot(app.UIAxes11_2,';
                    plot_function3 ='h=plot(app.UIAxes11_3,';
                    plot_function4 ='h=plot(app.UIAxes11_4,';
                    colour_function = 'set(h, {''color''}, {';
                    for J =1:app.window_profile_id-1
                        plot_function1 = [plot_function1,'1:app.total_frames,app.historical_population_mitochondria(:,' num2str(J) '),'];
                        plot_function2 = [plot_function2,'1:app.total_frames,app.historical_population_cytoplasm(:,' num2str(J) '),'];
                        plot_function3 = [plot_function3,'1:app.total_frames,app.historical_population_nucleus(:,' num2str(J) '),'];
                        plot_function4 = [plot_function4,'1:app.total_frames,app.historical_population_total(:,' num2str(J) '),'];
                        colour_function=[colour_function,'cmap(',num2str(J),',:);'];
                    end
                    plot_function1 = [plot_function1,'1:app.total_frames,app.population_mitochondria,''LineWidth'',2);'];
                    plot_function2 = [plot_function2,'1:app.total_frames,app.population_cytoplasm,''LineWidth'',2);'];
                    plot_function3 = [plot_function3,'1:app.total_frames,app.population_nucleus,''LineWidth'',2);'];
                    plot_function4 = [plot_function4,'1:app.total_frames,app.population_total,''LineWidth'',2);'];
                    colour_function=[colour_function,'cmap(app.window_profile_id,:)})'];
                    
                    min_mito=nanmin(app.population_mitochondria);
                    max_mito=nanmax(app.population_mitochondria);
                    min_cyto=nanmin(app.population_cytoplasm);
                    max_cyto=nanmax(app.population_cytoplasm);
                    min_nuc=nanmin(app.population_nucleus);
                    max_nuc=nanmax(app.population_nucleus);
                    min_total=nanmin(app.population_total);
                    max_total=nanmax(app.population_total);
                    if  app.window_profile_id > 1
                        min_mito=min(nanmin(app.historical_population_mitochondria(:)),min_mito);
                        max_mito=max(nanmax(app.historical_population_mitochondria(:)),max_mito);
                        min_cyto=min(nanmin(app.historical_population_cytoplasm(:)),min_cyto);
                        max_cyto=max(nanmax(app.historical_population_cytoplasm(:)),max_cyto);
                        min_nuc=min(nanmin(app.historical_population_nucleus(:)),min_nuc);
                        max_nuc=max(nanmax(app.historical_population_nucleus(:)),max_nuc);
                        min_total=min(nanmin(app.historical_population_total(:)),min_total);
                        max_total=max(nanmax(app.historical_population_total(:)),max_total);
                    end
               else
                    plot_function1 ='h=plot(app.UIAxes11_5,';
                    plot_function2 ='h=plot(app.UIAxes11_6,';
                    plot_function3 ='h=plot(app.UIAxes11_7,';
                    plot_function4 ='h=plot(app.UIAxes11_8,';
                    colour_function = 'set(h, {''color''}, {';
                    for J =1:length(app.seed_points_total)
                        plot_function1 = [plot_function1,'1:app.total_frames,app.compartment_data(' num2str(J) ').interp_mitochondria_population,'];
                        plot_function2 = [plot_function2,'1:app.total_frames,app.compartment_data(' num2str(J) ').interp_cytoplasm_population,'];
                        plot_function3 = [plot_function3,'1:app.total_frames,app.compartment_data(' num2str(J) ').interp_nucleus_population,'];
                        plot_function4 = [plot_function4,'1:app.total_frames,app.compartment_data(' num2str(J) ').interp_total_population,'];
                        colour_function=[colour_function,'cmap(',num2str(J),',:);'];
                        if J == 1
                            min_mito = nanmin(app.compartment_data(J).interp_mitochondria_population);
                            max_mito = nanmax(app.compartment_data(J).interp_mitochondria_population);
                            min_cyto = nanmin(app.compartment_data(J).interp_cytoplasm_population);
                            max_cyto = nanmax(app.compartment_data(J).interp_cytoplasm_population);
                            min_nuc = nanmin(app.compartment_data(J).interp_nucleus_population);
                            max_nuc = nanmax(app.compartment_data(J).interp_nucleus_population);
                            min_total = nanmin(app.compartment_data(J).interp_total_population);
                            max_total = nanmax(app.compartment_data(J).interp_total_population);
                        else
                            min_mito=min(min_mito,nanmin(app.compartment_data(J).interp_mitochondria_population));
                            max_mito=max(max_mito,nanmax(app.compartment_data(J).interp_mitochondria_population));
                            min_cyto=min(min_cyto,nanmin(app.compartment_data(J).interp_cytoplasm_population));
                            max_cyto=max(max_cyto,nanmax(app.compartment_data(J).interp_cytoplasm_population));
                            min_nuc=min(min_nuc,nanmin(app.compartment_data(J).interp_nucleus_population));
                            max_nuc=max(max_nuc,nanmax(app.compartment_data(J).interp_nucleus_population));
                            min_total=min(min_total,nanmin(app.compartment_data(J).interp_total_population));
                            max_total=max(max_total,nanmax(app.compartment_data(J).interp_total_population));
                        end
                    end
                    plot_function1 = [plot_function1,'''LineWidth'',2);'];
                    plot_function2 = [plot_function2,'''LineWidth'',2);'];
                    plot_function3 = [plot_function3,'''LineWidth'',2);'];
                    plot_function4 = [plot_function4,'''LineWidth'',2);'];
                    colour_function=[colour_function,'})'];
                end
                eval(plot_function1);
                eval(colour_function);
                eval(plot_function2);
                eval(colour_function);
                eval(plot_function3);
                eval(colour_function);
                eval(plot_function4);
                eval(colour_function);
                
                if app.app_stage == 7
                    if min_mito < max_mito
                        ylim(app.UIAxes11,[min_mito max_mito]);
                    else
                        ylim(app.UIAxes11,[0 2]);
                    end
                    if min_cyto < max_cyto
                        ylim(app.UIAxes11_2,[min_cyto max_cyto]);
                    else
                        ylim(app.UIAxes11_2,[0 2]);
                    end
                    if min_nuc < max_nuc
                        ylim(app.UIAxes11_3,[min_nuc max_nuc]); 
                    else
                        ylim(app.UIAxes11_3,[0 2]);
                    end
                    if min_total < max_total
                        ylim(app.UIAxes11_4,[min_total max_total]); 
                    else
                        ylim(app.UIAxes11_4,[0 2]); 
                    end
                    
                else
                    if min_mito < max_mito
                        ylim(app.UIAxes11_5,[min_mito max_mito]);
                    else
                        ylim(app.UIAxes11_5,[0 2]);
                    end
                    if min_cyto < max_cyto
                        ylim(app.UIAxes11_6,[min_cyto max_cyto]);
                    else
                        ylim(app.UIAxes11_6,[0 2]);
                    end
                    if min_nuc < max_nuc
                        ylim(app.UIAxes11_7,[min_nuc max_nuc]); 
                    else
                        ylim(app.UIAxes11_7,[0 2]);
                    end
                    if min_total < max_total
                        ylim(app.UIAxes11_8,[min_total max_total]); 
                    else
                        ylim(app.UIAxes11_8,[0 2]); 
                    end
                end
                xlim(app.UIAxes11,[0 app.total_frames])
                xlim(app.UIAxes11_2,[0 app.total_frames])
                xlim(app.UIAxes11_3,[0 app.total_frames])
                xlim(app.UIAxes11_4,[0 app.total_frames])
                xlim(app.UIAxes11_5,[0 app.total_frames])
                xlim(app.UIAxes11_6,[0 app.total_frames])
                xlim(app.UIAxes11_7,[0 app.total_frames])
                xlim(app.UIAxes11_8,[0 app.total_frames])
                
                app.UIAxes11.XTickMode = 'auto';
                app.UIAxes11_2.XTickMode = 'auto';
                app.UIAxes11_3.XTickMode = 'auto';
                app.UIAxes11_4.XTickMode = 'auto';
                app.UIAxes11_5.XTickMode = 'auto';
                app.UIAxes11_6.XTickMode = 'auto';
                app.UIAxes11_7.XTickMode = 'auto';
                app.UIAxes11_8.XTickMode = 'auto';
        end
        
        function app = linear_bleach_region_plotting(app)
            delete( findobj(app.UIAxes13, 'type', 'line') )
            delete( findobj(app.UIAxes13_2, 'type', 'line') )
            delete( findobj(app.UIAxes13_3, 'type', 'line') )
            ValueArray = {'-','-',':',':'}';
            ColorArray = {[0,0,0],[0.5,0.5,0.5],[0,1,1],[1,0,1]}';
            app.release_linear_start = app.ReleasestartSlider.Value;
            app.release_linear_end = app.ReleaseendSlider.Value;
            app.recovery_linear_start = app.RecoverystartSlider.Value;
            app.recovery_linear_end = app.RecoveryendSlider.Value;
            linear_release_frames = app.release_linear_start:app.release_linear_end;
            linear_release_total = app.compartment_data(app.intensity_plot_window).interp_total_population(app.release_linear_start:app.release_linear_end);           
            linear_release_mito = app.compartment_data(app.intensity_plot_window).interp_mitochondria_population(app.release_linear_start:app.release_linear_end); 
            linear_release_cytoplasm = app.compartment_data(app.intensity_plot_window).interp_cytoplasm_population(app.release_linear_start:app.release_linear_end);
            linear_recovery_frames = app.recovery_linear_start:app.recovery_linear_end;
            linear_recovery_total = app.compartment_data(app.intensity_plot_window).interp_total_population(app.recovery_linear_start:app.recovery_linear_end);           
            linear_recovery_mito = app.compartment_data(app.intensity_plot_window).interp_mitochondria_population(app.recovery_linear_start:app.recovery_linear_end); 
            linear_recovery_cytoplasm = app.compartment_data(app.intensity_plot_window).interp_cytoplasm_population(app.recovery_linear_start:app.recovery_linear_end);
            h=plot(app.UIAxes13_3,1:app.total_frames,app.compartment_data(app.intensity_plot_window).interp_total_population,1:app.total_release_frames,app.compartment_data(app.intensity_plot_window).interp_total_population(1:app.total_release_frames),linear_release_frames,linear_release_total,linear_recovery_frames,linear_recovery_total,'LineWidth',2);
            set(h,{'color'},ColorArray);
            set(h,{'LineStyle'},ValueArray);           
            h=plot(app.UIAxes13,1:app.total_frames,app.compartment_data(app.intensity_plot_window).interp_mitochondria_population,1:app.total_release_frames,app.compartment_data(app.intensity_plot_window).interp_mitochondria_population(1:app.total_release_frames),linear_release_frames,linear_release_mito,linear_recovery_frames,linear_recovery_mito,'LineWidth',2);
            set(h,{'color'},ColorArray);
            set(h,{'LineStyle'},ValueArray);
            h=plot(app.UIAxes13_2,1:app.total_frames,app.compartment_data(app.intensity_plot_window).interp_cytoplasm_population,1:app.total_release_frames,app.compartment_data(app.intensity_plot_window).interp_cytoplasm_population(1:app.total_release_frames),linear_release_frames,linear_release_cytoplasm,linear_recovery_frames,linear_recovery_cytoplasm,'LineWidth',2);
            set(h,{'color'},ColorArray);
            set(h,{'LineStyle'},ValueArray);
        end
        
        function app = steady_state_end_plotting(app)
            app.intensity_plot_window_ss = round(app.NumberofwindowsSlider_2.Value);
            app.steady_state_end = app.SteadystateendframeSpinner.Value;
            app.NumberofwindowsSlider_2.Value = app.intensity_plot_window_ss;
            delete( findobj(app.UIAxes14, 'type', 'line') )
            delete( findobj(app.UIAxes14_2, 'type', 'line') )
            ValueArray = {'-','-'}';
            MarkerArray = {'none','.'}';
            ColorArray = {[0,0,0],[1,0,1]}';
            h1=plot(app.UIAxes14,1:app.total_frames,app.compartment_data(app.intensity_plot_window_ss).interp_mitochondria_population,1:app.steady_state_end,app.compartment_data(app.intensity_plot_window_ss).interp_mitochondria_population(1:app.steady_state_end),'LineWidth',2,'MarkerSize',15);
            set(h1,{'color'},ColorArray);
            set(h1,{'LineStyle'},ValueArray);
            h2=plot(app.UIAxes14_2,1:app.total_frames,app.compartment_data(app.intensity_plot_window_ss).interp_cytoplasm_population,1:app.steady_state_end,app.compartment_data(app.intensity_plot_window_ss).interp_cytoplasm_population(1:app.steady_state_end),'LineWidth',2,'MarkerSize',15);
            set(h2,{'color'},ColorArray);
            set(h2,{'LineStyle'},ValueArray);
            if app.steady_state_end > 1
                MarkerArray = {'none','none'}';
            end
            set(h1,{'Marker'},MarkerArray);
            set(h2,{'Marker'},MarkerArray);
            
        end

        function app = normalisation_plotting(app)
            delete( findobj(app.UIAxes15, 'type', 'line') )
            delete( findobj(app.UIAxes15_2, 'type', 'line') )
            delete( findobj(app.UIAxes15_3, 'type', 'line') )
            delete( findobj(app.UIAxes15_4, 'type', 'line') )
            if app.PrenormalisationButton.Value == true

                mito = app.compartment_data(app.normalisation_plot_windows).mitochondria_background_subtracted;
                total = app.compartment_data(app.normalisation_plot_windows).total_background_subtracted;

                cyto = app.compartment_data(app.normalisation_plot_windows).cytoplasm_background_subtracted;
                nuc = app.compartment_data(app.normalisation_plot_windows).nucleus_background_subtracted;
                
                if app.SinglelinearButton.Value == true
                    ColorArray = {[0,0,0],[1,0,1]}';
                    h=plot(app.UIAxes15_4,app.time,total,app.time,app.compartment_data(app.normalisation_plot_windows).single_linear_fit_total,'LineWidth',2);

                elseif app.DoublelinearButton.Value == true
                    ColorArray = {[0,0,0],[0,1,1]}';
                    h=plot(app.UIAxes15_4,app.time,total,app.time,app.compartment_data(app.normalisation_plot_windows).double_linear_fit_total,'LineWidth',2);
                end
                set(h,{'color'},ColorArray);
            elseif app.PostnormalisationButton.Value == true
                if app.SinglelinearButton.Value == true

                    mito = app.compartment_data(app.normalisation_plot_windows).mitochondria_norm_single_linear;
                    cyto = app.compartment_data(app.normalisation_plot_windows).cytoplasm_norm_single_linear;
                    nuc = app.compartment_data(app.normalisation_plot_windows).nucleus_norm_single_linear;
                    total = app.compartment_data(app.normalisation_plot_windows).total_norm_single_linear;

                    plot(app.UIAxes15_4,app.time,total,'k','LineWidth',2);
                elseif app.DoublelinearButton.Value == true
                    
                    mito = app.compartment_data(app.normalisation_plot_windows).mitochondria_norm_double_linear;
                    cyto = app.compartment_data(app.normalisation_plot_windows).cytoplasm_norm_double_linear;
                    nuc = app.compartment_data(app.normalisation_plot_windows).nucleus_norm_double_linear;
                    total = app.compartment_data(app.normalisation_plot_windows).total_norm_double_linear;


                    plot(app.UIAxes15_4,app.time,total,'k','LineWidth',2);
                end                
            end
            min_mito = nanmin(mito);
            max_mito = nanmax(mito);
            min_cyto = nanmin(cyto);
            max_cyto = nanmax(cyto);
            min_nuc = nanmin(nuc);
            max_nuc = nanmax(nuc);
            min_total = nanmin(total);
            max_total = nanmax(total);
            ten_percent_buffer=0.1*(max_mito-min_mito);
            ylim(app.UIAxes15,[min_mito-ten_percent_buffer max_mito+ten_percent_buffer]);
            ten_percent_buffer=0.1*(max_cyto-min_cyto);
            ylim(app.UIAxes15_2,[min_cyto-ten_percent_buffer max_cyto+ten_percent_buffer]);
            ten_percent_buffer=0.1*(max_nuc-min_nuc);
            ylim(app.UIAxes15_3,[min_nuc-ten_percent_buffer max_nuc+ten_percent_buffer]);
            ten_percent_buffer=0.1*(max_total-min_total);
            ylim(app.UIAxes15_4,[min_total-ten_percent_buffer max_total+ten_percent_buffer]);
            xlim(app.UIAxes15,[min(app.time) max(app.time)]);
            xlim(app.UIAxes15_2,[min(app.time) max(app.time)]);
            xlim(app.UIAxes15_3,[min(app.time) max(app.time)]);
            xlim(app.UIAxes15_4,[min(app.time) max(app.time)]);
            plot(app.UIAxes15,app.time,mito,'k','LineWidth',2);
            plot(app.UIAxes15_2,app.time,cyto,'k','LineWidth',2);
            plot(app.UIAxes15_3,app.time,nuc,'k','LineWidth',2);            
        end


     function app = model_fitting_plotting(app)
            delete( findobj(app.UIAxes17, 'type', 'line') )
            delete( findobj(app.UIAxes17_2, 'type', 'line') )
            delete( findobj(app.UIAxes17_3, 'type', 'line') )
            app.model_fitting_plot_windows = round(app.NumberofwindowsSlider_5.Value);
            app.NumberofwindowsSlider_5.Value = app.model_fitting_plot_windows;
         
            if app.SinglelinearButton_3.Value == true
                ColorArray = {[0,0,0],[1,0,1]}';
                mito_exp = app.compartment_data(app.model_fitting_plot_windows).mitochondria_norm_single_linear;
                cyto_exp = app.compartment_data(app.model_fitting_plot_windows).cytoplasm_norm_single_linear;
                nuc_exp = app.compartment_data(app.model_fitting_plot_windows).nucleus_norm_single_linear;
                ValueArray = {'-','-'}';
                time_model = app.time;
                mito_model = app.compartment_data(app.model_fitting_plot_windows).single_model_fit_mitochondria_frames;
                cyto_model = app.compartment_data(app.model_fitting_plot_windows).single_model_fit_cytoplasm_frames;
                nuc_model = app.compartment_data(app.model_fitting_plot_windows).single_model_fit_nucleus_frames;                    
            else
                ColorArray = {[0,0,0],[0,1,1]}';
                mito_exp = app.compartment_data(app.model_fitting_plot_windows).mitochondria_norm_double_linear;
                cyto_exp = app.compartment_data(app.model_fitting_plot_windows).cytoplasm_norm_double_linear;
                nuc_exp = app.compartment_data(app.model_fitting_plot_windows).nucleus_norm_double_linear;
                ValueArray = {'-','-'}';
                time_model = app.time;
                mito_model = app.compartment_data(app.model_fitting_plot_windows).double_model_fit_mitochondria_frames;
                cyto_model = app.compartment_data(app.model_fitting_plot_windows).double_model_fit_cytoplasm_frames;
                nuc_model = app.compartment_data(app.model_fitting_plot_windows).double_model_fit_nucleus_frames;                    
               
            end
            
            min_mito = min(nanmin(mito_exp),nanmin(mito_model));
            max_mito = max(nanmax(mito_exp),nanmax(mito_model));
            min_cyto = min(nanmin(cyto_exp),nanmin(cyto_model));
            max_cyto = max(nanmax(cyto_exp),nanmax(cyto_model));
            min_nuc = min(nanmin(nuc_exp),nanmin(nuc_model));
            max_nuc = max(nanmax(nuc_exp),nanmax(nuc_model));
            ten_percent_buffer=0.1*(max_mito-min_mito);
            ylim(app.UIAxes17,[min_mito-ten_percent_buffer max_mito+ten_percent_buffer]);
            ten_percent_buffer=0.1*(max_cyto-min_cyto);
            ylim(app.UIAxes17_2,[min_cyto-ten_percent_buffer max_cyto+ten_percent_buffer]);
            ten_percent_buffer=0.1*(max_nuc-min_nuc);
            ylim(app.UIAxes17_3,[min_nuc-ten_percent_buffer max_nuc+ten_percent_buffer]);
            xlim(app.UIAxes17,[min(app.time) max(app.time)]);
            xlim(app.UIAxes17_2,[min(app.time) max(app.time)]);
            xlim(app.UIAxes17_3,[min(app.time) max(app.time)]);
            h1=plot(app.UIAxes17,app.time,mito_exp,time_model,mito_model,'LineWidth',2);
            h2=plot(app.UIAxes17_2,app.time,cyto_exp,time_model,cyto_model,'LineWidth',2);
            h3=plot(app.UIAxes17_3,app.time,nuc_exp,time_model,nuc_model,'LineWidth',2); 
            set(h1,{'color'},ColorArray);
            set(h1,{'LineStyle'},ValueArray);
            set(h2,{'color'},ColorArray);
            set(h2,{'LineStyle'},ValueArray);
            set(h3,{'color'},ColorArray);
            set(h3,{'LineStyle'},ValueArray);
        end        

        
        function [app,whole_cell_interval_image_bw,whole_cell_interval_image_bw_skel,rgbcell] = cell_threshold(app,whole_cell_interval_image_bw)
            image_input = zeros(size(app.max_int_all_movie_ROI));
            image_input(:,:) = app.whole_cell_window_percentile_projection(:,:,app.seed_point_id);
            
            whole_cell_interval_image_bw=imbinarize(uint16(image_input),app.whole_cell_threshold);
            whole_cell_interval_image_bw = whole_cell_interval_image_bw.*app.rough_cell_boundary_window(:,:,app.seed_point_id);
            whole_cell_interval_image_bw=imfill(whole_cell_interval_image_bw,'holes');
            SE = strel('disk', 1, 0);
            whole_cell_interval_image_bw=imopen(whole_cell_interval_image_bw,SE);
            L = bwlabel(whole_cell_interval_image_bw);
            LNo=length(unique(L(:)))-1;
            if(LNo>0)
                clear SizeI
                SizeI=zeros(1,LNo);
                for J=1:LNo
                    index=find(L==J);
                    SizeI(J)=length(index);
                end
                whole_cell_interval_image_bw=bwareaopen(whole_cell_interval_image_bw,max(SizeI),8);
            end
            app.whole_cell_binary_window(:,:,app.seed_point_id)=whole_cell_interval_image_bw;
            whole_cell_interval_image_bw_skel=bwmorph(whole_cell_interval_image_bw,'remove');
            image_input=double(image_input-min(image_input(:)));
            image_input=image_input./max(image_input(:));
            image_input=uint16(round((2^16-1)*image_input));
            rgbcell = ind2rgb(image_input, parula(2^16));
            index=find(whole_cell_interval_image_bw_skel);
            channel=rgbcell(:,:,1);
            channel(index)=2^16-1;
            rgbcell(:,:,1)=channel;
            channel=rgbcell(:,:,2);
            channel(index)=2^16-1;
            rgbcell(:,:,2)=channel;
            channel=rgbcell(:,:,3);
            channel(index)=2^16-1;
            rgbcell(:,:,3)=channel;
            
        end


        function [app,mito_cell_interval_image_bw,rgbcell] = mitochondrial_threshold(app)
            image_input = zeros(size(app.max_int_all_movie_ROI));
            image_input(:,:) = app.mitochondria_window_percentile_projection(:,:,app.seed_point_id);

            mito_cell_interval_image_bw = imbinarize(uint16(image_input),app.mitochondria_threshold);
            mito_cell_interval_image_bw=app.whole_cell_binary_window(:,:,app.seed_point_id).*mito_cell_interval_image_bw;
            app.mitochondria_binary_window(:,:,app.seed_point_id)=mito_cell_interval_image_bw;
            mito_cell_interval_image_skel=bwmorph(mito_cell_interval_image_bw,'remove');
            
            image_input=double(image_input-min(image_input(:)));
            image_input=image_input./max(image_input(:));
            image_input=uint16(round((2^16-1)*image_input));
            rgbcell = ind2rgb(image_input, parula(2^16));
            index=find(mito_cell_interval_image_skel);
            channel=rgbcell(:,:,1);
            channel(index)=2^16-1;
            rgbcell(:,:,1)=channel;
            channel=rgbcell(:,:,2);
            channel(index)=2^16-1;
            rgbcell(:,:,2)=channel;
            channel=rgbcell(:,:,3);
            channel(index)=2^16-1;
            rgbcell(:,:,3)=channel;
            
        end
        
        
        function [app,nuc_cell_interval_image_bw,rgbcell] = nuclear_threshold(app,mito_cell_interval_image_bw)
            image_input = zeros(size(app.max_int_all_movie_ROI));
            image_input(:,:) = app.nucleus_window_percentile_projection(:,:,app.seed_point_id);
            
            nuc_cell_interval_image_bw = imbinarize(uint16(image_input),app.nucleus_threshold);
            nuc_cell_interval_image_bw=app.whole_cell_binary_window(:,:,app.seed_point_id).*nuc_cell_interval_image_bw;
            nuc_cell_interval_image_bw=imfill(nuc_cell_interval_image_bw,'holes');
            L = bwlabel(nuc_cell_interval_image_bw);
            LNo=length(unique(L(:)))-1;
            if LNo>0
                nuc_object_area = zeros(1,LNo);
                for J=1:LNo
                    index_area=find(L==J);
                    nuc_object_area(J)=length(index_area);
                end
                nuc_cell_interval_image_bw=bwareaopen(nuc_cell_interval_image_bw,max(nuc_object_area),8);
            end
            overlap_bw=nuc_cell_interval_image_bw.*mito_cell_interval_image_bw;
            nuc_cell_interval_image_bw(overlap_bw==1)=0;
            L = bwlabel(nuc_cell_interval_image_bw);
            LNo=length(unique(L(:)))-1;
            if(LNo>0)
                nuc_object_area = zeros(1,LNo);
                for J=1:LNo
                    index=find(L==J);
                    nuc_object_area(J)=length(index);
                end
                nuc_cell_interval_image_bw=bwareaopen(nuc_cell_interval_image_bw,max(nuc_object_area),8);
            end
            app.nucleus_binary_window(:,:,app.seed_point_id)=nuc_cell_interval_image_bw;
            nuc_cell_interval_image_skel=bwmorph(nuc_cell_interval_image_bw,'remove');
            image_input=double(image_input-min(image_input(:)));
            image_input=image_input./max(image_input(:));
            image_input=uint16(round((2^16-1)*image_input));
            rgbcell = ind2rgb(image_input, parula(2^16));
            index=find(nuc_cell_interval_image_skel);
            channel=rgbcell(:,:,1);
            channel(index)=2^16-1;
            rgbcell(:,:,1)=channel;
            channel=rgbcell(:,:,2);
            channel(index)=2^16-1;
            rgbcell(:,:,2)=channel;
            channel=rgbcell(:,:,3);
            channel(index)=2^16-1;
            rgbcell(:,:,3)=channel;    
        end

        %Intensity extraction
        
        function app = population_intensity_extraction(app)
            if app.seed_point_id == app.seed_points_total(app.window_profile_id)
                current_frames = app.window_start_points(app.seed_point_id):app.total_frames;    
            else
                current_frames = app.window_start_points(app.seed_point_id):app.window_start_points(app.seed_point_id+1)-1;    
            end
        
            cell_object = app.whole_cell_binary_window(:,:,app.seed_point_id);
            nucleus_object = app.nucleus_binary_window(:,:,app.seed_point_id);
            mito_object = app.mitochondria_binary_window(:,:,app.seed_point_id);
            cytoplasm_object = max(cell_object - nucleus_object - mito_object, 0);
             
            for J = 1:length(current_frames)
                single_protein_frame(:,:) = app.LSM_protein_movie_ROI(:,:,current_frames(J));
                
                cytoplasm_population(current_frames(J)) = sum(single_protein_frame(cytoplasm_object > 0));
                mitochondria_population(current_frames(J)) = sum(single_protein_frame(mito_object > 0));
                nucleus_population(current_frames(J)) = sum(single_protein_frame(nucleus_object > 0));
            end
            app.population_cytoplasm(current_frames) = cytoplasm_population(current_frames);
            app.population_mitochondria(current_frames) = mitochondria_population(current_frames);
            app.population_nucleus(current_frames) = nucleus_population(current_frames);
            app.population_total(current_frames) = nucleus_population(current_frames) + mitochondria_population(current_frames) + cytoplasm_population(current_frames);
        end
        
        
        %Interpolation functions
        
        function app = interp_rough_region(app)
            if app.seed_points_total(app.window_profile_id)==1
                whole_region = app.rough_cell_boundary_window(:,:,1);
                app.rough_cell_boundary_interpolated = repmat(whole_region,[1,1,app.total_frames]);
            else
                first_region(:,:) = app.rough_cell_boundary_window(:,:,1);
                last_region(:,:) = app.rough_cell_boundary_window(:,:,end);
                start = repmat(first_region,[1,1,app.window_mid_points(1)]);
                finish = repmat(last_region,[1,1,app.total_frames-app.window_mid_points(end)+1]);
                interp_matrix = zeros([size(first_region),app.total_frames]);
                for I=1:length(app.window_mid_points)
                    interp_matrix(:,:,app.window_mid_points(I))=app.rough_cell_boundary_window(:,:,I);
                end
                for I=1:length(app.window_mid_points)-1
                   bw_r1(:,:)=app.rough_cell_boundary_window(:,:,I);
                   bw_r2(:,:)=app.rough_cell_boundary_window(:,:,I+1);
                   diff1=max(0,bw_r2-bw_r1);
                   diff2=max(0,bw_r1-bw_r2);
                   bw_dist_r1=bwdist(bw_r1);
                   bw_dist_r2=bwdist(bw_r2);
                   index1=find(diff1);
                   index2=find(diff2);
                   distance_r1=bw_dist_r1(index1);
                   distance_r1 = distance_r1/max(distance_r1);
                   distance_r1 = app.window_mid_points(I) + distance_r1*(app.window_mid_points(I+1)-app.window_mid_points(I));
                   distance_r1(distance_r1<app.window_mid_points(I)+1)=app.window_mid_points(I)+1;
                   distance_r1(distance_r1>app.window_mid_points(I+1)-1)=app.window_mid_points(I+1)-1;
                   distance_r1 = round(distance_r1);
                   bw_dist_r1 = zeros(size(bw_dist_r1));
                   bw_dist_r1(index1)=distance_r1;
                   distance_r2=bw_dist_r2(index2);
                   distance_r2 = 1-distance_r2/max(distance_r2);
                   distance_r2 = app.window_mid_points(I) + distance_r2*(app.window_mid_points(I+1)-app.window_mid_points(I));
                   distance_r2(distance_r2<app.window_mid_points(I)+1)=app.window_mid_points(I)+1;
                   distance_r2(distance_r2>app.window_mid_points(I+1)-1)=app.window_mid_points(I+1)-1;
                   distance_r2 = round(distance_r2);
                   bw_dist_r2 = zeros(size(bw_dist_r2));
                   bw_dist_r2(index2)=distance_r2;
                   
                   for J=app.window_mid_points(I)+1:app.window_mid_points(I+1)-1
                       index1 = find(bw_dist_r1 == J);
                       index2 = find(bw_dist_r2 == J);
                       input_matrix(:,:) = interp_matrix(:,:,J-1);
                       input_matrix(index1)=1;
                       input_matrix(index2)=0;
                       interp_matrix(:,:,J) = input_matrix;
                   end  
                end
                interp_matrix(:,:,1:app.window_mid_points(1))=start;
                interp_matrix(:,:,app.window_mid_points(end):end)=finish; 
                app.rough_cell_boundary_interpolated = interp_matrix;
            end
            
            
        end


        function app = interp_thresholds(app)
            if app.seed_points_total(app.window_profile_id)==1
                interp_data(1:app.total_frames) = app.whole_cell_thresholds;
                app.whole_cell_thresholds_interp = interp_data;
                
                interp_data(1:app.total_frames) = app.mitochondria_thresholds;
                app.mitochondria_thresholds_interp = interp_data;
                
                interp_data(1:app.total_frames) = app.nucleus_thresholds;
                app.nucleus_thresholds_interp = interp_data;
            else
                interp_data(1:app.window_mid_points(1))=app.whole_cell_thresholds(1);
                interp_data(app.window_mid_points(1):app.window_mid_points(end))=interp1(app.window_mid_points,app.whole_cell_thresholds,app.window_mid_points(1):app.window_mid_points(end));
                interp_data(app.window_mid_points(end):app.total_frames)=app.whole_cell_thresholds(end);
                app.whole_cell_thresholds_interp = interp_data;
                
                interp_data(1:app.window_mid_points(1))=app.mitochondria_thresholds(1);
                interp_data(app.window_mid_points(1):app.window_mid_points(end))=interp1(app.window_mid_points,app.mitochondria_thresholds,app.window_mid_points(1):app.window_mid_points(end));
                interp_data(app.window_mid_points(end):app.total_frames)=app.mitochondria_thresholds(end);
                app.mitochondria_thresholds_interp = interp_data;
                
                interp_data(1:app.window_mid_points(1))=app.nucleus_thresholds(1);
                interp_data(app.window_mid_points(1):app.window_mid_points(end))=interp1(app.window_mid_points,app.nucleus_thresholds,app.window_mid_points(1):app.window_mid_points(end));
                interp_data(app.window_mid_points(end):app.total_frames)=app.nucleus_thresholds(end);
                app.nucleus_thresholds_interp = interp_data;
            end

     
        end
        
        function app = interp_intensities(app)
            moving_average_distance = round(mean(diff(app.window_start_points)));
            moving_average_distance = moving_average_distance -1;
            moving_average_distance_max = ceil(moving_average_distance/2);
            moving_average_distance_min = floor(moving_average_distance/2);
            for J=1:app.total_frames
                if J - moving_average_distance_min <= 1
                    average_cell_window = app.max_int_all_frames_ROI(:,:,1:J+moving_average_distance_max);
                    average_mito_window = app.LSM_mitochondria_movie_ROI(:,:,1:J+moving_average_distance_max);
                    average_nuc_window = app.LSM_nuclear_movie_ROI(:,:,1:J+moving_average_distance_max);
                elseif J + moving_average_distance_min >= app.total_frames
                    average_cell_window = app.max_int_all_frames_ROI(:,:,J-moving_average_distance_max:end);
                    average_mito_window = app.LSM_mitochondria_movie_ROI(:,:,J-moving_average_distance_max:end);
                    average_nuc_window = app.LSM_nuclear_movie_ROI(:,:,J-moving_average_distance_max:end);
                else
                    average_cell_window = app.max_int_all_frames_ROI(:,:,J-moving_average_distance_max:J+moving_average_distance_min);
                    average_mito_window = app.LSM_mitochondria_movie_ROI(:,:,J-moving_average_distance_max:J+moving_average_distance_min);
                    average_nuc_window = app.LSM_nuclear_movie_ROI(:,:,J-moving_average_distance_max:J+moving_average_distance_min);
                end
                app.whole_cell_windows_interp(:,:,J) = uint16(prctile(average_cell_window,app.whole_cell_percentile,3));
                app.nucleus_windows_interp(:,:,J) = uint16(prctile(average_nuc_window,app.nuclear_percentile,3));
                app.mitochondria_windows_interp(:,:,J) = uint16(prctile(average_mito_window,app.mitochondria_percentile,3)); 
            end
      
        end
        
       %Warnings and reset
       
       
        function app = error_message_reset(app)
                app.ErrorMessagesLabel.BackgroundColor = 'none';
                app.error_counter = 0;
                app.ErrorMessagesLabel.Text = 'Error Messages';
        end
        
        
        function app = warning_compartment_area(app,compartment,segmentation_stage,message_type)
            if message_type == 1
                info_label = 'Warning!';
                app.ErrorMessagesLabel.BackgroundColor = 'y';
                if compartment == 0
                        lsm_type_error = ['The crop selected gives a ROI area of' newline  'below 250 pixels. Only proceed if this is correct.' newline 'Otherwise re-select "Accept channels and Crop' newline 'Image" to determine ROI.'];
                elseif compartment == 1
                    if segmentation_stage == 1
                        lsm_type_error = ['The crop selected gives a cell size of' newline  'below 100 pixels. Only proceed if this is correct.' newline 'Otherwise re-select Rough Crop to outline cell.'];
                    else
                        lsm_type_error = ['The threshold selected gives a cell size of' newline  'below 100 pixels. Only proceed if this is correct.' newline 'Otherwise adjust the threshold levels.'];
                    end
                elseif compartment == 2
                    lsm_type_error = ['The threshold selected gives a mitochondria size of' newline  'below 25 pixels. Only proceed if this is correct.' newline 'Otherwise adjust the threshold levels.'];
                elseif compartment == 3 
                    lsm_type_error = ['The threshold selected gives a nucleus size of' newline  'below 25 pixels. Only proceed if this is correct.' newline 'Otherwise adjust the threshold levels.'];
                elseif compartment == 4
                    lsm_type_error1 = ['The threshold selected gives a mitochondria size of' newline  'below 25 pixels. Only proceed if this is correct.' newline 'Otherwise adjust the threshold levels.'];
                    lsm_type_error2 = ['The threshold selected gives a nucleus size of' newline  'below 25 pixels. Only proceed if this is correct.' newline 'Otherwise adjust the threshold levels.'];
                elseif compartment == 5 
                    lsm_type_error = ['The crop selected gives a background size of' newline  'below 25 pixels. Only proceed if this is correct.' newline 'Otherwise re-select Select background region' newline ' to outline background.'];
                end
                
            elseif message_type ==2
                info_label = 'Error Messages';
                app.ErrorMessagesLabel.BackgroundColor = 'k';
                    if compartment == 0
                        lsm_type_error = ['Error. The crop selected gives a ROI size of zero!' newline  'Re-select "Accept channels and Crop Image"' newline 'to determine ROI.'];
                    elseif compartment == 1
                        lsm_type_error = ['Error. The threshold selected gives a cell size of zero!' newline 'Adjust the threshold levels.'];
                    elseif compartment == 5 
                        lsm_type_error = ['Error. The crop selected gives a background size' newline 'of zero! Re-select "Select Background Region"' newline 'to determine background ROI.'];
                    end
            end
            if compartment ==4
                app.error_counter = app.error_counter+ 1;
                info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error1];
                app.error_counter = app.error_counter+ 1;
                info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error2];
                app.ErrorMessagesLabel.Text = info_label;                
            else
                app.error_counter = app.error_counter+ 1;
                info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                app.ErrorMessagesLabel.Text = info_label;
            end


        end
  
          function [k_off_unlit_fitted,k_on_unlit_fitted,k_off_lit_fitted,k_on_lit_fitted,k_imp_fitted,k_exp_fitted,mitochondria_ss_fitted,cytoplasm_ss_fitted,nucleus_ss_fitted] = fitted_values(app,beta)
            k_off_unlit_fitted=abs(beta(1));
            k_on_unlit_fitted=abs(beta(2));
            k_off_lit_fitted=abs(beta(3));
            k_on_lit_fitted=abs(beta(4));
            k_imp_fitted=abs(beta(5));
            k_exp_fitted=abs(beta(6));
            mitochondria_ss_fitted=abs(beta(7));
            cytoplasm_ss_fitted=k_off_unlit_fitted*mitochondria_ss_fitted/k_on_unlit_fitted;
            nucleus_ss_fitted=k_imp_fitted*cytoplasm_ss_fitted/k_exp_fitted;
        end
        
        function reset_function(app)
            app.SeedpointsselectedLabel.Text = 'Seedpoints selected:';
            
            app.FrameSlider.Value = 1; 
            app.FrameSlider.Limits = [1 100];
            
            app.PercentileSlider_2.Value = 0;
            app.PercentileSlider_3.Value = 0;
            app.PercentileSlider.Value = 0;
            
            app.SeedpointSlider_2.Value = 1;
            app.SeedpointSlider_3.Value = 1;
            app.SeedpointSlider.Value = 1;
            app.SeedpointSlider.Limits = [1 10];
            app.SeedpointSlider_2.Limits = [1 10];
            app.SeedpointSlider_3.Limits = [1 10];
            
            app.FramenumberSlider.Limits = [1 100];
            
            app.ReleasestartSlider.Limits = [1 100];
            app.ReleaseendSlider.Limits = [1 100];
            app.RecoverystartSlider.Limits = [101 200];
            app.RecoveryendSlider.Limits = [101 200];
            
            
            app.NumberofwindowsSlider_2.Value=1;
            app.NumberofwindowsSlider_3.Value=1;
            app.NumberofwindowsSlider_5.Value=1;
            app.NumberofwindowsSlider.Limits = [1 10];
            app.NumberofwindowsSlider_2.Limits = [1 10];
            app.NumberofwindowsSlider_3.Limits = [1 10];
            app.NumberofwindowsSlider_5.Limits = [1 10];
            app.NumberofwindowsSlider_2.MajorTicks = 1:10;
            app.NumberofwindowsSlider_3.MajorTicks = 1:10;
            app.NumberofwindowsSlider_5.MajorTicks = 1:10;
            clear slider_tick_label
            for J = 1:10
                slider_tick_label{J} = num2str(J);
            end
            app.NumberofwindowsSlider_2.MajorTickLabels = slider_tick_label;
            app.NumberofwindowsSlider_3.MajorTickLabels = slider_tick_label;
            app.NumberofwindowsSlider_5.MajorTickLabels = slider_tick_label;
            
            app.Thresholdscale01Slider.Value = 0;
            app.Thresholdscale001Slider.Value = 0;
            app.Thresholdscale0001Slider.Value = 0;
            app.Thresholdscale00001Slider.Value = 0; 
            app.Mitochondriathresholdscale01Slider.Value = 0;
            app.Mitochondriathresholdscale001Slider.Value = 0;
            app.Mitochondriathresholdscale0001Slider.Value = 0;
            app.Mitochondriathresholdscale00001Slider.Value = 0;
            app.Nucleusthresholdscale01Slider.Value = 0;
            app.Nucleusthresholdscale001Slider.Value = 0;
            app.Nucleusthresholdscale0001Slider.Value = 0;
            app.Nucleusthresholdscale00001Slider.Value = 0;
            
            threshold_label = ['Threshold value:' newline num2str(app.whole_cell_threshold,'%.5f')];
            app.Thresholdvalue000000Label.Text = threshold_label;
            threshold_label = ['Mitochondrial threshold value: '  num2str(app.mitochondria_threshold,'%.5f') newline 'Nucleus threshold value: ' num2str(app.nucleus_threshold,'%.5f')];
            app.Mitochondrialthresholdvalue000000Label.Text = threshold_label;
            
            app.TextArea.Value={''};
            
            app.NullButton_2.Value = true;
            app.NoneButton_5.Value = true;
            app.NoneButton_6.Value = true;
            app.NoneButton_7.Value = true;
            app.NoneButton_8.Value = true;
            app.UseframesastimeButton.Value = true;
            app.SinglelinearButton.Value = true;
            app.PrenormalisationButton.Value = true;

            
            app.NumberofwindowsSlider.Value = 1;
            app.NumberofwindowsSlider_2.Value = 1;
            app.NumberofwindowsSlider_3.Value = 1;
            app.SteadystateendframeSpinner.Value = 1;
            app.NumberofwindowsSlider_5.Value = 1;
            
            
            cla(app.UIAxes)
            cla(app.UIAxes2)
            cla(app.UIAxes3)
            cla(app.UIAxes4)
            cla(app.UIAxes7_3)
            cla(app.UIAxes7)
            cla(app.UIAxes7_2)
            cla(app.UIAxes6)
            cla(app.UIAxes6_2)
            cla(app.UIAxes6_3)
            cla(app.UIAxes6_4)
            cla(app.UIAxes5)
            cla(app.UIAxes8)
            cla(app.UIAxes8_2)
            cla(app.UIAxes8_3)
            cla(app.UIAxes10)
            cla(app.UIAxes10_2)
            cla(app.UIAxes10_3)
            cla(app.UIAxes10_4)
            cla(app.UIAxes10_5)
            cla(app.UIAxes10_6)
            cla(app.UIAxes11)
            cla(app.UIAxes11_2)
            cla(app.UIAxes11_3)
            cla(app.UIAxes11_4)
            cla(app.UIAxes11_5)
            cla(app.UIAxes11_6)
            cla(app.UIAxes11_7)
            cla(app.UIAxes11_8)
            cla(app.UIAxes12)
            cla(app.UIAxes13)
            cla(app.UIAxes13_2)
            cla(app.UIAxes13_3)
            cla(app.UIAxes14)
            cla(app.UIAxes14_2)
            cla(app.UIAxes15)                        
            cla(app.UIAxes15_2)                      
            cla(app.UIAxes15_3)                      
            cla(app.UIAxes15_4)
            cla(app.UIAxes17)                        
            cla(app.UIAxes17_2)                      
            cla(app.UIAxes17_3) 

            
            app.UITable.Data = [];
            app.UITable2.Data = [];            
        end
        
        function save_function(app,pth,fnm)
                    compartment_data = app.compartment_data;
                    total_release_frames = app.total_release_frames;
                    total_recovery_frames = app.total_recovery_frames;
                    total_frames = app.total_frames;
                    seed_points_total = app.seed_points_total;
                    nuclear_channel = app.nuclear_channel;
                    mitochondrial_channel = app.mitochondrial_channel;
                    protein_channel = app.protein_channel;
                    vertical_min_ROI = app.vertical_min_ROI;
                    vertical_max_ROI = app.vertical_max_ROI;
                    horizontal_min_ROI = app.horizontal_min_ROI;
                    horizontal_max_ROI = app.horizontal_max_ROI;
                    LSM_nucleus_release_ROI = app.LSM_nucleus_release_ROI;
                    LSM_protein_release_ROI = app.LSM_protein_release_ROI;
                    LSM_mitochondria_release_ROI = app.LSM_mitochondria_release_ROI;
                    LSM_nuclear_recovery_ROI = app.LSM_nuclear_recovery_ROI;
                    LSM_protein_recovery_ROI = app.LSM_protein_recovery_ROI;
                    LSM_mitochondria_recovery_ROI = app.LSM_mitochondria_recovery_ROI;
                    LSM_nuclear_movie_ROI = app.LSM_nuclear_movie_ROI;
                    LSM_protein_movie_ROI = app.LSM_protein_movie_ROI;
                    LSM_mitochondria_movie_ROI  = app.LSM_mitochondria_movie_ROI;
                    
                    background_mean = app.background_mean;        
                    release_linear_start = app.release_linear_start;
                    release_linear_end = app.release_linear_end;
                    recovery_linear_start = app.recovery_linear_start;
                    recovery_linear_end = app.recovery_linear_end; 
                    steady_state_end = app.steady_state_end;
                    time = app.time;
                    
                    if app.IncludebackgroundsubtractionCheckBox.Value == true
                        background_subtraction = true;
                    else
                        background_subtraction = false;
                    end

                    save(fullfile(pth,fnm),'compartment_data','total_release_frames','total_recovery_frames',...
                        'total_frames','seed_points_total','nuclear_channel','mitochondrial_channel','protein_channel',...
                        'vertical_min_ROI','vertical_max_ROI','horizontal_min_ROI','horizontal_max_ROI',...
                        'LSM_nucleus_release_ROI','LSM_protein_release_ROI','LSM_mitochondria_release_ROI',...
                        'LSM_nuclear_recovery_ROI','LSM_protein_recovery_ROI','LSM_mitochondria_recovery_ROI',...
                        'LSM_nuclear_movie_ROI','LSM_protein_movie_ROI','LSM_mitochondria_movie_ROI',...
                        'background_mean','release_linear_start','release_linear_end','recovery_linear_start',...
                        'recovery_linear_end','steady_state_end','time','background_subtraction')            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
              app.compartment_data = struct('window_start_points',{},'window_mid_points',{}, 'whole_cell_percentile',{},'nuclear_percentile',{},'mitochondria_percentile',{},...
                            'whole_cell_interval_image',{},'nucleus_window_percentile_projection',{},'mitochondria_window_percentile_projection',{},'rough_cell_boundary_window',{},...
                            'whole_cell_thresholds',{},'mitochondria_thresholds',{},'nucleus_thresholds',{},'whole_cell_binary_window',{},'mitochondria_binary_window',{},...
                            'nucleus_binary_window',{},'rough_cell_boundary_interpolated',{},'interp_whole_cell_threshold',{},'interp_mito_threshold',{},...
                            'interp_nucleus_threshold',{},'whole_cell_windows_interp',{},'nucleus_windows_interp',{},...
                            'mitochondria_windows_interp',{},'whole_cell_interpolated_region_bw',{},'mitochondria_interpolated_region_bw',{},'nucleus_interpolated_region_bw',{},'nucleus_population',{},...
                            'mitochondria_population',{},'cytoplasm_population',{},'total_population',{},...
                            'interp_cytoplasm_population',{},'interp_nucleus_population',{},'interp_mitochondria_population',{},'interp_total_population',{},'mitochondria_background_subtracted',{},...
                            'cytoplasm_background_subtracted',{},'nucleus_background_subtracted',{},'total_background_subtracted',{},'single_linear_fit_total',{},...
                            'double_linear_fit_total',{},'mitochondria_norm_single_linear',{},'cytoplasm_norm_single_linear',{},'nucleus_norm_single_linear',{},...
                            'total_norm_single_linear',{},'mitochondria_norm_double_linear',{},'cytoplasm_norm_double_linear',{},'nucleus_norm_double_linear',{},'total_norm_double_linear',{},...
                            'single_k_off_unlit',{},'single_k_on_unlit',{},'single_k_off_lit',{},'single_k_on_lit',{},'single_k_imp',{},'single_k_exp',{},...
                            'single_mitochondria_ss',{},'single_cytoplasm_ss',{},'single_nucleus_ss',{},'single_whole_cell_MSE',{},...
                            'single_model_fit_mitochondria_frames',{},'single_model_fit_cytoplasm_frames',{},'single_model_fit_nucleus_frames',{},...
                            'single_mitochondria_MSE',{},'single_cytoplasm_MSE',{},'single_nucleus_MSE',{},'single_whole_cell_MAE',{},'double_k_off_unlit',{},...
                            'double_k_on_unlit',{},'double_k_off_lit',{},'double_k_on_lit',{},'double_k_imp',{},'double_k_exp',{},'double_mitochondria_ss',{},...
                            'double_cytoplasm_ss',{},'double_nucleus_ss',{},'double_whole_cell_MSE',{},...
                            'double_model_fit_mitochondria_frames',{},'double_model_fit_cytoplasm_frames',{},'double_model_fit_nucleus_frames',{},...
                            'double_mitochondria_MSE',{},'double_cytoplasm_MSE',{},'double_nucleus_MSE',{},'double_whole_cell_MAE',{}); 
                                            
                    
            app.release_file = [];
            app.release_file_path = [];
            app.recovery_file = [];
            app.recovery_file_path = [];
            app.LSM_release = [];
            app.LSM_recovery = []; 
            app.total_channels = []; 
            app.LSM_vert_size = []; 
            app.LSM_horz_size = []; 
            app.total_release_frames = []; 
            app.total_recovery_frames = []; 
            app.total_frames = []; 
            app.seed_points_total = [];
            app.nuclear_channel = [];
            app.mitochondrial_channel = [];
            app.protein_channel = [];
            app.LSM_protein_release = [];
            app.LSM_protein_recovery = [];
            app.LSM_protein_all_frames = [];
            app.LSM_protein_frame1 = [];
            app.vertical_min_ROI = [];
            app.vertical_max_ROI = [];
            app.horizontal_min_ROI = [];
            app.horizontal_max_ROI = [];
            app.LSM_nucleus_release_ROI = [];
            app.LSM_protein_release_ROI = [];
            app.LSM_mitochondria_release_ROI = [];
            app.LSM_nuclear_recovery_ROI = [];
            app.LSM_protein_recovery_ROI = [];
            app.LSM_mitochondria_recovery_ROI = [];
            app.LSM_nuclear_movie_ROI = [];
            app.LSM_protein_movie_ROI = [];
            app.LSM_mitochondria_movie_ROI = [];
            app.max_int_release_frames_ROI = [];
            app.max_int_recovery_frames_ROI = [];
            app.max_int_release_all_ROI = [];
            app.max_int_recovery_all_ROI = [];
            app.max_int_all_movie_ROI = [];
            app.max_int_protein_all_movie_ROI = [];
            app.max_int_all_frames_ROI = [];
            app.window_start_points = [];
            app.window_mid_points = [];
            app.window_max_nucleus_ROI = [];
            app.window_max_mitochondria_ROI = [];
            app.window_max_protein_ROI = [];
            app.window_max_all_channels_ROI = [];
            app.whole_cell_window_percentile_projection = [];
            app.nucleus_window_percentile_projection = [];
            app.mitochondria_window_percentile_projection = [];
            app.rough_cell_boundary_window = [];
            app.whole_cell_binary_window = [];
            app.mitochondria_binary_window = [];
            app.nucleus_binary_window = [];
            app.population_cytoplasm = [];
            app.population_mitochondria = [];
            app.population_nucleus = [];
            app.population_total = [];
            app.historical_population_cytoplasm = [];
            app.historical_population_mitochondria = [];
            app.historical_population_nucleus = [];
            app.historical_population_total = [];
            app.segmentation_table_input = [];
            app.segmentation_table_background_colour = [];
            app.whole_cell_thresholds = [];
            app.mitochondria_thresholds = [];
            app.nucleus_thresholds = [];
            app.rough_cell_boundary_interpolated = [];
            app.whole_cell_thresholds_interp = [];
            app.mitochondria_thresholds_interp = [];
            app.nucleus_thresholds_interp = [];
            app.whole_cell_windows_interp = []; 
            app.nucleus_windows_interp =[];
            app.mitochondria_windows_interp =[];
            app.whole_cell_interpolated_region_bw = [];
            app.nucleus_interpolated_region_bw = [];
            app.mitochondria_interpolated_region_bw = [];
            app.background_location = [];
            app.background_mean = [];
            app.model_fitting_table_input = [];
            app.model_fitting_table_background_colour = [];
            app.k_off_lit_guess=[]; 
            app.k_off_unlit_guess=[];
            app.k_on_lit_guess=[]; 
            app.k_on_unlit_guess=[];
            app.k_imp_guess=[]; 
            app.k_exp_guess=[]; 
                            
            app.whole_cell_threshold=0;
            app.mitochondria_threshold=0;
            app.nucleus_threshold=0;
            app.whole_cell_threshold_0_1 = 0; 
            app.whole_cell_threshold_0_01 = 0; 
            app.whole_cell_threshold_0_001 = 0; 
            app.whole_cell_threshold_0_0001 = 0; 
            app.mitochondria_threshold_0_1 = 0; 
            app.mitochondria_threshold_0_01 = 0; 
            app.mitochondria_threshold_0_001 = 0; 
            app.mitochondria_threshold_0_0001 = 0;
            app.nucleus_threshold_0_1 = 0;
            app.nucleus_threshold_0_01 = 0;
            app.nucleus_threshold_0_001 =0;
            app.nucleus_threshold_0_0001=0;

            
            app.app_stage=1;
            app.input_flag = 1;
            app.error_counter = 0;
            app.cropping_stage = 0;
            app.zoom_ROI_created_flag = 0; 
            app.rough_cell_boundary_created = 0; 
            app.whole_cell_threshold_slider_flag = 0; 
            app.mitochondria_threshold_slider_flag = 0; 
            app.nucleus_threshold_slider_flag = 0; 
            app.whole_cell_slider_percentile_flag = 0; 
            app.nuclear_slider_percentile_flag = 0;
            app.mitochondria_slider_percentile_flag = 0; 
            app.background_ROI_created_flag = 0;
            app.release_start_flag = 0;
            app.release_end_flag = 0; 
            app.recovery_start_flag = 0; 
            app.recovery_end_flag = 0; 
            
            app.window_profile_id = 1;
            app.seed_point_id = 1;
            app.whole_cell_percentile=0;
            app.nuclear_percentile=0;
            app.mitochondria_percentile=0;
            
            app.FramenumberSlider.Value = 1; 
            
            app.ReleasestartSlider.Value = 1;
            app.ReleaseendSlider.Value = 100;
            app.RecoverystartSlider.Value = 101;
            app.RecoveryendSlider.Value = 200;
            
            app.intensity_plot_window = 1;
            app.release_linear_start = 1;
            app.release_linear_end = 100;
            app.recovery_linear_start = 101;
            app.recovery_linear_end = 200;
            app.intensity_plot_window_ss = 1;
            app.steady_state_end = 1;
            
            app.normalisation_plot_windows = 1;
            
            
            app.model_fitting_flag =0;
            app.model_fitting_plot_windows = 1;
            
            app.LitoffrateEditField.Value=0.1;
            app.UnlitoffrateEditField.Value=0.02;
            app.LitonrateEditField.Value=0.03;
            app.UnlitonrateEditField.Value=0.02;
            app.ImportrateEditField.Value=0.005;
            app.ExportrateEditField.Value=0.005;

        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)

            
            app = error_message_reset(app);

            reset_function(app);
            startupFcn(app);
            
           app.TabGroup.SelectedTab = app.ChannelselectionTab;            
        end

        % Button pushed function: LoadReleaseDataButton
        function LoadReleaseDataButtonPushed(app, event)
            [app.release_file,app.release_file_path] = uigetfile('*.lsm');
        end

        % Button pushed function: LoadRecoveryDataButton
        function LoadRecoveryDataButtonPushed(app, event)
            [app.recovery_file,app.recovery_file_path] = uigetfile('*.lsm');
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
             stage_error_counter = stage_error(app,app.app_stage,1,app.cropping_stage);
            if stage_error_counter == 0
                app.input_flag = 1;
                app = error_message_reset(app);

                if isempty(app.release_file_path) || isempty(app.release_file) || isempty(app.recovery_file_path) || isempty(app.recovery_file)% || (length(release_file) < 3) || (length(recovery_file) < 3)
                    lsm_type_error = ['Error. Release and recovery' newline 'files must be selected.'];
                    app.input_flag = 0;
                    app.error_counter = app.error_counter +1;
                    app.ErrorMessagesLabel.Text = [app.ErrorMessagesLabel.Text newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                    return;
                end
                release_file=[app.release_file_path app.release_file];
                recovery_file=[app.recovery_file_path app.recovery_file];
                if release_file(2)==0 || recovery_file(2)==0
                   lsm_type_error = ['Error. Select image files.'];
                   app.input_flag = 0;
                   app.error_counter = app.error_counter +1;
                   app.ErrorMessagesLabel.Text = [app.ErrorMessagesLabel.Text newline '(' num2str(app.error_counter) ')' lsm_type_error];
                   app.ErrorMessagesLabel.BackgroundColor = 'k';  
                   return;
                end
                
                if(strcmp(release_file(end-2:end),'lsm')==0)||(strcmp(recovery_file(end-2:end),'lsm')==0)
                   lsm_type_error = ['Error. Image files must' newline 'be of lsm type.'];
                   app.input_flag = 0;
                   app.error_counter = app.error_counter +1;
                   app.ErrorMessagesLabel.Text = [app.ErrorMessagesLabel.Text newline '(' num2str(app.error_counter) ')' lsm_type_error];
                   app.ErrorMessagesLabel.BackgroundColor = 'k';
                end
                if (isfile(release_file) == 0) || (isfile(recovery_file) == 0)
                    lsm_type_error = ['Error. At least one image' newline 'file has not been assigned.'];
                    app.input_flag = 0;
                    app.error_counter = app.error_counter +1;
                    app.ErrorMessagesLabel.Text = [app.ErrorMessagesLabel.Text newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                end

                interval_string = app.TextArea.Value;
                interval_string = interval_string{1}; 
                if (isempty(interval_string)) 
                    lsm_type_error = 'Error. No. of intervals cannot be empty.';
                    app.input_flag = 0;
                    app.error_counter = app.error_counter +1;
                    app.ErrorMessagesLabel.Text = [app.ErrorMessagesLabel.Text newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    %app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                    return
                 end
                app.SeedpointsselectedLabel.Text = 'Seedpoints selected:';
                app.seed_points_total = [];
                print_intervals=1;
                value_split=strsplit(interval_string,',');
                for I=1:length(value_split)
                    value_num=str2double(value_split{I});
                    if(isempty(value_num))
                        lsm_type_error = ['Error. Intervals must be integers' newline 'greater than zero in' newline 'comma separated format.'];
                        app.input_flag = 0;
                        app.error_counter = app.error_counter +1;
                        info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                        app.ErrorMessagesLabel.Text = info_label;
                        app.ErrorMessagesLabel.BackgroundColor = 'k';
                        print_intervals=0;
                        break
                    elseif (mod(value_num,1) ~= 0) ||  (value_num < 0)
                        lsm_type_error = ['Error. Intervals must be integers' newline 'greater than zero in' newline 'comma separated format.'];
                        app.input_flag = 0;
                        app.error_counter = app.error_counter +1;
                        app.ErrorMessagesLabel.Text = [app.ErrorMessagesLabel.Text newline '(' num2str(app.error_counter) ')' lsm_type_error];
                        %app.ErrorMessagesLabel.Text = info_label;
                        app.ErrorMessagesLabel.BackgroundColor = 'k';
                        print_intervals=0;
                        break
                    else
                        app.seed_points_total = [app.seed_points_total,value_num];    
                    end
                end
                if(isempty(app.seed_points_total) == 0) && (print_intervals == 1)
                    app.seed_points_total=unique(app.seed_points_total);
                    integer_string = sprintf('%.0f,' , app.seed_points_total);
                    integer_string = integer_string(1:end-1);% strip final comma
                    app.SeedpointsselectedLabel.Text=[app.SeedpointsselectedLabel.Text newline integer_string];
                end
                if exist('lsmread','file') ~=2
                    lsmread_location=which('lsmread');
                    if length(lsmread_location)>0
                        addpath(lsmread_location(1:end-9))
                        lsm_type_error = ['Information. lsmread.m was found in' newline lsmread_location(1:end-9) newline 'The path to this folder has been added.']
                        app.error_counter = app.error_counter +1;
                        app.ErrorMessagesLabel.Text = [app.ErrorMessagesLabel.Text newline '(' num2str(app.error_counter) ')' lsm_type_error];
                        app.ErrorMessagesLabel.BackgroundColor = 'k';
                    else
                        lsm_type_error = ['Error. m-file ''lsmread.m''' newline 'cannot be found.'];
                        app.input_flag = 0;
                        app.error_counter = app.error_counter +1;
                        app.ErrorMessagesLabel.Text = [app.ErrorMessagesLabel.Text newline '(' num2str(app.error_counter) ')' lsm_type_error];
                        app.ErrorMessagesLabel.BackgroundColor = 'k';
                    end
                end
                if (app.input_flag ==1)
                    %Add code to find lsmread
                    f = uifigure;
                    d = uiprogressdlg(f,'Title','Please wait. Loading data.','Indeterminate','on');
                    [app.LSM_release, ~] = lsmread(release_file);
                    [app.total_release_frames,app.total_channels,~,app.LSM_vert_size,app.LSM_horz_size]=size(app.LSM_release);
                    [app.LSM_recovery, ~] = lsmread(recovery_file);
                    [app.total_recovery_frames,~,~,u_rec,v_rec]=size(app.LSM_recovery);
                    app.total_frames = app.total_release_frames + app.total_recovery_frames;
                    %Upsize images from 16 bit to 8 bit
                    if max(app.LSM_release(:))<=255|| max(app.LSM_recovery(:))<=255
                        app.LSM_release=uint16(app.LSM_release).*2^8;
                        app.LSM_recovery=uint16(app.LSM_recovery).*2^8;
                    end
                    
                    app.population_cytoplasm=zeros(app.total_frames,1)+NaN;
                    app.population_mitochondria=zeros(app.total_frames,1)+NaN;
                    app.population_nucleus=zeros(app.total_frames,1)+NaN;
                    app.population_total=zeros(app.total_frames,1)+NaN;
                    if app.total_channels~=3 && app.total_channels~=4
                        lsm_type_error = 'Error. No. of total_channels must be 3 or 4.';
                        app.input_flag = 0;
                        app.error_counter = app.error_counter +1;
                        info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                        app.ErrorMessagesLabel.Text = info_label;
                        app.ErrorMessagesLabel.BackgroundColor = 'k';
                    elseif u_rec ~= app.LSM_vert_size || v_rec ~= app.LSM_horz_size
                        lsm_type_error = ['Error. Spatial dimensions of' newline 'release and recovery phases' newline 'should be the same.'];
                        app.input_flag = 0;
                        app.error_counter = app.error_counter +1;
                        info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                        app.ErrorMessagesLabel.Text = info_label;
                        app.ErrorMessagesLabel.BackgroundColor = 'k';
                    else
                        ch1(:, :) = app.LSM_release(1, 1, 1, :, :);
                        ch2(:, :) = app.LSM_release(1, 2, 1, :, :);
                        ch3(:, :) = app.LSM_release(1, 3, 1, :, :);
                        plot_imagesc(app,app.UIAxes,ch1)
                        plot_imagesc(app,app.UIAxes2,ch2)
                        plot_imagesc(app,app.UIAxes3,ch3)
                        if app.total_channels == 4
                            ch4(:, :) = app.LSM_release(1, 4, 1, :, :);
                            plot_imagesc(app,app.UIAxes4,ch4)
    
                        end
                        
                        if length(app.seed_points_total) == 1 && app.seed_points_total(1) == 1
                            app.NumberofwindowsSlider.Limits = [0.5 1.5];
                            app.NumberofwindowsSlider_2.Limits = [0.5 1.5];
                            app.NumberofwindowsSlider_3.Limits = [0.5 1.5];
 
                            app.NumberofwindowsSlider_5.Limits = [0.5 1.5];
                        else
                            app.NumberofwindowsSlider.Limits = [1 length(app.seed_points_total)];
                            app.NumberofwindowsSlider_2.Limits = [1 length(app.seed_points_total)];
                            app.NumberofwindowsSlider_3.Limits = [1 length(app.seed_points_total)];
                            app.NumberofwindowsSlider_5.Limits = [1 length(app.seed_points_total)];
                        end
         
                        
                        for J = 1:length(app.seed_points_total)
                            slider_tick_label{J} = num2str(app.seed_points_total(J));
                        end
                        app.NumberofwindowsSlider.MajorTicks = 1:length(app.seed_points_total);
                        app.NumberofwindowsSlider.MajorTickLabels = slider_tick_label;
                        app.NumberofwindowsSlider.MinorTicks = [];
                        app.NumberofwindowsSlider_2.MajorTicks = 1:length(app.seed_points_total);
                        app.NumberofwindowsSlider_2.MajorTickLabels = slider_tick_label;
                        app.NumberofwindowsSlider_2.MinorTicks = [];
                        app.NumberofwindowsSlider_3.MajorTicks = 1:length(app.seed_points_total);
                        app.NumberofwindowsSlider_3.MajorTickLabels = slider_tick_label;
                        app.NumberofwindowsSlider_3.MinorTicks = [];
                        app.NumberofwindowsSlider_5.MajorTicks = 1:length(app.seed_points_total);
                        app.NumberofwindowsSlider_5.MajorTickLabels = slider_tick_label;
                        app.NumberofwindowsSlider_5.MinorTicks = [];
                        
                        app.ReleasestartSlider.Limits = [1 app.total_release_frames];
                        app.ReleaseendSlider.Limits = [1 app.total_release_frames];
                        app.RecoverystartSlider.Limits = [app.total_release_frames+1 app.total_release_frames+app.total_recovery_frames];
                        app.RecoveryendSlider.Limits = [app.total_release_frames+1 app.total_release_frames+app.total_recovery_frames];
                        app.ReleasestartSlider.Value = 1;
                        app.ReleaseendSlider.Value = app.total_release_frames;
                        app.RecoverystartSlider.Value = app.total_release_frames + 1;
                        app.RecoveryendSlider.Value = app.total_release_frames+app.total_recovery_frames;
                        app.release_linear_start =1;
                        app.release_linear_end = app.total_release_frames;
                        app.recovery_linear_start = app.total_release_frames + 1;
                        app.recovery_linear_end = app.total_release_frames+app.total_recovery_frames;
                        app.FrameSlider.Limits = [1 app.total_release_frames];
                        app.app_stage=2;
                        cmap = jet(length(app.seed_points_total));
                        app.segmentation_table_background_colour = [];
                        for J=1:length(app.seed_points_total)
                            for K=1:app.seed_points_total(J)
                                app.segmentation_table_background_colour = [app.segmentation_table_background_colour;cmap(J,:)];
                            end
                        end
                        app = error_message_reset(app);
                        
                    end
                    close(d)
                    close(f)
                end
            end           
        end

        % Value changing function: FrameSlider
        function FrameSliderValueChanging(app, event)
            changingValue = uint8(min(max(round(event.Value),1),app.total_release_frames));
            stage_error_counter = stage_error(app,app.app_stage,2,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                ch1(:, :) = app.LSM_release(changingValue, 1, 1, :, :);
                ch2(:, :) = app.LSM_release(changingValue, 2, 1, :, :);
                ch3(:, :) = app.LSM_release(changingValue, 3, 1, :, :);
                plot_imagesc(app,app.UIAxes,ch1)
                plot_imagesc(app,app.UIAxes2,ch2)
                plot_imagesc(app,app.UIAxes3,ch3)
                if app.total_channels == 4
                    ch4(:, :) = app.LSM_release(changingValue, 4, 1, :, :);
                    plot_imagesc(app,app.UIAxes4,ch4)
                end
            end            
        end

        % Button pushed function: AcceptChannelsandCropImageButton
        function AcceptChannelsandCropImageButtonPushed(app, event)
            stage_error_counter = stage_error(app,app.app_stage,2,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                app.input_flag = 1;
                 app.ErrorMessagesLabel.Text = 'Error Messages';
                 info_label = app.ErrorMessagesLabel.Text;
                if (app.NoneButton_8.Value == 0) && (app.total_channels == 3) 
                   lsm_type_error = ['Error. There are only three channels.' newline 'Channel 4 should be "None".'];
                   app.input_flag = 0;
                   app.error_counter = app.error_counter +1;
                   info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                   app.ErrorMessagesLabel.Text = info_label;
                   app.ErrorMessagesLabel.BackgroundColor = 'k';
                end
                total_nucleus_radio = app.NucleusButton.Value + app.NucleusButton_2.Value + app.NucleusButton_3.Value + app.NucleusButton_4.Value;
                total_mitochondria_radio = app.MitochondriaButton.Value + app.MitochondriaButton_2.Value + app.MitochondriaButton_3.Value + app.MitochondriaButton_4.Value;
                total_cytoplasm_radio = app.ProteinButton.Value + app.ProteinButton_2.Value + app.ProteinButton_3.Value + app.ProteinButton_4.Value;
                if total_cytoplasm_radio == 2
                   lsm_type_error = ['Error. The app can analyse only one protein' newline 'at a time at present'];
                   app.input_flag = 0;
                   app.error_counter = app.error_counter +1;
                   info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                   app.ErrorMessagesLabel.Text = info_label;
                   app.ErrorMessagesLabel.BackgroundColor = 'k';
                end
                if total_nucleus_radio ~= 1 || total_mitochondria_radio ~= 1 || (total_cytoplasm_radio ~= 1 && total_cytoplasm_radio ~= 2)
                   lsm_type_error = ['Error. There must be one allocated' newline 'protein channel, nuclear channel and' newline 'mitochondrial channel.'];
                   app.input_flag = 0;
                   app.error_counter = app.error_counter +1;
                   app.ErrorMessagesLabel.Text = [app.ErrorMessagesLabel.Text newline '(' num2str(app.error_counter) ')' lsm_type_error];
                   app.ErrorMessagesLabel.BackgroundColor = 'k';
                end
                
                if app.input_flag == 1
                    app = error_message_reset(app);
                    nucleus_location = [app.NucleusButton.Value, app.NucleusButton_2.Value, app.NucleusButton_3.Value, app.NucleusButton_4.Value];
                    protein_location = [app.ProteinButton.Value, app.ProteinButton_2.Value, app.ProteinButton_3.Value, app.ProteinButton_4.Value];
                    mitochondria_location = [app.MitochondriaButton.Value, app.MitochondriaButton_2.Value, app.MitochondriaButton_3.Value, app.MitochondriaButton_4.Value];
                    app.nuclear_channel = find(nucleus_location);
                    app.mitochondrial_channel = find(mitochondria_location);
                    app.protein_channel = find(protein_location);
                    LSM_protein_release=zeros(app.LSM_vert_size,app.LSM_horz_size,app.total_release_frames);
                    for I = 1:app.total_release_frames
                        LSM_protein_release(:, :, I)=app.LSM_release(I, app.protein_channel, 1, :, :);
                    end
                    app.LSM_protein_recovery=zeros(app.LSM_vert_size,app.LSM_horz_size,app.total_recovery_frames);
                    for I = 1:app.total_recovery_frames
                        app.LSM_protein_recovery(:, :, I)=app.LSM_recovery(I, app.protein_channel, 1, :, :);
                    end
                    app.LSM_protein_all_frames = cat(3, LSM_protein_release, app.LSM_protein_recovery);
                    app.LSM_protein_frame1(1:app.LSM_vert_size, 1:app.LSM_horz_size) = app.LSM_release(1,app.protein_channel,1,:,:);
                    
                    image_input = app.LSM_protein_frame1;
                    image_input=double(image_input-min(image_input(:)));
                    image_input=image_input./max(image_input(:));
                    image_input=uint16(round((2^16-1)*image_input));
                    rgbcell = ind2rgb(image_input, parula(2^16));
                                        
                    crop_image = figure;
                    set(crop_image,'name','Zoom location','numbertitle','off')
                    imshow(rgbcell);
                    iptsetpref('ImshowAxesVisible','off')
                    axis('equal');
                    app.cropping_stage = 1;
                    app.zoom_ROI_created_flag = 1;
                    while isvalid(crop_image)
                        h = drawrectangle;
                        while isvalid(h)
                           BW = createMask(h);
                           delete(h);    
                        end
                        
                        delete(crop_image);
                    end
                    %Draw a rectangle around image to get new image bounds
                              

                    if exist('BW','var')
                        if sum(BW(:)) < 250
                            app = warning_compartment_area(app,0,1,1);
                        end
                        index = find(BW);
                        [y, x] = ind2sub(size(BW),index);
                        max_x=max(x);
                        min_x=min(x);
                        max_y=max(y);
                        min_y=min(y);
                        y_length = max_y - min_y +1;
                        x_length = max_x - min_x +1;
                        [height,width]= size(BW);
                        if max(y_length,x_length) < min(height, width)
                            xy_diff = abs(y_length - x_length);
                            side1=floor(xy_diff/2)+xy_diff-2*floor(xy_diff/2);
                            side2=floor(xy_diff/2);
                            if x_length < y_length
                               upper_x = min(max_x+side1,width);
                               lower_x = max(min_x-side2,1);
                               if max_x+side1 > width
                                   lower_x = lower_x  - (upper_x - width);
                               end
                               if min_x-side2 < 1
                                   upper_x = upper_x + (1-min_x+side2);
                               end
                               lower_y=min_y;
                               upper_y=max_y;
                            elseif y_length < x_length
                                upper_y = min(max_y+side1,height);
                                lower_y = max(min_y-side2,1);
                                if max_y+side1 > height
                                    lower_y = lower_y  - (upper_y - height);
                                end
                                if min_y-side2 < 1
                                    upper_y = upper_y + (1-min_y+side2);
                                end
                                lower_x=min_x;
                                upper_x=max_x;
                             else
                                lower_y=min_y;
                                upper_y=max_y;
                                lower_x=min_x;
                                upper_x=max_y;
                            end
                            max_x = upper_x;
                            min_x = lower_x;
                            max_y = upper_y;
                            min_y = lower_y;
                        end
    
                        app.vertical_min_ROI = double(min_y);
                        app.vertical_max_ROI = double(max_y);
                        app.horizontal_min_ROI = double(min_x);
                        app.horizontal_max_ROI = double(max_x);
                        
                       
                        app.cropping_stage = 0;
                        app.zoom_ROI_created_flag = 1;
                                                   

                   else
                        app.cropping_stage = 0;
                        app.zoom_ROI_created_flag = 0;
                    end

                end

            end
            
        end

        % Button pushed function: AcceptCropButton
        function AcceptCropButtonPushed(app, event)
            stage_error_counter = stage_error(app,app.app_stage,2,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                if app.zoom_ROI_created_flag ==0
                    info_label = 'Error Messages';
                    lsm_type_error = [' Error. Select "Accept channels and crop image"' newline 'in Channel selection tab.'];
                    stage_error_counter = 1;
                    info_label = [info_label newline '(1)' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                else
                    app = error_message_reset(app);
             
                    if isempty(app.vertical_min_ROI) || isempty(app.vertical_max_ROI) || isempty(app.horizontal_min_ROI) || isempty(app.horizontal_max_ROI) 
                            app = warning_compartment_area(app,0,1,2);
                    else
                        for I=1:app.total_release_frames
                            app.LSM_nucleus_release_ROI(:,:,I) = app.LSM_release(I,app.nuclear_channel,1,app.vertical_min_ROI:app.vertical_max_ROI,app.horizontal_min_ROI:app.horizontal_max_ROI);
                            app.LSM_protein_release_ROI(:,:,I) = app.LSM_release(I,app.protein_channel,1,app.vertical_min_ROI:app.vertical_max_ROI,app.horizontal_min_ROI:app.horizontal_max_ROI);
                            app.LSM_mitochondria_release_ROI(:,:,I) = app.LSM_release(I,app.mitochondrial_channel,1,app.vertical_min_ROI:app.vertical_max_ROI,app.horizontal_min_ROI:app.horizontal_max_ROI);
                        end
                        for I=1:app.total_recovery_frames
                            app.LSM_nuclear_recovery_ROI(:,:,I) = app.LSM_recovery(I,app.nuclear_channel,1,app.vertical_min_ROI:app.vertical_max_ROI,app.horizontal_min_ROI:app.horizontal_max_ROI);
                            app.LSM_protein_recovery_ROI(:,:,I) = app.LSM_recovery(I,app.protein_channel,1,app.vertical_min_ROI:app.vertical_max_ROI,app.horizontal_min_ROI:app.horizontal_max_ROI);
                            app.LSM_mitochondria_recovery_ROI(:,:,I) = app.LSM_recovery(I,app.mitochondrial_channel,1,app.vertical_min_ROI:app.vertical_max_ROI,app.horizontal_min_ROI:app.horizontal_max_ROI);
                        end
                        %Maximum of all release compartments (3d for time)
                        app.max_int_release_frames_ROI = max(max(app.LSM_nucleus_release_ROI,app.LSM_protein_release_ROI),app.LSM_mitochondria_release_ROI);
                        %Maximum of all recovery compartments (3d for time)
                        app.max_int_recovery_frames_ROI = max(max(app.LSM_nuclear_recovery_ROI,app.LSM_protein_recovery_ROI),app.LSM_mitochondria_recovery_ROI);
                        %2D maximum  of all release stages
                        app.max_int_release_all_ROI = max(app.max_int_release_frames_ROI,[],3);
                        %2D maximum of all recovery stages
                        app.max_int_recovery_all_ROI = max(app.max_int_recovery_frames_ROI,[],3);
                        %2D maximum of entire release and recovery movies
                        app.max_int_all_movie_ROI = max(app.max_int_release_all_ROI,app.max_int_recovery_all_ROI);
                        %Maximum of all compartment for every release and recovery
                        %frame
                        app.max_int_all_frames_ROI=cat(3,app.max_int_release_frames_ROI,app.max_int_recovery_frames_ROI);
                        app.max_int_protein_all_movie_ROI = max(max(app.LSM_protein_release_ROI,[],3),max(app.LSM_protein_recovery_ROI,[],3));
                        app.LSM_nuclear_movie_ROI=cat(3,app.LSM_nucleus_release_ROI,app.LSM_nuclear_recovery_ROI);
                        app.LSM_protein_movie_ROI=cat(3,app.LSM_protein_release_ROI,app.LSM_protein_recovery_ROI);
                        app.LSM_mitochondria_movie_ROI=cat(3,app.LSM_mitochondria_release_ROI,app.LSM_mitochondria_recovery_ROI);
                        
                        app.app_stage =3;
                        app.TabGroup.SelectedTab = app.CompartmentviewingpercentilesTab;
                        
                    end
                  
                end

             end            
        end

        % Button pushed function: LoadNextWindowProfileButton
        function LoadNextWindowProfileButtonPushed(app, event)
            stage_error_counter = stage_error(app,app.app_stage,3,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                if app.seed_point_id == 1
                    if app.seed_points_total(app.window_profile_id) == 1
                        app.SeedpointSlider.Limits = [0.5 1.5];
                        app.SeedpointSlider_2.Limits = [0.5 1.5];
                        app.SeedpointSlider_3.Limits = [0.5 1.5];
                    else
                        app.SeedpointSlider.Limits = [1 app.seed_points_total(app.window_profile_id)];
                        app.SeedpointSlider_2.Limits = [1 app.seed_points_total(app.window_profile_id)];
                        app.SeedpointSlider_3.Limits = [1 app.seed_points_total(app.window_profile_id)];
                    end
                    app.whole_cell_slider_percentile_flag = 0;
                    app.nuclear_slider_percentile_flag = 0;
                    app.mitochondria_slider_percentile_flag = 0;
                    app.window_start_points = 1:(app.total_frames-1)/app.seed_points_total(app.window_profile_id):app.total_frames;%Start point of each region. 
                    app.window_start_points = round(app.window_start_points);
                    app.window_mid_points=1+(app.total_frames-1)/app.seed_points_total(app.window_profile_id)/2:(app.total_frames-1)/app.seed_points_total(app.window_profile_id):app.total_frames;
                    app.window_mid_points=round(app.window_mid_points);
                    app.window_max_all_channels_ROI=zeros([size(app.max_int_all_movie_ROI),app.seed_points_total(app.window_profile_id)]);
                    app.window_max_nucleus_ROI=zeros([size(app.max_int_all_movie_ROI),app.seed_points_total(app.window_profile_id)]);
                    app.window_max_mitochondria_ROI=zeros([size(app.max_int_all_movie_ROI),app.seed_points_total(app.window_profile_id)]);
                    app.window_max_protein_ROI=zeros([size(app.max_int_all_movie_ROI),app.seed_points_total(app.window_profile_id)]);
                     
                    for J=1:app.seed_points_total(app.window_profile_id)
                        if J == app.seed_points_total(app.window_profile_id)
                            end_pt = app.window_start_points(J+1);
                        else
                            end_pt = app.window_start_points(J+1)-1;
                        end

                        %Maximum intensity of nucleus in that region
                        app.window_max_nucleus_ROI(:,:,J)=max(app.LSM_nuclear_movie_ROI(:,:,app.window_start_points(J):end_pt),[],3);
                        %Maximum intesnity of cytoplasm in that region
                        app.window_max_protein_ROI(:,:,J)=max(app.LSM_protein_movie_ROI(:,:,app.window_start_points(J):end_pt),[],3);
                        %Maximum intensity of mitochondria in that region
                        app.window_max_mitochondria_ROI(:,:,J)=max(app.LSM_mitochondria_movie_ROI(:,:,app.window_start_points(J):end_pt),[],3);
                        %Maximum intensity of entire cell over that time region
                        app.window_max_all_channels_ROI(:,:,J)=max(app.window_max_mitochondria_ROI(:,:,J),max(app.window_max_nucleus_ROI(:,:,J),app.window_max_protein_ROI(:,:,J)));
                    end
                    app.whole_cell_window_index=min(1,length(app.window_start_points)-1);
                    app.nuclear_window_index=min(1,length(app.window_start_points)-1);
                    app.mitochondria_window_index=min(1,length(app.window_start_points)-1);
                    whole_cell_image_percentile=uint16(prctile(app.max_int_all_frames_ROI(:,:,app.window_start_points(app.whole_cell_window_index):app.window_start_points(app.whole_cell_window_index+1)-1),app.whole_cell_percentile,3));
                    nuc_image_percentile=uint16(prctile(app.LSM_nuclear_movie_ROI(:,:,app.window_start_points(app.nuclear_window_index):app.window_start_points(app.nuclear_window_index+1)-1),app.nuclear_percentile,3));
                    mito_image_percentile=uint16(prctile(app.LSM_mitochondria_movie_ROI(:,:,app.window_start_points(app.mitochondria_window_index):app.window_start_points(app.mitochondria_window_index+1)-1),app.mitochondria_percentile,3));
                   
                    plot_imagesc(app,app.UIAxes7_3,whole_cell_image_percentile)
                    plot_imagesc(app,app.UIAxes7,nuc_image_percentile)
                    plot_imagesc(app,app.UIAxes7_2,mito_image_percentile)
                    app.app_stage = 4;
                    
                else
                    info_label = 'Error Messages';
                    lsm_type_error = 'Error. Complete stages in the previous interval selection first.';
                    app.error_counter = 1;
                    info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                end

            end               
            
        end

        % Value changed function: SeedpointSlider
        function SeedpointSliderValueChanged(app, event)
            value = round(app.SeedpointSlider.Value);
            stage_error_counter = stage_error(app,app.app_stage,4,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                if app.seed_point_id == 1
                    if app.seed_points_total(app.window_profile_id) == 1
                        app.SeedpointSlider.Value = 1;
                        app.whole_cell_window_index = 1;
                    else
                        app.SeedpointSlider.Value = value;
                        app.whole_cell_window_index=max(1,min(value,length(app.window_start_points)-1));
                    end
                        
                    
                    whole_cell_image_percentile=uint16(prctile(app.max_int_all_frames_ROI(:,:,app.window_start_points(app.whole_cell_window_index):app.window_start_points(app.whole_cell_window_index+1)-1),app.whole_cell_percentile,3));
                    plot_imagesc(app,app.UIAxes7_3,whole_cell_image_percentile)
                else
                    info_label = 'Error Messages';
                    lsm_type_error = 'Error. Complete stages in the previous interval selection first.';
                    app.error_counter = 1;
                    info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                    app.SeedpointSlider.Value = 1;
                    app.whole_cell_window_index = 1;
                end
            else
                app.SeedpointSlider.Value = 1;
                app.whole_cell_window_index = 1;

            end             
        end

        % Value changed function: SeedpointSlider_2
        function SeedpointSlider_2ValueChanged(app, event)
            value = round(app.SeedpointSlider_2.Value);
            stage_error_counter = stage_error(app,app.app_stage,4,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                if app.seed_point_id == 1
                    if app.seed_points_total(app.window_profile_id) == 1
                        app.SeedpointSlider_2.Value = 1;
                        app.nuclear_window_index = 1;
                    else
                        app.SeedpointSlider_2.Value = value;
                        app.nuclear_window_index = max(1,min(value,length(app.window_start_points)-1));
                    end
                    nuc_image_percentile=uint16(prctile(app.LSM_nuclear_movie_ROI(:,:,app.window_start_points(app.nuclear_window_index):app.window_start_points(app.nuclear_window_index+1)-1),app.nuclear_percentile,3));
                    plot_imagesc(app,app.UIAxes7,nuc_image_percentile)
                else
                    info_label = 'Error Messages';
                    lsm_type_error = 'Error. Complete stages in the previous interval selection first.';
                    app.error_counter = 1;
                    info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                    app.SeedpointSlider_2.Value = 1;
                    app.nuclear_window_index = 1;
                end
            else
                app.SeedpointSlider_2.Value = 1;
                app.nuclear_window_index = 1;
                
            end
        end

        % Value changed function: SeedpointSlider_3
        function SeedpointSlider_3ValueChanged(app, event)
            value = round(app.SeedpointSlider_3.Value);
            stage_error_counter = stage_error(app,app.app_stage,4,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                if app.seed_point_id == 1
                    if app.seed_points_total(app.window_profile_id) == 1
                        app.SeedpointSlider_3.Value = 1;
                        app.mitochondria_window_index = 1;
                    else
                        app.SeedpointSlider_3.Value = value;
                        app.mitochondria_window_index = max(1,min(value,length(app.window_start_points)-1));
                    end

                    mito_image_percentile=uint16(prctile(app.LSM_mitochondria_movie_ROI(:,:,app.window_start_points(app.mitochondria_window_index):app.window_start_points(app.mitochondria_window_index+1)-1),app.mitochondria_percentile,3));
                    plot_imagesc(app,app.UIAxes7_2,mito_image_percentile)
                else
                    info_label = 'Error Messages';
                    lsm_type_error = 'Error. Complete stages in the previous interval selection first.';
                    app.error_counter = 1;
                    info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                    app.SeedpointSlider_3.Value = 1;
                    app.mitochondria_window_index = 1;
                end
            else
                app.SeedpointSlider_3.Value = 1;
                app.mitochondria_window_index = 1;
               
            end               
        end

        % Value changing function: PercentileSlider
        function PercentileSliderValueChanging(app, event)
            changingValue = event.Value;
            stage_error_counter = stage_error(app,app.app_stage,4,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                if app.seed_point_id == 1
                    app.whole_cell_slider_percentile_flag = 1;
                    app.whole_cell_percentile=changingValue;
                    whole_cell_image_percentile=uint16(prctile(app.max_int_all_frames_ROI(:,:,app.window_start_points(app.whole_cell_window_index):app.window_start_points(app.whole_cell_window_index+1)-1),app.whole_cell_percentile,3));
                    plot_imagesc(app,app.UIAxes7_3,whole_cell_image_percentile)
                else
                    info_label = 'Error Messages';
                    lsm_type_error = 'Error. Complete stages in the previous interval selection first.';
                    app.error_counter = 1;
                    info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                end
            end       
        end

        % Value changing function: PercentileSlider_2
        function PercentileSlider_2ValueChanging(app, event)
           changingValue = event.Value;
            stage_error_counter = stage_error(app,app.app_stage,4,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                if app.seed_point_id == 1
                    app.nuclear_slider_percentile_flag = 1;
                    app.nuclear_percentile=changingValue;
                    nuc_image_percentile=uint16(prctile(app.LSM_nuclear_movie_ROI(:,:,app.window_start_points(app.nuclear_window_index):app.window_start_points(app.nuclear_window_index+1)-1),app.nuclear_percentile,3));
                    plot_imagesc(app,app.UIAxes7,nuc_image_percentile)
                else
                    info_label = 'Error Messages';
                    lsm_type_error = 'Error. Complete stages in the previous interval selection first.';
                    app.error_counter = 1;
                    info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                end
            end                       
        end

        % Value changing function: PercentileSlider_3
        function PercentileSlider_3ValueChanging(app, event)
            changingValue = event.Value;
            stage_error_counter = stage_error(app,app.app_stage,4,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                app.ErrorMessagesLabel.BackgroundColor = 'none';
                if app.seed_point_id == 1
                    app.mitochondria_slider_percentile_flag = 1;
                    app.mitochondria_percentile = changingValue;
                    mito_image_percentile = uint16(prctile(app.LSM_mitochondria_movie_ROI(:,:,app.window_start_points(app.mitochondria_window_index):app.window_start_points(app.mitochondria_window_index+1)-1),app.mitochondria_percentile,3));
                    plot_imagesc(app,app.UIAxes7_2,mito_image_percentile)
               else
                    info_label = 'Error Messages';
                    lsm_type_error = 'Error. Complete stages in the previous interval selection first.';
                    app.error_counter = 1;
                    info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                end
             
            end     
        end

        % Button pushed function: AcceptPercentilesButton
        function AcceptPercentilesButtonPushed(app, event)
            proceed_to_rough_cropping_flag = 1;
            app.error_counter = 0;
            info_label = 'Error Messages';
            stage_error_counter = stage_error(app,app.app_stage,4,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                if app.seed_point_id ~= 1
                    lsm_type_error = 'Error. Complete stages in the previous interval selection first.';
                    app.error_counter = app.error_counter+ 1;
                    info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                end
                if app.whole_cell_slider_percentile_flag ~= 1 & app.whole_cell_percentile == 0
                    lsm_type_error = 'Error. Select nuclear imaging percentile using the slider.';
                    app.error_counter = app.error_counter+ 1;
                    proceed_to_rough_cropping_flag = 0;
                    info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                end                 
                if app.nuclear_slider_percentile_flag ~= 1 & app.nuclear_percentile == 0
                    lsm_type_error = 'Error. Select nuclear imaging percentile using the slider.';
                    app.error_counter = app.error_counter+ 1;
                    proceed_to_rough_cropping_flag = 0;
                    info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                end 
                if app.mitochondria_slider_percentile_flag ~= 1 & app.mitochondria_percentile == 0
                    lsm_type_error = 'Error. Select mitochondrial imaging percentile using the slider.';
                    app.error_counter = app.error_counter+ 1;
                    proceed_to_rough_cropping_flag = 0;
                    info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                end
                if app.seed_point_id == 1 && proceed_to_rough_cropping_flag == 1
                    app = error_message_reset(app);
                    if app.seed_points_total(app.window_profile_id) ==1
                        image_input1 = zeros(size(app.max_int_all_movie_ROI));
                        image_input1(:,:) = app.max_int_all_movie_ROI;
                        image_input2 = zeros(size(app.max_int_all_movie_ROI));
                        image_input2(:,:) = app.max_int_protein_all_movie_ROI;
                    else
                        image_input1 = zeros(size(app.max_int_all_movie_ROI));
                        image_input1(:,:) = app.window_max_all_channels_ROI(:,:,app.seed_point_id);
                        image_input2 = zeros(size(app.max_int_all_movie_ROI));
                        image_input2(:,:) = app.window_max_protein_ROI(:,:,app.seed_point_id);
                    end
                    plot_imagesc(app,app.UIAxes6,image_input1)
                    plot_imagesc(app,app.UIAxes6_2,image_input2)
                    plot_imagesc(app,app.UIAxes6_3,app.max_int_all_movie_ROI)
                    plot_imagesc(app,app.UIAxes6_4,app.max_int_protein_all_movie_ROI)
                    app.whole_cell_window_percentile_projection=zeros([size(app.max_int_all_movie_ROI),app.seed_points_total(app.window_profile_id)]);
                    app.nucleus_window_percentile_projection=zeros([size(app.max_int_all_movie_ROI),app.seed_points_total(app.window_profile_id)]);
                    app.mitochondria_window_percentile_projection=zeros([size(app.max_int_all_movie_ROI),app.seed_points_total(app.window_profile_id)]);
                    for J=1:app.seed_points_total(app.window_profile_id)
                        if J == app.seed_points_total(app.window_profile_id)
                            end_pt = app.window_start_points(J+1);
                        else
                            end_pt = app.window_start_points(J+1)-1;
                        end
                        app.whole_cell_window_percentile_projection(:,:,J) = uint16(prctile(app.max_int_all_frames_ROI(:,:,app.window_start_points(J):end_pt),app.whole_cell_percentile,3));
                        app.nucleus_window_percentile_projection(:,:,J) = uint16(prctile(app.LSM_nuclear_movie_ROI(:,:,app.window_start_points(J):end_pt),app.nuclear_percentile,3));
                        app.mitochondria_window_percentile_projection(:,:,J) = uint16(prctile(app.LSM_mitochondria_movie_ROI(:,:,app.window_start_points(J):end_pt),app.mitochondria_percentile,3));
                    end
                    app.app_stage = 5;
                    app.rough_cell_boundary_created = 0;
                    app.NullButton_2.Value = true;
                    cla(app.UIAxes5);
                    app.TabGroup.SelectedTab = app.CellboundarycropTab;
                end
            end            
        end

        % Selection changed function: ImageSelectionButtonGroup
        function ImageSelectionButtonGroupSelectionChanged(app, event)
            stage_error_counter = stage_error(app,app.app_stage,5,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                if app.Image1Button.Value == true
                    if app.seed_points_total(app.window_profile_id) ==1
                        image_input = zeros(size(app.max_int_all_movie_ROI));
                        image_input(:,:) = app.max_int_all_movie_ROI;
                    else
                        image_input = zeros(size(app.max_int_all_movie_ROI));
                        image_input(:,:) = app.window_max_all_channels_ROI(:,:,app.seed_point_id);
                    end
                    plot_imagesc(app,app.UIAxes5,image_input)
                elseif app.Image2Button.Value == true
                    if app.seed_points_total(app.window_profile_id) ==1
                        image_input = zeros(size(app.max_int_all_movie_ROI));
                        image_input(:,:) = app.max_int_protein_all_movie_ROI;
                    else
                        image_input = zeros(size(app.max_int_all_movie_ROI));
                        image_input(:,:) = app.window_max_protein_ROI(:,:,app.seed_point_id);
                    end
                    plot_imagesc(app,app.UIAxes5,image_input)
                elseif app.Image3Button.Value == true
                    plot_imagesc(app,app.UIAxes5,app.max_int_all_movie_ROI)
                elseif app.Image4Button.Value == true
                    plot_imagesc(app,app.UIAxes5,app.max_int_protein_all_movie_ROI)
                else
                    cla(app.UIAxes5)
                end
            end
        end

        % Button pushed function: RoughCropButton
        function RoughCropButtonPushed(app, event)
            %Place errors here for incorrectly timed press
            stage_error_counter = stage_error(app,app.app_stage,5,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                if app.NullButton_2.Value == true
                    info_label = 'Error Messages';
                    lsm_type_error = 'Error. Select best image for cropping cell outline.';
                    app.error_counter = app.error_counter+ 1;
                    info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                else
                    app = error_message_reset(app);
                    if app.Image1Button.Value == true
                        if app.seed_points_total(app.window_profile_id) ==1
                            image_input = zeros(size(app.max_int_all_movie_ROI));
                            image_input(:,:) = app.max_int_all_movie_ROI;
                        else
                            image_input = zeros(size(app.max_int_all_movie_ROI));
                            image_input(:,:) = app.window_max_all_channels_ROI(:,:,app.seed_point_id);
                        end                
                    elseif app.Image2Button.Value == true
                        if app.seed_points_total(app.window_profile_id) ==1
                            image_input = zeros(size(app.max_int_all_movie_ROI));
                            image_input(:,:) = app.max_int_protein_all_movie_ROI;
                        else
                            image_input = zeros(size(app.max_int_all_movie_ROI));
                            image_input(:,:) = app.window_max_protein_ROI(:,:,app.seed_point_id);
                        end
                    elseif app.Image3Button.Value == true
                        image_input = zeros(size(app.max_int_all_movie_ROI));
                        image_input(:,:) =  app.max_int_all_movie_ROI;
                    elseif app.Image4Button.Value == true
                        image_input = zeros(size(app.max_int_all_movie_ROI));
                        image_input(:,:) =  app.max_int_protein_all_movie_ROI;
                    end
                    image_input=double(image_input-min(image_input(:)));
                    image_input=image_input./max(image_input(:));
                    image_input=uint16(round((2^16-1)*image_input));
                    rgbcell = ind2rgb(image_input, parula(2^16));
                    app.cropping_stage = 2; 
                    
                    rough_crop_image = figure;
                    set(rough_crop_image,'name','Rough crop','numbertitle','off')
                    imshow(rgbcell);
                    iptsetpref('ImshowAxesVisible','off')
                    axis('equal');
                    iptsetpref('ImshowAxesVisible','off')
                    while isvalid(rough_crop_image)
                        rough_window_bw = roipoly;
                        delete(rough_crop_image);
                    end
                    if exist('rough_window_bw','var')
                        if sum(rough_window_bw(:)>0)
                            app.rough_cell_boundary_window(:,:,app.seed_point_id) = rough_window_bw;
                            app.rough_cell_boundary_created = 1;
                            app.cropping_stage = 0;
                            if sum(rough_window_bw(:)) < 100
                                app = warning_compartment_area(app,1,1,1);
                            end
                        end
                    end
                end
            end
            
            
        end

        % Button pushed function: ConfirmRoughCropButton
        function ConfirmRoughCropButtonPushed(app, event)
            stage_error_counter = stage_error(app,app.app_stage,5,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                if app.rough_cell_boundary_created == 1
                    boundary_check(:,:) = app.rough_cell_boundary_window(:,:,app.seed_point_id);
                    if sum(boundary_check(:)) == 0
                        app = warning_compartment_area(app,1,1,2);
                    else
                        app.app_stage = 6;
                        app.whole_cell_threshold_slider_flag=0;
                        image_input = zeros(size(app.max_int_all_movie_ROI));
                        image_input(:,:) = app.whole_cell_window_percentile_projection(:,:,app.seed_point_id);
                        plot_imagesc(app,app.UIAxes8,image_input)
                        whole_cell_interval_image_bw=imbinarize(uint16(image_input),app.whole_cell_threshold);
                        whole_cell_interval_image_bw = whole_cell_interval_image_bw.*app.rough_cell_boundary_window(:,:,app.seed_point_id);
                        whole_cell_interval_image_bw=imfill(whole_cell_interval_image_bw,'holes');
                        SE = strel('disk', 1, 0);
                        whole_cell_interval_image_bw=imopen(whole_cell_interval_image_bw,SE);
                        L = bwlabel(whole_cell_interval_image_bw);
                        LNo=length(unique(L(:)))-1;
                        if(LNo>0)
                            clear SizeI
                            SizeI=zeros(1,LNo);
                            for J=1:LNo
                                index=find(L==J);
                                SizeI(J)=length(index);
                            end
                            whole_cell_interval_image_bw=bwareaopen(whole_cell_interval_image_bw,max(SizeI),8);
                        end
                        app.whole_cell_binary_window(:,:,app.seed_point_id)=whole_cell_interval_image_bw;
                        plot_imshow(app,app.UIAxes8_2,whole_cell_interval_image_bw)
                        whole_cell_interval_image_bw=bwmorph(whole_cell_interval_image_bw,'remove');
                        image_input=double(image_input-min(image_input(:)));
                        image_input=image_input./max(image_input(:));
                        image_input=uint16(round((2^16-1)*image_input));
                        rgbcell = ind2rgb(image_input, parula(2^16));
                        index=find(whole_cell_interval_image_bw);
                        channel=rgbcell(:,:,1);
                        channel(index)=2^16-1;
                        rgbcell(:,:,1)=channel;
                        channel=rgbcell(:,:,2);
                        channel(index)=2^16-1;
                        rgbcell(:,:,2)=channel;
                        channel=rgbcell(:,:,3);
                        channel(index)=2^16-1;
                        rgbcell(:,:,3)=channel;
                        plot_imshow(app,app.UIAxes8_3,rgbcell)
                        app.TabGroup.SelectedTab = app.CellthresholdingTab;
                    end
                else
                    info_label = 'Error Messages';
                    lsm_type_error = 'Error. Select Rough Crop to outline cell.';
                    app.error_counter = app.error_counter+ 1;
                    info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                end
                
            end            
        end

        % Value changed function: Thresholdscale01Slider
        function Thresholdscale01SliderValueChanged(app, event)
            value = app.Thresholdscale01Slider.Value;
            stage_error_counter = stage_error(app,app.app_stage,6,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                app.whole_cell_threshold_slider_flag=1;
                % determine which discrete option the current value is closest to.
                [min_major, minIdx_major] = min(abs(value - event.Source.MajorTicks(:)));
                [min_minor, minIdx_minor] = min(abs(value - event.Source.MinorTicks(:)));
                % move the slider to that option
                if min_major < min_minor
                   event.Source.Value = event.Source.MajorTicks(minIdx_major);
                   % Override the selected value if you plan on using it within this function
                   value = event.Source.MajorTicks(minIdx_major); 
                else
                   event.Source.Value = event.Source.MinorTicks(minIdx_minor);
                   % Override the selected value if you plan on using it within this function
                   value = event.Source.MinorTicks(minIdx_minor);                 
                end
                app.whole_cell_threshold_0_1 = value;
                app.whole_cell_threshold = app.whole_cell_threshold_0_1 + app.whole_cell_threshold_0_01 + app.whole_cell_threshold_0_001 + app.whole_cell_threshold_0_0001;
                [app,whole_cell_interval_image_bw,whole_cell_interval_image_bw_skel,rgbcell] = cell_threshold(app);
                plot_imshow(app,app.UIAxes8_2,whole_cell_interval_image_bw)
                plot_imshow(app,app.UIAxes8_3,rgbcell)
                threshold_label = ['Threshold value:' newline num2str(app.whole_cell_threshold,'%.5f')];
                app.Thresholdvalue000000Label.Text = threshold_label;
                if sum(whole_cell_interval_image_bw(:))<100
                    app = warning_compartment_area(app,1,2,1);
                end
            else
                event.Source.Value = event.Source.MajorTicks(1);
            end

        end

        % Value changed function: Thresholdscale001Slider
        function Thresholdscale001SliderValueChanged(app, event)
            value = app.Thresholdscale001Slider.Value;
            stage_error_counter = stage_error(app,app.app_stage,6,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                app.whole_cell_threshold_slider_flag=1;
                % determine which discrete option the current value is closest to.
                [min_major, minIdx_major] = min(abs(value - event.Source.MajorTicks(:)));
                [min_minor, minIdx_minor] = min(abs(value - event.Source.MinorTicks(:)));
                % move the slider to that option
                if min_major < min_minor
                   event.Source.Value = event.Source.MajorTicks(minIdx_major);
                   % Override the selected value if you plan on using it within this function
                   value = event.Source.MajorTicks(minIdx_major); 
                else
                   event.Source.Value = event.Source.MinorTicks(minIdx_minor);
                   % Override the selected value if you plan on using it within this function
                   value = event.Source.MinorTicks(minIdx_minor);                 
                end
                app.whole_cell_threshold_0_01 = value;
                app.whole_cell_threshold = app.whole_cell_threshold_0_1 + app.whole_cell_threshold_0_01 + app.whole_cell_threshold_0_001 + app.whole_cell_threshold_0_0001;
                [app,whole_cell_interval_image_bw,whole_cell_interval_image_bw_skel,rgbcell] = cell_threshold(app);
                plot_imshow(app,app.UIAxes8_2,whole_cell_interval_image_bw)
                plot_imshow(app,app.UIAxes8_3,rgbcell)
                threshold_label = ['Threshold value:' newline num2str(app.whole_cell_threshold,'%.5f')];
                app.Thresholdvalue000000Label.Text = threshold_label;
                if sum(whole_cell_interval_image_bw(:))<100
                    app = warning_compartment_area(app,1,2,1);
                end
            else
               event.Source.Value = event.Source.MajorTicks(1); 
            end
        end

        % Value changed function: Thresholdscale0001Slider
        function Thresholdscale0001SliderValueChanged(app, event)
            value = app.Thresholdscale0001Slider.Value;
            stage_error_counter = stage_error(app,app.app_stage,6,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                app.whole_cell_threshold_slider_flag=1;
                % determine which discrete option the current value is closest to.
                [min_major, minIdx_major] = min(abs(value - event.Source.MajorTicks(:)));
                [min_minor, minIdx_minor] = min(abs(value - event.Source.MinorTicks(:)));
                % move the slider to that option
                if min_major < min_minor
                   event.Source.Value = event.Source.MajorTicks(minIdx_major);
                   % Override the selected value if you plan on using it within this function
                   value = event.Source.MajorTicks(minIdx_major); 
                else
                   event.Source.Value = event.Source.MinorTicks(minIdx_minor);
                   % Override the selected value if you plan on using it within this function
                   value = event.Source.MinorTicks(minIdx_minor);                 
                end
                app.whole_cell_threshold_0_001 = value;
                app.whole_cell_threshold = app.whole_cell_threshold_0_1 + app.whole_cell_threshold_0_01 + app.whole_cell_threshold_0_001 + app.whole_cell_threshold_0_0001;
                [app,whole_cell_interval_image_bw,whole_cell_interval_image_bw_skel,rgbcell] = cell_threshold(app);
                plot_imshow(app,app.UIAxes8_2,whole_cell_interval_image_bw)
                plot_imshow(app,app.UIAxes8_3,rgbcell)
                threshold_label = ['Threshold value:' newline num2str(app.whole_cell_threshold,'%.5f')];
                app.Thresholdvalue000000Label.Text = threshold_label;
                if sum(whole_cell_interval_image_bw(:))<100
                    app = warning_compartment_area(app,1,2,1);
                end
            else
                event.Source.Value = event.Source.MajorTicks(1); 
            end
        end

        % Value changed function: Thresholdscale00001Slider
        function Thresholdscale00001SliderValueChanged(app, event)
            value = app.Thresholdscale00001Slider.Value;
            stage_error_counter = stage_error(app,app.app_stage,6,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                app.whole_cell_threshold_slider_flag=1;
                % determine which discrete option the current value is closest to.
                [min_major, minIdx_major] = min(abs(value - event.Source.MajorTicks(:)));
                [min_minor, minIdx_minor] = min(abs(value - event.Source.MinorTicks(:)));
                % move the slider to that option
                if min_major < min_minor
                   event.Source.Value = event.Source.MajorTicks(minIdx_major);
                   % Override the selected value if you plan on using it within this function
                   value = event.Source.MajorTicks(minIdx_major); 
                else
                   event.Source.Value = event.Source.MinorTicks(minIdx_minor);
                   % Override the selected value if you plan on using it within this function
                   value = event.Source.MinorTicks(minIdx_minor);                 
                end
                app.whole_cell_threshold_0_0001 = value;
                app.whole_cell_threshold = app.whole_cell_threshold_0_1 + app.whole_cell_threshold_0_01 + app.whole_cell_threshold_0_001 + app.whole_cell_threshold_0_0001;
                [app,whole_cell_interval_image_bw,whole_cell_interval_image_bw_skel,rgbcell] = cell_threshold(app);
                plot_imshow(app,app.UIAxes8_2,whole_cell_interval_image_bw)
                plot_imshow(app,app.UIAxes8_3,rgbcell)
                threshold_label = ['Threshold value:' newline num2str(app.whole_cell_threshold,'%.5f')];
                app.Thresholdvalue000000Label.Text = threshold_label;
                if sum(whole_cell_interval_image_bw(:))<100
                    app = warning_compartment_area(app,1,2,1);
                end
            else
                event.Source.Value = event.Source.MajorTicks(1); 
            end
        end

        % Button pushed function: AcceptthresholdButton
        function AcceptthresholdButtonPushed(app, event)
            stage_error_counter = stage_error(app,app.app_stage,6,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                if app.whole_cell_threshold_slider_flag == 1 || app.window_profile_id >1 || app.seed_point_id >1
                    boundary_check(:,:) = app.whole_cell_binary_window(:,:,app.seed_point_id);
                    if sum(boundary_check(:)) == 0
                        app = warning_compartment_area(app,1,2,2);
                    else
                        app.app_stage = 7;
                        app.whole_cell_thresholds(app.seed_point_id) = app.whole_cell_threshold;
                        app.mitochondria_threshold_slider_flag=0;
                        app.nucleus_threshold_slider_flag=0;
                        image_input = zeros(size(app.max_int_all_movie_ROI));
                        image_input(:,:) = app.mitochondria_window_percentile_projection(:,:,app.seed_point_id);
                        plot_imagesc(app,app.UIAxes10,image_input)
                        mito_cell_interval_image_bw = imbinarize(uint16(image_input),app.mitochondria_threshold);
                        mito_cell_interval_image_bw=app.whole_cell_binary_window(:,:,app.seed_point_id).*mito_cell_interval_image_bw;
                        app.mitochondria_binary_window(:,:,app.seed_point_id)=mito_cell_interval_image_bw;
                        plot_imshow(app,app.UIAxes10_2,mito_cell_interval_image_bw)
                        mito_cell_interval_image_skel=bwmorph(mito_cell_interval_image_bw,'remove');
                        image_input=double(image_input-min(image_input(:)));
                        image_input=image_input./max(image_input(:));
                        image_input=uint16(round((2^16-1)*image_input));
                        rgbcell = ind2rgb(image_input, parula(2^16));
                        index=find(mito_cell_interval_image_skel);
                        channel=rgbcell(:,:,1);
                        channel(index)=2^16-1;
                        rgbcell(:,:,1)=channel;
                        channel=rgbcell(:,:,2);
                        channel(index)=2^16-1;
                        rgbcell(:,:,2)=channel;
                        channel=rgbcell(:,:,3);
                        channel(index)=2^16-1;
                        rgbcell(:,:,3)=channel;
                        plot_imshow(app,app.UIAxes10_3,rgbcell)
                        image_input = zeros(size(app.max_int_all_movie_ROI));
                        image_input(:,:) = app.nucleus_window_percentile_projection(:,:,app.seed_point_id);
                        plot_imagesc(app,app.UIAxes10_4,image_input)
                        nuc_cell_interval_image_bw = imbinarize(uint16(image_input),app.nucleus_threshold);
                        nuc_cell_interval_image_bw=app.whole_cell_binary_window(:,:,app.seed_point_id).*nuc_cell_interval_image_bw;
                        nuc_cell_interval_image_bw=imfill(nuc_cell_interval_image_bw,'holes');
                        clear nuc_object_area
                        L = bwlabel(nuc_cell_interval_image_bw);
                        LNo=length(unique(L(:)))-1;
                        if LNo>0
                            nuc_object_area = zeros(1,LNo);
                            for J=1:LNo
                                index_area=find(L==J);
                                nuc_object_area(J)=length(index_area);
                            end
                            nuc_cell_interval_image_bw=bwareaopen(nuc_cell_interval_image_bw,max(nuc_object_area),8);
                        end
                        mito_cell_interval_image_bw = zeros(size(app.max_int_all_movie_ROI));
                        mito_cell_interval_image_bw(:,:) = app.mitochondria_binary_window(:,:,app.seed_point_id);
                        overlap_bw=nuc_cell_interval_image_bw.*mito_cell_interval_image_bw;
                        nuc_cell_interval_image_bw(overlap_bw==1)=0;
                        clear nuc_object_area
                        L = bwlabel(nuc_cell_interval_image_bw);
                        LNo=length(unique(L(:)))-1;
                        if(LNo>0)
                            for J=1:LNo
                                index=find(L==J);
                                nuc_object_area(J)=length(index);
                            end
                            nuc_cell_interval_image_bw=bwareaopen(nuc_cell_interval_image_bw,max(nuc_object_area),8);
                        end
                        app.nucleus_binary_window(:,:,app.seed_point_id)=nuc_cell_interval_image_bw;
                        plot_imshow(app,app.UIAxes10_5,nuc_cell_interval_image_bw)
                        nuc_cell_interval_image_skel=bwmorph(nuc_cell_interval_image_bw,'remove');
                        image_input=double(image_input-min(image_input(:)));
                        image_input=image_input./max(image_input(:));
                        image_input=uint16(round((2^16-1)*image_input));
                        rgbcell = ind2rgb(image_input, parula(2^16));
                        index=find(nuc_cell_interval_image_skel);
                        channel=rgbcell(:,:,1);
                        channel(index)=2^16-1;
                        rgbcell(:,:,1)=channel;
                        channel=rgbcell(:,:,2);
                        channel(index)=2^16-1;
                        rgbcell(:,:,2)=channel;
                        channel=rgbcell(:,:,3);
                        channel(index)=2^16-1;
                        rgbcell(:,:,3)=channel;
                        plot_imshow(app,app.UIAxes10_6,rgbcell)
                        
                        if app.window_profile_id >1 || app.seed_point_id >1
                            app = population_intensity_extraction(app);
                            intensity_plotting(app);
                        end
                        
                        
                        app.TabGroup.SelectedTab = app.CompartmentthresholdingTab;
                    end
                else
                    info_label = 'Error Messages';
                    lsm_type_error = 'Error. Use sliders to select desired cell threshold.';
                    stage_error_counter = 1;
                    info_label = [info_label newline '(1)' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';

                end
            end

        end

        % Value changed function: Mitochondriathresholdscale01Slider
        function Mitochondriathresholdscale01SliderValueChanged(app, event)
            value = app.Mitochondriathresholdscale01Slider.Value;
            stage_error_counter = stage_error(app,app.app_stage,7,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                app.mitochondria_threshold_slider_flag=1;
                
                [min_major, minIdx_major] = min(abs(value - event.Source.MajorTicks(:)));
                [min_minor, minIdx_minor] = min(abs(value - event.Source.MinorTicks(:)));

                if min_major < min_minor
                   event.Source.Value = event.Source.MajorTicks(minIdx_major);
                    
                   value = event.Source.MajorTicks(minIdx_major); 
                else
                   event.Source.Value = event.Source.MinorTicks(minIdx_minor);
                  
                   value = event.Source.MinorTicks(minIdx_minor);                 
                end
                app.mitochondria_threshold_0_1 = value;
                app.mitochondria_threshold = app.mitochondria_threshold_0_1 + app.mitochondria_threshold_0_01 + app.mitochondria_threshold_0_001 + app.mitochondria_threshold_0_0001;
                [app,mito_cell_interval_image_bw,rgbcell] = mitochondrial_threshold(app);
                plot_imshow(app,app.UIAxes10_2,mito_cell_interval_image_bw)
                plot_imshow(app,app.UIAxes10_3,rgbcell)
                [app,nuc_cell_interval_image_bw,rgbcell] = nuclear_threshold(app,mito_cell_interval_image_bw);        
                plot_imshow(app,app.UIAxes10_5,nuc_cell_interval_image_bw)
                plot_imshow(app,app.UIAxes10_6,rgbcell)
                app = population_intensity_extraction(app);
                intensity_plotting(app);
                threshold_label = ['Mitochondrial threshold value: '  num2str(app.mitochondria_threshold,'%.5f') newline 'Nucleus threshold value: ' num2str(app.nucleus_threshold,'%.5f')];
                app.Mitochondrialthresholdvalue000000Label.Text = threshold_label;
                if sum(mito_cell_interval_image_bw(:))<25 && sum(nuc_cell_interval_image_bw(:))<25
                    app = warning_compartment_area(app,4,2,1);
                elseif sum(mito_cell_interval_image_bw(:))<25
                    app = warning_compartment_area(app,2,2,1);
                elseif sum(nuc_cell_interval_image_bw(:))<25
                    app = warning_compartment_area(app,3,2,1);    
                end
            else
                event.Source.Value = event.Source.MajorTicks(1); 
            end
        end

        % Value changed function: 
        % Mitochondriathresholdscale001Slider
        function Mitochondriathresholdscale001SliderValueChanged(app, event)
            value = app.Mitochondriathresholdscale001Slider.Value;
            stage_error_counter = stage_error(app,app.app_stage,7,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                app.mitochondria_threshold_slider_flag=1;
                
                [min_major, minIdx_major] = min(abs(value - event.Source.MajorTicks(:)));
                [min_minor, minIdx_minor] = min(abs(value - event.Source.MinorTicks(:)));
                
                if min_major < min_minor
                   event.Source.Value = event.Source.MajorTicks(minIdx_major);
                   
                   value = event.Source.MajorTicks(minIdx_major); 
                else
                   event.Source.Value = event.Source.MinorTicks(minIdx_minor);
                   
                   value = event.Source.MinorTicks(minIdx_minor);                 
                end
                app.mitochondria_threshold_0_01 = value;
                app.mitochondria_threshold = app.mitochondria_threshold_0_1 + app.mitochondria_threshold_0_01 + app.mitochondria_threshold_0_001 + app.mitochondria_threshold_0_0001;
                [app,mito_cell_interval_image_bw,rgbcell] = mitochondrial_threshold(app);
                plot_imshow(app,app.UIAxes10_2,mito_cell_interval_image_bw)
                plot_imshow(app,app.UIAxes10_3,rgbcell)
                [app,nuc_cell_interval_image_bw,rgbcell] = nuclear_threshold(app,mito_cell_interval_image_bw);
                plot_imshow(app,app.UIAxes10_5,nuc_cell_interval_image_bw)
                plot_imshow(app,app.UIAxes10_6,rgbcell)
                app = population_intensity_extraction(app);
                intensity_plotting(app);
                threshold_label = ['Mitochondrial threshold value: '  num2str(app.mitochondria_threshold,'%.5f') newline 'Nucleus threshold value: ' num2str(app.nucleus_threshold,'%.5f')];
                app.Mitochondrialthresholdvalue000000Label.Text = threshold_label;
                if sum(mito_cell_interval_image_bw(:))<25 && sum(nuc_cell_interval_image_bw(:))<25
                    app = warning_compartment_area(app,4,2,1);
                elseif sum(mito_cell_interval_image_bw(:))<25
                    app = warning_compartment_area(app,2,2,1);
                elseif sum(nuc_cell_interval_image_bw(:))<25
                    app = warning_compartment_area(app,3,2,1);    
                end
            else
                event.Source.Value = event.Source.MajorTicks(1); 
            end
        end

        % Value changed function: 
        % Mitochondriathresholdscale0001Slider
        function Mitochondriathresholdscale0001SliderValueChanged(app, event)
            value = app.Mitochondriathresholdscale0001Slider.Value;
            stage_error_counter = stage_error(app,app.app_stage,7,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                app.mitochondria_threshold_slider_flag=1;
                
                [min_major, minIdx_major] = min(abs(value - event.Source.MajorTicks(:)));
                [min_minor, minIdx_minor] = min(abs(value - event.Source.MinorTicks(:)));
                
                if min_major < min_minor
                   event.Source.Value = event.Source.MajorTicks(minIdx_major);
                   
                   value = event.Source.MajorTicks(minIdx_major); 
                else
                   event.Source.Value = event.Source.MinorTicks(minIdx_minor);
                   
                   value = event.Source.MinorTicks(minIdx_minor);                 
                end
                app.mitochondria_threshold_0_001 = value;
                app.mitochondria_threshold = app.mitochondria_threshold_0_1 + app.mitochondria_threshold_0_01 + app.mitochondria_threshold_0_001 + app.mitochondria_threshold_0_0001;
                [app,mito_cell_interval_image_bw,rgbcell] = mitochondrial_threshold(app);
                plot_imshow(app,app.UIAxes10_2,mito_cell_interval_image_bw)
                plot_imshow(app,app.UIAxes10_3,rgbcell)
                [app,nuc_cell_interval_image_bw,rgbcell] = nuclear_threshold(app,mito_cell_interval_image_bw);
                plot_imshow(app,app.UIAxes10_5,nuc_cell_interval_image_bw)
                plot_imshow(app,app.UIAxes10_6,rgbcell)
                app = population_intensity_extraction(app);
                intensity_plotting(app);
                threshold_label = ['Mitochondrial threshold value: '  num2str(app.mitochondria_threshold,'%.5f') newline 'Nucleus threshold value: ' num2str(app.nucleus_threshold,'%.5f')];
                app.Mitochondrialthresholdvalue000000Label.Text = threshold_label;
                if sum(mito_cell_interval_image_bw(:))<25 && sum(nuc_cell_interval_image_bw(:))<25
                    app = warning_compartment_area(app,4,2,1);
                elseif sum(mito_cell_interval_image_bw(:))<25
                    app = warning_compartment_area(app,2,2,1);
                elseif sum(nuc_cell_interval_image_bw(:))<25
                    app = warning_compartment_area(app,3,2,1);    
                end
            else
                event.Source.Value = event.Source.MajorTicks(1);
            end   
        end

        % Value changed function: 
        % Mitochondriathresholdscale00001Slider
        function Mitochondriathresholdscale00001SliderValueChanged(app, event)
            value = app.Mitochondriathresholdscale00001Slider.Value;
            stage_error_counter = stage_error(app,app.app_stage,7,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                app.mitochondria_threshold_slider_flag=1;
                
                [min_major, minIdx_major] = min(abs(value - event.Source.MajorTicks(:)));
                [min_minor, minIdx_minor] = min(abs(value - event.Source.MinorTicks(:)));
                
                if min_major < min_minor
                   event.Source.Value = event.Source.MajorTicks(minIdx_major);
                   
                   value = event.Source.MajorTicks(minIdx_major); 
                else
                   event.Source.Value = event.Source.MinorTicks(minIdx_minor);
                   
                   value = event.Source.MinorTicks(minIdx_minor);                 
                end
                app.mitochondria_threshold_0_0001 = value;
                app.mitochondria_threshold = app.mitochondria_threshold_0_1 + app.mitochondria_threshold_0_01 + app.mitochondria_threshold_0_001 + app.mitochondria_threshold_0_0001;
                [app,mito_cell_interval_image_bw,rgbcell] = mitochondrial_threshold(app);
                plot_imshow(app,app.UIAxes10_2,mito_cell_interval_image_bw)
                plot_imshow(app,app.UIAxes10_3,rgbcell)
                [app,nuc_cell_interval_image_bw,rgbcell] = nuclear_threshold(app,mito_cell_interval_image_bw);
                plot_imshow(app,app.UIAxes10_5,nuc_cell_interval_image_bw)
                plot_imshow(app,app.UIAxes10_6,rgbcell)
                app = population_intensity_extraction(app);
                intensity_plotting(app);
                threshold_label = ['Mitochondrial threshold value: '  num2str(app.mitochondria_threshold,'%.5f') newline 'Nucleus threshold value: ' num2str(app.nucleus_threshold,'%.5f')];
                app.Mitochondrialthresholdvalue000000Label.Text = threshold_label;
                if sum(mito_cell_interval_image_bw(:))<25 && sum(nuc_cell_interval_image_bw(:))<25
                    app = warning_compartment_area(app,4,2,1);
                elseif sum(mito_cell_interval_image_bw(:))<25
                    app = warning_compartment_area(app,2,2,1);
                elseif sum(nuc_cell_interval_image_bw(:))<25
                    app = warning_compartment_area(app,3,2,1);    
                end
            else
                event.Source.Value = event.Source.MajorTicks(1);                
            end
        end

        % Value changed function: Nucleusthresholdscale01Slider
        function Nucleusthresholdscale01SliderValueChanged(app, event)
            value = app.Nucleusthresholdscale01Slider.Value;
            stage_error_counter = stage_error(app,app.app_stage,7,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                app.nucleus_threshold_slider_flag=1;
                
                [min_major, minIdx_major] = min(abs(value - event.Source.MajorTicks(:)));
                [min_minor, minIdx_minor] = min(abs(value - event.Source.MinorTicks(:)));
                
                if min_major < min_minor
                   event.Source.Value = event.Source.MajorTicks(minIdx_major);
                   
                   value = event.Source.MajorTicks(minIdx_major); 
                else
                   event.Source.Value = event.Source.MinorTicks(minIdx_minor);
                   
                   value = event.Source.MinorTicks(minIdx_minor);                 
                end
                app.nucleus_threshold_0_1 = value;
                app.nucleus_threshold = app.nucleus_threshold_0_1 + app.nucleus_threshold_0_01 + app.nucleus_threshold_0_001 + app.nucleus_threshold_0_0001;
                image_input = zeros(size(app.max_int_all_movie_ROI));
                image_input(:,:) = app.nucleus_window_percentile_projection(:,:,app.seed_point_id);
                mito_cell_interval_image_bw = zeros(size(app.max_int_all_movie_ROI));
                mito_cell_interval_image_bw(:,:) = app.mitochondria_binary_window(:,:,app.seed_point_id);
                [app,nuc_cell_interval_image_bw,rgbcell] = nuclear_threshold(app,mito_cell_interval_image_bw);
                plot_imshow(app,app.UIAxes10_5,nuc_cell_interval_image_bw)
                plot_imshow(app,app.UIAxes10_6,rgbcell)
                app = population_intensity_extraction(app);
                intensity_plotting(app);
                threshold_label = ['Mitochondrial threshold value: '  num2str(app.mitochondria_threshold,'%.5f') newline 'Nucleus threshold value: ' num2str(app.nucleus_threshold,'%.5f')];
                app.Mitochondrialthresholdvalue000000Label.Text = threshold_label;
                if sum(mito_cell_interval_image_bw(:))<25 && sum(nuc_cell_interval_image_bw(:))<25
                    app = warning_compartment_area(app,4,2,1);
                elseif sum(mito_cell_interval_image_bw(:))<25
                    app = warning_compartment_area(app,2,2,1);
                elseif sum(nuc_cell_interval_image_bw(:))<25
                    app = warning_compartment_area(app,3,2,1);    
                end
            else
                event.Source.Value = event.Source.MajorTicks(1);                
            end
        end

        % Value changed function: Nucleusthresholdscale001Slider
        function Nucleusthresholdscale001SliderValueChanged(app, event)
            value = app.Nucleusthresholdscale001Slider.Value;
            stage_error_counter = stage_error(app,app.app_stage,7,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                app.nucleus_threshold_slider_flag=1;
               
                [min_major, minIdx_major] = min(abs(value - event.Source.MajorTicks(:)));
                [min_minor, minIdx_minor] = min(abs(value - event.Source.MinorTicks(:)));
                
                if min_major < min_minor
                   event.Source.Value = event.Source.MajorTicks(minIdx_major);
                  
                   value = event.Source.MajorTicks(minIdx_major); 
                else
                   event.Source.Value = event.Source.MinorTicks(minIdx_minor);
                   
                   value = event.Source.MinorTicks(minIdx_minor);                 
                end
                app.nucleus_threshold_0_01 = value;
                app.nucleus_threshold = app.nucleus_threshold_0_1 + app.nucleus_threshold_0_01 + app.nucleus_threshold_0_001 + app.nucleus_threshold_0_0001;
                mito_cell_interval_image_bw = zeros(size(app.max_int_all_movie_ROI));
                mito_cell_interval_image_bw(:,:) = app.mitochondria_binary_window(:,:,app.seed_point_id);
                [app,nuc_cell_interval_image_bw,rgbcell] = nuclear_threshold(app,mito_cell_interval_image_bw);
                plot_imshow(app,app.UIAxes10_5,nuc_cell_interval_image_bw)
                plot_imshow(app,app.UIAxes10_6,rgbcell)
                app = population_intensity_extraction(app);
                intensity_plotting(app);
                threshold_label = ['Mitochondrial threshold value: '  num2str(app.mitochondria_threshold,'%.5f') newline 'Nucleus threshold value: ' num2str(app.nucleus_threshold,'%.5f')];
                app.Mitochondrialthresholdvalue000000Label.Text = threshold_label;
                if sum(mito_cell_interval_image_bw(:))<25 && sum(nuc_cell_interval_image_bw(:))<25
                    app = warning_compartment_area(app,4,2,1);
                elseif sum(mito_cell_interval_image_bw(:))<25
                    app = warning_compartment_area(app,2,2,1);
                elseif sum(nuc_cell_interval_image_bw(:))<25
                    app = warning_compartment_area(app,3,2,1);    
                end
            else
                event.Source.Value = event.Source.MajorTicks(1);
            end
        end

        % Value changed function: Nucleusthresholdscale0001Slider
        function Nucleusthresholdscale0001SliderValueChanged(app, event)
            value = app.Nucleusthresholdscale0001Slider.Value;
            stage_error_counter = stage_error(app,app.app_stage,7,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                app.nucleus_threshold_slider_flag=1;
                
                [min_major, minIdx_major] = min(abs(value - event.Source.MajorTicks(:)));
                [min_minor, minIdx_minor] = min(abs(value - event.Source.MinorTicks(:)));
                
                if min_major < min_minor
                   event.Source.Value = event.Source.MajorTicks(minIdx_major);
                   
                   value = event.Source.MajorTicks(minIdx_major); 
                else
                   event.Source.Value = event.Source.MinorTicks(minIdx_minor);
                   
                   value = event.Source.MinorTicks(minIdx_minor);                 
                end
                app.nucleus_threshold_0_001 = value;
                app.nucleus_threshold = app.nucleus_threshold_0_1 + app.nucleus_threshold_0_01 + app.nucleus_threshold_0_001 + app.nucleus_threshold_0_0001;
                mito_cell_interval_image_bw = zeros(size(app.max_int_all_movie_ROI));
                mito_cell_interval_image_bw(:,:) = app.mitochondria_binary_window(:,:,app.seed_point_id);
                [app,nuc_cell_interval_image_bw,rgbcell] = nuclear_threshold(app,mito_cell_interval_image_bw);
                plot_imshow(app,app.UIAxes10_5,nuc_cell_interval_image_bw)
                plot_imshow(app,app.UIAxes10_6,rgbcell)
                app = population_intensity_extraction(app);
                intensity_plotting(app);
                threshold_label = ['Mitochondrial threshold value: '  num2str(app.mitochondria_threshold,'%.5f') newline 'Nucleus threshold value: ' num2str(app.nucleus_threshold,'%.5f')];
                app.Mitochondrialthresholdvalue000000Label.Text = threshold_label;
                if sum(mito_cell_interval_image_bw(:))<25 && sum(nuc_cell_interval_image_bw(:))<25
                    app = warning_compartment_area(app,4,2,1);
                elseif sum(mito_cell_interval_image_bw(:))<25
                    app = warning_compartment_area(app,2,2,1);
                elseif sum(nuc_cell_interval_image_bw(:))<25
                    app = warning_compartment_area(app,3,2,1);    
                end
            else
                event.Source.Value = event.Source.MajorTicks(1);
            end
        end

        % Value changed function: Nucleusthresholdscale00001Slider
        function Nucleusthresholdscale00001SliderValueChanged(app, event)
            value = app.Nucleusthresholdscale00001Slider.Value;
            stage_error_counter = stage_error(app,app.app_stage,7,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                app.nucleus_threshold_slider_flag=1;
                
                [min_major, minIdx_major] = min(abs(value - event.Source.MajorTicks(:)));
                [min_minor, minIdx_minor] = min(abs(value - event.Source.MinorTicks(:)));
                
                if min_major < min_minor
                   event.Source.Value = event.Source.MajorTicks(minIdx_major);
                   
                   value = event.Source.MajorTicks(minIdx_major); 
                else
                   event.Source.Value = event.Source.MinorTicks(minIdx_minor);
                   
                   value = event.Source.MinorTicks(minIdx_minor);                 
                end
                app.nucleus_threshold_0_0001 = value;
                app.nucleus_threshold = app.nucleus_threshold_0_1 + app.nucleus_threshold_0_01 + app.nucleus_threshold_0_001 + app.nucleus_threshold_0_0001;
                mito_cell_interval_image_bw = zeros(size(app.max_int_all_movie_ROI));
                mito_cell_interval_image_bw(:,:) = app.mitochondria_binary_window(:,:,app.seed_point_id);
                [app,nuc_cell_interval_image_bw,rgbcell] = nuclear_threshold(app,mito_cell_interval_image_bw);
                plot_imshow(app,app.UIAxes10_5,nuc_cell_interval_image_bw)
                plot_imshow(app,app.UIAxes10_6,rgbcell)
                app = population_intensity_extraction(app);
                intensity_plotting(app);
                threshold_label = ['Mitochondrial threshold value: '  num2str(app.mitochondria_threshold,'%.5f') newline 'Nucleus threshold value: ' num2str(app.nucleus_threshold,'%.5f')];
                app.Mitochondrialthresholdvalue000000Label.Text = threshold_label;
                if sum(mito_cell_interval_image_bw(:))<25 && sum(nuc_cell_interval_image_bw(:))<25
                    app = warning_compartment_area(app,4,2,1);
                elseif sum(mito_cell_interval_image_bw(:))<25
                    app = warning_compartment_area(app,2,2,1);
                elseif sum(nuc_cell_interval_image_bw(:))<25
                    app = warning_compartment_area(app,3,2,1);    
                end
            else
                event.Source.Value = event.Source.MajorTicks(1);
            end
        end

        % Button pushed function: AcceptthresholdsButton
        function AcceptthresholdsButtonPushed(app, event)
            stage_error_counter = stage_error(app,app.app_stage,7,app.cropping_stage);
            if stage_error_counter == 0
                app.input_flag = 1;
                app = error_message_reset(app);
                info_label = 'Error Messages';
                if app.mitochondria_threshold_slider_flag == 0 && app.mitochondria_threshold ==0 
                   lsm_type_error = ['Error. Select the mitochondrial threshold using' newline 'the sliders.'];
                   app.input_flag = 0;
                   app.error_counter = app.error_counter +1;
                   info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                   app.ErrorMessagesLabel.Text = info_label;
                   app.ErrorMessagesLabel.BackgroundColor = 'k';
                   return
                end
                if app.nucleus_threshold_slider_flag == 0 && app.nucleus_threshold ==0
                   lsm_type_error = ['Error. Select the nuclear threshold using the' newline 'sliders.'];
                   app.input_flag = 0;
                   app.error_counter = app.error_counter +1;
                   info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                   app.ErrorMessagesLabel.Text = info_label;
                   app.ErrorMessagesLabel.BackgroundColor = 'k';
                   return
                end
                nuc_bw(:,:) = app.nucleus_binary_window(:,:,app.seed_point_id);
                mito_bw(:,:) = app.mitochondria_binary_window(:,:,app.seed_point_id);
                if sum(nuc_bw(:)) == 0 && sum(mito_bw(:)) == 0
                   lsm_type_error = ['Error. Both the nuclear and mitochondrial' newline 'compartments are of size zero.' newline 'Adjust thresholds.'];
                   app.input_flag = 0;
                   app.error_counter = app.error_counter +1;
                   info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                   app.ErrorMessagesLabel.Text = info_label;
                   app.ErrorMessagesLabel.BackgroundColor = 'k';
                   return
                end
                
                
                if app.input_flag ==1
                    app = error_message_reset(app);
                    app = population_intensity_extraction(app);
                    intensity_plotting(app);
                    app.mitochondria_thresholds(app.seed_point_id) = app.mitochondria_threshold;
                    app.nucleus_thresholds(app.seed_point_id) = app.nucleus_threshold;
                    input_data = {app.seed_points_total(app.window_profile_id) app.seed_point_id app.whole_cell_percentile app.nuclear_percentile app.mitochondria_percentile app.whole_cell_threshold app.mitochondria_threshold app.nucleus_threshold};
                    app.UITable.Data = [app.segmentation_table_input; input_data];
                    app.segmentation_table_input = app.UITable.Data;
                    app.UITable.BackgroundColor=app.segmentation_table_background_colour;%[1 1 1 ;0.85 0.85 1];
                    app.UITable.ForegroundColor=[0.5,0.5,0.5];
    
                   
                    if app.seed_point_id == app.seed_points_total(app.window_profile_id)
                        f = uifigure;
                        %d = uiprogressdlg(f,'Title','Please wait. Interpolating.','Indeterminate','on');
                        d = uiprogressdlg(f,'Title','Please wait. Interpolating.');
                        d.Value = 0;
                        app = interp_rough_region(app);
                        d.Value = 1/7;
                        app = interp_thresholds(app);
                        d.Value = 2/7;
                        app = interp_intensities(app);
                        d.Value = 3/7;
                        clear interp_cytoplasm_population interp_nucleus_population interp_mitochondria_population interp_total_population
                        for J=1:app.total_frames
                            frame_cell = zeros(size(app.max_int_all_movie_ROI));
                            frame_cell(:,:) = app.whole_cell_windows_interp(:,:,J);
                               
                            whole_cell_frame_image_bw=imbinarize(uint16(frame_cell),app.whole_cell_thresholds_interp(J));
                            whole_cell_frame_image_bw = whole_cell_frame_image_bw.*app.rough_cell_boundary_interpolated(:,:,J);
                            whole_cell_frame_image_bw=imfill(whole_cell_frame_image_bw,'holes');
                            SE = strel('disk', 1, 0);
                            whole_cell_frame_image_bw=imopen(whole_cell_frame_image_bw,SE);
                            L = bwlabel(whole_cell_frame_image_bw);
                            LNo=length(unique(L(:)))-1;
                            if(LNo>0)
                                clear SizeI
                                SizeI=zeros(1,LNo);
                                for K=1:LNo
                                    index=find(L==K);
                                    SizeI(K)=length(index);
                                end
                                whole_cell_frame_image_bw=bwareaopen(whole_cell_frame_image_bw,max(SizeI),8);
                            end
                            app.whole_cell_interpolated_region_bw(:,:,J) = whole_cell_frame_image_bw;
                        end
                        app.whole_cell_interpolated_region_bw=logical(round(smoothdata(double(app.whole_cell_interpolated_region_bw),3,'movmean',10)));       
                        d.Value = 4/7;
                        
                        for J=1:app.total_frames
                                                           
                            whole_cell_frame_image_bw(:,:)=app.whole_cell_interpolated_region_bw(:,:,J);
                            whole_cell_frame_image_bw=imfill(whole_cell_frame_image_bw,'holes');
                            L = bwlabel(whole_cell_frame_image_bw);
                            LNo=length(unique(L(:)))-1;
                            if(LNo>0)
                                clear SizeI
                                SizeI=zeros(1,LNo);
                                for K=1:LNo
                                    index=find(L==K);
                                    SizeI(K)=length(index);
                                end
                                whole_cell_frame_image_bw=bwareaopen(whole_cell_frame_image_bw,max(SizeI),8);
                            end
                            app.whole_cell_interpolated_region_bw(:,:,J) = whole_cell_frame_image_bw;


                            frame_mito = zeros(size(app.max_int_all_movie_ROI));
                            frame_mito(:,:) = app.mitochondria_windows_interp(:,:,J);
                                
                            mito_cell_frame_image_bw = imbinarize(uint16(frame_mito),app.mitochondria_thresholds_interp(J));
                            mito_cell_frame_image_bw=whole_cell_frame_image_bw.*mito_cell_frame_image_bw;
                            app.mitochondria_interpolated_region_bw(:,:,J) = mito_cell_frame_image_bw;
                        end
                        app.mitochondria_interpolated_region_bw=logical(round(smoothdata(double(app.mitochondria_interpolated_region_bw),3,'movmean',10)));
                        d.Value =5/7;    
                            
                        for J=1:app.total_frames
                            whole_cell_frame_image_bw(:,:)=app.whole_cell_interpolated_region_bw(:,:,J);
                            mito_cell_frame_image_bw(:,:)=app.mitochondria_interpolated_region_bw(:,:,J);
                            frame_nuc = zeros(size(app.max_int_all_movie_ROI));
                            frame_nuc(:,:) = app.nucleus_windows_interp(:,:,J);
                            
                            nuc_cell_frame_image_bw = imbinarize(uint16(frame_nuc),app.nucleus_thresholds_interp(J));
                            nuc_cell_frame_image_bw=whole_cell_frame_image_bw.*nuc_cell_frame_image_bw;
                            nuc_cell_frame_image_bw=imfill(nuc_cell_frame_image_bw,'holes');
                            L = bwlabel(nuc_cell_frame_image_bw);
                            LNo=length(unique(L(:)))-1;
                            if LNo>0
                                nuc_object_area = zeros(1,LNo);
                                for K=1:LNo
                                    index_area=find(L==K);
                                    nuc_object_area(K)=length(index_area);
                                end
                                nuc_cell_frame_image_bw=bwareaopen(nuc_cell_frame_image_bw,max(nuc_object_area),8);
                            end
                            overlap_bw=nuc_cell_frame_image_bw.*mito_cell_frame_image_bw;
                            nuc_cell_frame_image_bw(overlap_bw==1)=0;
                            L = bwlabel(nuc_cell_frame_image_bw);
                            LNo=length(unique(L(:)))-1;
                            if(LNo>0)
                                nuc_object_area = zeros(1,LNo);
                                for K=1:LNo
                                    index=find(L==K);
                                    nuc_object_area(K)=length(index);
                                end
                                nuc_cell_frame_image_bw=bwareaopen(nuc_cell_frame_image_bw,max(nuc_object_area),8);
                            end
                            
                            app.nucleus_interpolated_region_bw(:,:,J) = nuc_cell_frame_image_bw;
                        end
                        app.nucleus_interpolated_region_bw=logical(round(smoothdata(double(app.nucleus_interpolated_region_bw),3,'movmean',10)));
                        d.Value =6/7;
                        for J=1:app.total_frames
                            whole_cell_frame_image_bw(:,:)=app.whole_cell_interpolated_region_bw(:,:,J);
                            mito_cell_frame_image_bw(:,:)=app.mitochondria_interpolated_region_bw(:,:,J);
                            nuc_cell_frame_image_bw(:,:)=app.nucleus_interpolated_region_bw(:,:,J);
                            
                            
                            nuc_cell_frame_image_bw=whole_cell_frame_image_bw.*nuc_cell_frame_image_bw;
                            nuc_cell_frame_image_bw=imfill(nuc_cell_frame_image_bw,'holes');
                            overlap_bw=nuc_cell_frame_image_bw.*mito_cell_frame_image_bw;
                            nuc_cell_frame_image_bw(overlap_bw==1)=0;
                            L = bwlabel(nuc_cell_frame_image_bw);
                            LNo=length(unique(L(:)))-1;
                            if(LNo>0)
                                nuc_object_area = zeros(1,LNo);
                                for K=1:LNo
                                    index=find(L==K);
                                    nuc_object_area(K)=length(index);
                                end
                                nuc_cell_frame_image_bw=bwareaopen(nuc_cell_frame_image_bw,max(nuc_object_area),8);
                            end
                            
                            app.nucleus_interpolated_region_bw(:,:,J) = nuc_cell_frame_image_bw;
                            
                            
                            cytoplasm_cell_frame_image_bw = max(whole_cell_frame_image_bw - nuc_cell_frame_image_bw - mito_cell_frame_image_bw,0);
                            single_protein_frame(:,:) = app.LSM_protein_movie_ROI(:,:,J);
                            protein_input = single_protein_frame(cytoplasm_cell_frame_image_bw > 0);
                            interp_cytoplasm_population(J) = sum(protein_input(:));
                            protein_input = single_protein_frame(nuc_cell_frame_image_bw > 0);
                            interp_nucleus_population(J) = sum(protein_input(:));
                            protein_input = single_protein_frame(mito_cell_frame_image_bw > 0);
                            interp_mitochondria_population(J) = sum(protein_input(:));
                            interp_total_population(J)=interp_cytoplasm_population(J)+interp_nucleus_population(J)+interp_mitochondria_population(J);
                            
                        end
                    
                        app.compartment_data(app.window_profile_id).window_start_points=app.window_start_points;
                        app.compartment_data(app.window_profile_id).window_mid_points=app.window_mid_points;
                        app.compartment_data(app.window_profile_id).whole_cell_percentile=app.whole_cell_percentile;
                        app.compartment_data(app.window_profile_id).nuclear_percentile=app.nuclear_percentile;
                        app.compartment_data(app.window_profile_id).mitochondria_percentile=app.mitochondria_percentile;
                        app.compartment_data(app.window_profile_id).whole_cell_interval_image=app.whole_cell_window_percentile_projection;
                        app.compartment_data(app.window_profile_id).nucleus_window_percentile_projection=app.nucleus_window_percentile_projection;
                        app.compartment_data(app.window_profile_id).mitochondria_window_percentile_projection=app.mitochondria_window_percentile_projection;
                        app.compartment_data(app.window_profile_id).rough_cell_boundary_window=app.rough_cell_boundary_window;
                        app.compartment_data(app.window_profile_id).whole_cell_thresholds=app.whole_cell_thresholds;
                        app.compartment_data(app.window_profile_id).mitochondria_thresholds=app.mitochondria_thresholds;
                        app.compartment_data(app.window_profile_id).nucleus_thresholds=app.nucleus_thresholds;
                        app.compartment_data(app.window_profile_id).whole_cell_binary_window=app.whole_cell_binary_window;
                        app.compartment_data(app.window_profile_id).mitochondria_binary_window=app.mitochondria_binary_window;
                        app.compartment_data(app.window_profile_id).nucleus_binary_window=app.nucleus_binary_window;
                        app.compartment_data(app.window_profile_id).rough_cell_boundary_interpolated=app.rough_cell_boundary_interpolated;
                        app.compartment_data(app.window_profile_id).interp_whole_cell_threshold=app.whole_cell_thresholds_interp;
                        app.compartment_data(app.window_profile_id).interp_mito_threshold=app.mitochondria_thresholds_interp;
                        app.compartment_data(app.window_profile_id).interp_nucleus_threshold=app.nucleus_thresholds_interp;
                        app.compartment_data(app.window_profile_id).whole_cell_windows_interp=app.whole_cell_windows_interp;
                        app.compartment_data(app.window_profile_id).nucleus_windows_interp=app.nucleus_windows_interp;
                        app.compartment_data(app.window_profile_id).mitochondria_windows_interp=app.mitochondria_windows_interp;
                        app.compartment_data(app.window_profile_id).interp_cytoplasm_population=interp_cytoplasm_population;
                        app.compartment_data(app.window_profile_id).interp_nucleus_population=interp_nucleus_population;
                        app.compartment_data(app.window_profile_id).interp_mitochondria_population=interp_mitochondria_population;
                        app.compartment_data(app.window_profile_id).interp_total_population=interp_total_population;
                        app.compartment_data(app.window_profile_id).whole_cell_interpolated_region_bw=app.whole_cell_interpolated_region_bw;
                        app.compartment_data(app.window_profile_id).nucleus_interpolated_region_bw=app.nucleus_interpolated_region_bw;
                        app.compartment_data(app.window_profile_id).mitochondria_interpolated_region_bw=app.mitochondria_interpolated_region_bw;
                        app.compartment_data(app.window_profile_id).nucleus_population = app.population_nucleus;
                        app.compartment_data(app.window_profile_id).cytoplasm_population = app.population_cytoplasm;
                        app.compartment_data(app.window_profile_id).mitochondria_population = app.population_mitochondria;
                        app.compartment_data(app.window_profile_id).total_population = app.population_total;
                        
                        app.historical_population_cytoplasm(:,app.window_profile_id) = app.population_cytoplasm;
                        app.historical_population_mitochondria(:,app.window_profile_id) =app.population_mitochondria;
                        app.historical_population_nucleus(:,app.window_profile_id) =app.population_nucleus;
                        app.historical_population_total(:,app.window_profile_id) =app.population_total;
                        
                        whole_cell_bw = app.compartment_data(app.window_profile_id).whole_cell_interpolated_region_bw;
                        nuc_bw = app.compartment_data(app.window_profile_id).nucleus_interpolated_region_bw;
                        mito_bw = app.compartment_data(app.window_profile_id).mitochondria_interpolated_region_bw;
                        
                        Cell=logical(median(double(whole_cell_bw(:,:,1:app.steady_state_end)),3));
                        Cell=bwareafilt(Cell,1);
                        Cell=imfill(Cell,'holes');
                        
                        Nuc=logical(median(nuc_bw(:,:,1:app.steady_state_end),3));
                        Nuc=bwareafilt(Nuc,1);
                        Nuc=imfill(Nuc,'holes');
                        
                        Mito=logical(median(mito_bw(:,:,1:app.steady_state_end),3));
                        
                        
                        s=regionprops(Nuc,'Eccentricity','Perimeter','Area');
                        nuc_eccentric=s.Eccentricity;%Circle is 0, Line is 1.
                        index=find(Nuc);
                        [I1,J1] = ind2sub(size(Nuc),index);
                        nuc_shp = alphaShape(I1,J1,1);
                        nuc_circularity=4*pi*nuc_shp.area/nuc_shp.perimeter^2;
                        app.compartment_data(app.window_profile_id).nuclear_eccentricity=nuc_eccentric;
                        app.compartment_data(app.window_profile_id).nuclear_area=nuc_shp.area;
                        app.compartment_data(app.window_profile_id).nuclear_perimeter=nuc_shp.perimeter;
                        app.compartment_data(app.window_profile_id).nuclear_circularity=nuc_circularity;
                        
                        s=regionprops(Cell,'Eccentricity');
                        cell_eccentric=s.Eccentricity;%Circle is 0, Line is 1.
                        index=find(Cell);
                        [I1,J1] = ind2sub(size(Cell),index);
                        cell_shp = alphaShape(I1,J1,1);
                        cell_circularity=4*pi*cell_shp.area/cell_shp.perimeter^2;
                        app.compartment_data(app.window_profile_id).cell_eccentricity=cell_eccentric;
                        app.compartment_data(app.window_profile_id).cell_area=cell_shp.area;
                        app.compartment_data(app.window_profile_id).cell_perimeter=cell_shp.perimeter;
                        app.compartment_data(app.window_profile_id).cell_circularity=cell_circularity;
                        
                        
                        app.compartment_data(app.window_profile_id).mito_cell_area_proportion=sum(Mito(:))/sum(Cell(:));
                        app.compartment_data(app.window_profile_id).nuc_cell_area_proportion=sum(Nuc(:))/sum(Cell(:)); 
                        
                        for mean_conc_frame = 1:app.steady_state_end
                            protein_frame(:,:)=app.LSM_protein_movie_ROI(:,:,mean_conc_frame);
                            cyto_index=find(max(Cell-Nuc-Mito,0));
                            nuc_index=find(Nuc);
                            mito_index=find(Mito);
                        
                            cyto_mean(mean_conc_frame)=mean(protein_frame(cyto_index));
                            nuc_mean(mean_conc_frame)=mean(protein_frame(nuc_index));
                            mito_mean(mean_conc_frame)=mean(protein_frame(mito_index));
                            
                            cyto_total(mean_conc_frame)=sum(protein_frame(cyto_index));
                            nuc_total(mean_conc_frame)=sum(protein_frame(nuc_index));
                            mito_total(mean_conc_frame)=sum(protein_frame(mito_index));
                            
                            
                        end
                        
                        app.compartment_data(app.window_profile_id).nonnormalised_mitochondria_concentration_ss=median(mito_mean);
                        app.compartment_data(app.window_profile_id).nonnormalised_mitochondria_total_ss=median(mito_total); 
                        app.compartment_data(app.window_profile_id).nonnormalised_cytoplasmic_concentration_ss=median(cyto_mean);
                        app.compartment_data(app.window_profile_id).nonnormalised_cytoplasmic_total_ss=median(cyto_total); 
                        app.compartment_data(app.window_profile_id).nonnormalised_nuclear_concentration_ss=median(nuc_mean);
                        app.compartment_data(app.window_profile_id).nonnormalised_nuclear_total_ss=median(nuc_total); 
                        
                        
                        d.Value =1;
                        close(d);
                        close(f);
                        
                        if app.window_profile_id == length(app.seed_points_total) 
                            app.app_stage =8;
                            intensity_plotting(app);
                            app.TabGroup.SelectedTab = app.InterpolationTab;
                            
                        else
                            app.window_profile_id = app.window_profile_id + 1;
                            app.seed_point_id = 1;
                            app.whole_cell_threshold_slider_flag=0;
                            app.population_cytoplasm=zeros(app.total_frames,1)+NaN;
                            app.population_mitochondria=zeros(app.total_frames,1)+NaN;
                            app.population_nucleus=zeros(app.total_frames,1)+NaN;
                            app.population_total=zeros(app.total_frames,1)+NaN;
                            cla(app.UIAxes7_3)
                            cla(app.UIAxes7)
                            cla(app.UIAxes7_2)
                            app.app_stage =3;
                            app.TabGroup.SelectedTab = app.CompartmentviewingpercentilesTab;
                        end
                    elseif app.seed_point_id < app.seed_points_total(app.window_profile_id)    
                   
                        app.seed_point_id = app.seed_point_id +1;
                        
                        app.whole_cell_threshold_slider_flag=0;
                        image_input1 = zeros(size(app.max_int_all_movie_ROI));
                        image_input1(:,:) = app.window_max_all_channels_ROI(:,:,app.seed_point_id);
                        image_input2 = zeros(size(app.max_int_all_movie_ROI));
                        image_input2(:,:) = app.window_max_protein_ROI(:,:,app.seed_point_id);
                        plot_imagesc(app,app.UIAxes6,image_input1)
                        plot_imagesc(app,app.UIAxes6_2,image_input2)
                        plot_imagesc(app,app.UIAxes6_3,app.max_int_all_movie_ROI)
                        plot_imagesc(app,app.UIAxes6_4,app.max_int_protein_all_movie_ROI)
                        app.app_stage = 5;
                        app.rough_cell_boundary_created = 0;
                        app.NullButton_2.Value = true;
                        cla(app.UIAxes5);
                        app.TabGroup.SelectedTab = app.CellboundarycropTab;
                    end
    
                end
            end
            
        end

        % Button pushed function: SavedataandquitButton
        function SavedataandquitButtonPushed(app, event)
            stage_error_counter = stage_error(app,app.app_stage,8,app.cropping_stage);
            if stage_error_counter == 0
        
                f = uifigure;
                d = uiprogressdlg(f,'Title','Saving and closing. Goodbye.','Indeterminate','on');

                name = 'opto_analyser_output.mat';
                [fnm,pth] = uiputfile(name);
                if ischar(fnm)
                    save_function(app,pth,fnm);
                    close(d);
                    close(f);
                    reset_function(app);
                    startupFcn(app);
                end

            end

        end

        % Button pushed function: ProceedtonormalisationButton
        function ProceedtonormalisationButtonPushed(app, event)
            stage_error_counter = stage_error(app,app.app_stage,8,app.cropping_stage);
            if stage_error_counter == 0
                app.app_stage = 9;
                plot_imagesc(app,app.UIAxes12,app.max_int_all_frames_ROI(:,:,1));
                app.FramenumberSlider.Limits = [1 app.total_frames];
                app.TabGroup.SelectedTab = app.BackgroundsubtractionTab;
            end

        end

        % Value changing function: FramenumberSlider
        function FramenumberSliderValueChanging(app, event)
            stage_error_counter = stage_error(app,app.app_stage,9,app.cropping_stage);
            if stage_error_counter == 0    
                changingValue = uint16(event.Value);
                plot_imagesc(app,app.UIAxes12,app.max_int_all_frames_ROI(:,:,changingValue));
            end
        end

        % Button pushed function: SelectBackgroundRegionButton
        function SelectBackgroundRegionButtonPushed(app, event)
            stage_error_counter = stage_error(app,app.app_stage,9,app.cropping_stage);
            if stage_error_counter == 0     
                image_input = app.max_int_protein_all_movie_ROI;
                image_input=double(image_input-min(image_input(:)));
                image_input=image_input./max(image_input(:));
                image_input=uint16(round((2^16-1)*image_input));
                rgbcell = ind2rgb(image_input, parula(2^16));
                                            
                background_image = figure;
                set(background_image,'name','Background location','numbertitle','off')
                imshow(rgbcell);
                iptsetpref('ImshowAxesVisible','off')
                axis('equal');
                app.cropping_stage = 3;
                app.background_ROI_created_flag = 1;
                while isvalid(background_image)
                    h = drawrectangle;
                    while isvalid(h)
                       BW = createMask(h);
                       delete(h);    
                    end
                    delete(background_image);
                end
    
                if exist('BW','var')
                    if sum(BW(:)) < 25
                        app = warning_compartment_area(app,5,1,1);
                    end
                    app.background_location = find(BW);
                    app.cropping_stage = 0;
                    app.background_ROI_created_flag = 1;
                else
                    app.cropping_stage = 0;
                    app.background_ROI_created_flag = 0;
                end
            end
        end

        % Button pushed function: AcceptBackgroundRegionButton
        function AcceptBackgroundRegionButtonPushed(app, event)
            stage_error_counter = stage_error(app,app.app_stage,9,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                if app.background_ROI_created_flag ==0
                    info_label = 'Error Messages';
                    lsm_type_error = [' Error. Select "Select Background Region"' newline 'in Background subtraction tab.'];
                    stage_error_counter = 1;
                    info_label = [info_label newline '(1)' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                else
                    app = error_message_reset(app);
                    if isempty(app.background_location)
                        app = warning_compartment_area(app,5,1,2);
                    else
                        for J = 1:length(app.total_frames)
                            single_protein_frame(:,:) = app.LSM_protein_movie_ROI(:,:,J);
                            app.background_mean(J) = mean(double(single_protein_frame(app.background_location)));
                        end
                        min_total = nanmin(app.compartment_data(1).interp_total_population);
                        max_total = nanmax(app.compartment_data(1).interp_total_population);
                        min_mito = nanmin(app.compartment_data(1).interp_mitochondria_population);
                        max_mito = nanmin(app.compartment_data(1).interp_mitochondria_population);
                        min_cyto = nanmin(app.compartment_data(1).interp_cytoplasm_population);
                        max_cyto = nanmin(app.compartment_data(1).interp_cytoplasm_population);
                        for J = 1:length(app.total_frames)
                            min_mito = min(min_mito,nanmin(app.compartment_data(J).interp_mitochondria_population));
                            max_mito = max(max_mito,nanmax(app.compartment_data(J).interp_mitochondria_population));
                            min_total = min(min_total,nanmin(app.compartment_data(J).interp_total_population));
                            max_total = max(max_total,nanmax(app.compartment_data(J).interp_total_population));
                            min_cyto = min(min_cyto,nanmin(app.compartment_data(J).interp_cytoplasm_population));
                            max_cyto = max(max_cyto,nanmax(app.compartment_data(J).interp_cytoplasm_population));
                        end
                        ten_percent_buffer=0.1*(max_total-min_total);
                        %Change limits to be plues minus 10%
                        ylim(app.UIAxes13_3,[min_total-ten_percent_buffer max_total+ten_percent_buffer]);
                        ten_percent_buffer=0.1*(max_mito-min_mito);
                        ylim(app.UIAxes13,[min_mito-ten_percent_buffer max_mito+ten_percent_buffer]);
                        ten_percent_buffer=0.1*(max_cyto-min_cyto);
                        ylim(app.UIAxes13_2,[min_cyto-ten_percent_buffer max_cyto+ten_percent_buffer]);
                        xlim(app.UIAxes13,[0 app.total_frames]);
                        xlim(app.UIAxes13_2,[0 app.total_frames]);
                        xlim(app.UIAxes13_3,[0 app.total_frames]);

                        app.app_stage = 10;
                        app = linear_bleach_region_plotting(app);
                        app.TabGroup.SelectedTab = app.NormalisationregionsTab;
                    end
                end
            end
      
        end

        % Value changed function: ReleasestartSlider
        function ReleasestartSliderValueChanged(app, event)
            stage_error_counter = stage_error(app,app.app_stage,10,app.cropping_stage);
            if stage_error_counter == 0
                value = min(round(app.ReleasestartSlider.Value),app.total_release_frames-1);
                app = error_message_reset(app);
                app.ReleasestartSlider.Value = value;
                if app.ReleaseendSlider.Value <= value
                   app.ReleaseendSlider.Value = value + 1;
                end
                app = linear_bleach_region_plotting(app);
                app.release_start_flag = 1;
            else
                event.Source.Value = event.Source.MajorTicks(1);
            end
        end

        % Value changed function: ReleaseendSlider
        function ReleaseendSliderValueChanged(app, event)
            stage_error_counter = stage_error(app,app.app_stage,10,app.cropping_stage);
            if stage_error_counter == 0
                value = max(round(app.ReleaseendSlider.Value),2);
                app = error_message_reset(app);
                app.ReleaseendSlider.Value = value;
                if app.ReleasestartSlider.Value >= value
                   app.ReleasestartSlider.Value = value - 1;
                end
                app = linear_bleach_region_plotting(app);
                app.release_end_flag = 1;
            else
                event.Source.Value = event.Source.MajorTicks(1);
            end 
            
        end

        % Value changed function: RecoverystartSlider
        function RecoverystartSliderValueChanged(app, event)
            stage_error_counter = stage_error(app,app.app_stage,10,app.cropping_stage);
            if stage_error_counter == 0
                value = min(round(app.RecoverystartSlider.Value),app.total_frames-1);
                app = error_message_reset(app);
                app.RecoverystartSlider.Value = value;
                if app.RecoveryendSlider.Value <= value
                   app.RecoveryendSlider.Value = value + 1;
                end
                app = linear_bleach_region_plotting(app);
                app.recovery_start_flag = 1;
            else
                event.Source.Value = event.Source.MajorTicks(1);
            end
        end

        % Value changed function: RecoveryendSlider
        function RecoveryendSliderValueChanged(app, event)
            stage_error_counter = stage_error(app,app.app_stage,10,app.cropping_stage);
            if stage_error_counter == 0
                value = max(round(app.RecoveryendSlider.Value),app.total_release_frames + 2);
                app = error_message_reset(app);
                app.RecoveryendSlider.Value = value;
                if app.RecoverystartSlider.Value >= value
                   app.RecoverystartSlider.Value = value - 1;
                end
                app = linear_bleach_region_plotting(app);
                app.recovery_end_flag = 1;
            else
                event.Source.Value = event.Source.MajorTicks(1);
            end
        end

        % Value changed function: NumberofwindowsSlider
        function NumberofwindowsSliderValueChanged(app, event)
            stage_error_counter = stage_error(app,app.app_stage,10,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                app.intensity_plot_window = round(app.NumberofwindowsSlider.Value);
                app.NumberofwindowsSlider.Value = app.intensity_plot_window;
                app = linear_bleach_region_plotting(app);
            else
                event.Source.Value = event.Source.MajorTicks(1);
            end
            
        end

        % Button pushed function: AcceptNormalisationRegionsButton
        function AcceptNormalisationRegionsButtonPushed(app, event)
            stage_error_counter = stage_error(app,app.app_stage,10,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                if app.release_start_flag == 0 && app.release_end_flag == 0 && app.recovery_start_flag == 0 && app.recovery_end_flag == 0
                    info_label = 'Error Messages';
                    lsm_type_error = [' Error. Select the linear fitting regions' newline 'avoiding regions of rapid intensity' newline 'change in Normalisation' newline 'regions tab.'];
                    stage_error_counter = 1;
                    info_label = [info_label newline '(1)' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                else
                    app = error_message_reset(app);
                    app.app_stage = 11;
                    min_mito = nanmin(app.compartment_data(1).interp_mitochondria_population);
                    max_mito = nanmin(app.compartment_data(1).interp_mitochondria_population);
                    min_cyto = nanmin(app.compartment_data(1).interp_cytoplasm_population);
                    max_cyto = nanmin(app.compartment_data(1).interp_cytoplasm_population);
                    for J = 1:length(app.total_frames)
                        min_mito = min(min_mito,nanmin(app.compartment_data(J).interp_mitochondria_population));
                        max_mito = max(max_mito,nanmax(app.compartment_data(J).interp_mitochondria_population));
                        min_cyto = min(min_cyto,nanmin(app.compartment_data(J).interp_cytoplasm_population));
                        max_cyto = max(max_cyto,nanmax(app.compartment_data(J).interp_cytoplasm_population));
                    end
                    ten_percent_buffer=0.1*(max_mito-min_mito);
                    ylim(app.UIAxes14,[min_mito-ten_percent_buffer max_mito+ten_percent_buffer]);
                    ten_percent_buffer=0.1*(max_cyto-min_cyto);
                    ylim(app.UIAxes14_2,[min_cyto-ten_percent_buffer max_cyto+ten_percent_buffer]);
                    xlim(app.UIAxes14,[0 app.total_frames]);
                    xlim(app.UIAxes14_2,[0 app.total_frames]);
                    app = steady_state_end_plotting(app);
                    app.TabGroup.SelectedTab = app.TimeandSSinputTab;
                end
            end
        end

        % Value changed function: NumberofwindowsSlider_2
        function NumberofwindowsSlider_2ValueChanged(app, event)
            stage_error_counter = stage_error(app,app.app_stage,11,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                app = steady_state_end_plotting(app);
            else
                event.Source.Value = event.Source.MajorTicks(1);
            end
        end

        % Value changed function: SteadystateendframeSpinner
        function SteadystateendframeSpinnerValueChanged(app, event)
            stage_error_counter = stage_error(app,app.app_stage,11,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                app = steady_state_end_plotting(app);
            else
                app.SteadystateendframeSpinner.Value = 1;
            end
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            stage_error_counter = stage_error(app,app.app_stage,11,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                app.input_flag = 1;
                if app.UseframesastimeButton.Value == true
                    app.time = 1:app.total_frames;
                else
                    [time_file,time_file_path] = uigetfile({'*.xlsx';'*.txt';'*.dat';'*.csv'});
                    time_file_full_path=[time_file_path time_file];
                    time_input = readmatrix(time_file_full_path);
                    [u,v] = size(time_input);
                    if min(u,v) ~=1 || max(u,v) ~= app.total_frames
                        lsm_type_error = ['Error. Input data should be a' newline '1 by ' num2str(app.total_frames) 'vector.' newline 'Re-select appropriate input file.'];
                        app.input_flag = 0;
                        app.error_counter = app.error_counter +1;
                        app.ErrorMessagesLabel.Text = [app.ErrorMessagesLabel.Text newline '(' num2str(app.error_counter) ')' lsm_type_error];
                        app.ErrorMessagesLabel.BackgroundColor = 'k';
                    end
                    if max(isnan(time_input)) == 1
                       lsm_type_error = ['Error. Input data should' newline 'include only numerical values.' newline 'Re-select appropriate input file.'];
                       app.input_flag = 0;
                       app.error_counter = app.error_counter +1;
                       app.ErrorMessagesLabel.Text = [app.ErrorMessagesLabel.Text newline '(' num2str(app.error_counter) ')' lsm_type_error];
                       app.ErrorMessagesLabel.BackgroundColor = 'k';
                    end
                    if app.input_flag == 1
                        if u>v
                            time_input = time_input';
                        end
                        app.time = time_input;
                    end
                end
                if app.input_flag == 1
                    linear_release_frames = app.release_linear_start:app.release_linear_end;
                    linear_recovery_frames = app.recovery_linear_start:app.recovery_linear_end;
                    all_frame_data=[linear_release_frames,linear_recovery_frames];
                    for J= 1:length(app.seed_points_total)
                        clear cell_size mitochondria_size nulceus_size
                        mitochondria_population = double(app.compartment_data(J).interp_mitochondria_population);
                        cytoplasm_population = double(app.compartment_data(J).interp_cytoplasm_population);
                        nucleus_population=double(app.compartment_data(J).interp_nucleus_population);
                        for I = 1:app.total_frames
                            mitochondria_bw(:,:) = app.compartment_data(J).mitochondria_interpolated_region_bw(:,:,I);
                            mitochondria_size(I) = double(sum(mitochondria_bw(:)));
                            nucleus_bw(:,:) = app.compartment_data(J).nucleus_interpolated_region_bw(:,:,I);
                            nucleus_size(I) = double(sum(nucleus_bw(:)));
                            cell_bw(:,:) = app.compartment_data(J).whole_cell_interpolated_region_bw(:,:,I);
                            cyto_bw=max(cell_bw-nucleus_bw-mitochondria_bw,0);
                            cytoplasm_size(I) = double(sum(cyto_bw(:)));
                        end
                        if app.IncludebackgroundsubtractionCheckBox.Value == true
                            mitochondria_population = mitochondria_population - mitochondria_size.*app.background_mean;
                            nucleus_population = nucleus_population - nucleus_size.*app.background_mean;
                            cytoplasm_population = cytoplasm_population - cytoplasm_size.*app.background_mean;
                            total_population = mitochondria_population + nucleus_population + cytoplasm_population;
                        else
                            mitochondria_population = mitochondria_population;
                            nucleus_population = nucleus_population;
                            cytoplasm_population = cytoplasm_population;
                            total_population = mitochondria_population + nucleus_population + cytoplasm_population;
                        end

                        app.compartment_data(J).mitochondria_background_subtracted=mitochondria_population;
                        app.compartment_data(J).cytoplasm_background_subtracted=cytoplasm_population;
                        app.compartment_data(J).nucleus_background_subtracted=nucleus_population;
                        app.compartment_data(J).total_background_subtracted=total_population;

                        linear_release_total = total_population(app.release_linear_start:app.release_linear_end);           
                        linear_recovery_total = total_population(app.recovery_linear_start:app.recovery_linear_end);
                        all_intensity_data=[linear_release_total,linear_recovery_total];
                        p = polyfit(app.time(all_frame_data),all_intensity_data,1);
                        single_linear_intensity = min(p(1),0).*app.time+p(2);%Doesn't allow positive gradient
                        app.compartment_data(J).single_linear_fit_total = single_linear_intensity;
                        app.compartment_data(J).mitochondria_norm_single_linear=mitochondria_population./single_linear_intensity;
                        app.compartment_data(J).cytoplasm_norm_single_linear=cytoplasm_population./single_linear_intensity;
                        app.compartment_data(J).nucleus_norm_single_linear=nucleus_population./single_linear_intensity;
                        app.compartment_data(J).total_norm_single_linear=total_population./single_linear_intensity;
                        
                        median_total_ss=median(total_population(1:app.steady_state_end));
                        p_release = polyfit(app.time(linear_release_frames),linear_release_total,1);
                        p_recovery = polyfit(app.time(linear_recovery_frames),linear_recovery_total,1);
                        p_release(1)=min(p_release(1),0);
                        p_recovery(1)=min(p_recovery(1),0);
                        if(app.time(app.steady_state_end)*p_release(1)+p_release(2)>median_total_ss)
                            release_linear_intercept=p_release(2);
                        else
                            release_linear_intercept=median_total_ss-app.time(app.steady_state_end)*p_release(1);
                        end
                        recovery_linear_intercept=app.time(app.total_release_frames+1)*(p_release(1)-p_recovery(1))+release_linear_intercept;
                        double_release=p_release(1)*app.time(1:app.total_release_frames)+release_linear_intercept;
                        double_recovery=p_recovery(1)*app.time(app.total_release_frames+1:app.total_frames)+recovery_linear_intercept;
                        double_linear_intensity=[double_release,double_recovery];
        
                        app.compartment_data(J).double_linear_fit_total=double_linear_intensity;
                        app.compartment_data(J).mitochondria_norm_double_linear=mitochondria_population./double_linear_intensity;
                        app.compartment_data(J).cytoplasm_norm_double_linear=cytoplasm_population./double_linear_intensity;
                        app.compartment_data(J).nucleus_norm_double_linear=nucleus_population./double_linear_intensity;
                        app.compartment_data(J).total_norm_double_linear=total_population./double_linear_intensity;
 
                    end


                    app = normalisation_plotting(app);
                    app.app_stage = 12;
                    app.TabGroup.SelectedTab = app.NormalisationplottingTab;
                end

            else
                app.UseframesastimeButton.Value = true;
            end

        end

        % Selection changed function: NormalisationButtonGroup_3
        function NormalisationButtonGroup_3SelectionChanged(app, event)
            stage_error_counter = stage_error(app,app.app_stage,12,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                app = normalisation_plotting(app);
            else
                app.SinglelinearButton.Value = true;
            end

        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            stage_error_counter = stage_error(app,app.app_stage,12,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                app = normalisation_plotting(app);
            else
                app.PrenormalisationButton.Value = true;
            end
        end

        % Callback function
        function ButtonGroup_2SelectionChanged(app, event)
            stage_error_counter = stage_error(app,app.app_stage,12,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                app = normalisation_plotting(app);
            end            
        end

        % Value changed function: NumberofwindowsSlider_3
        function NumberofwindowsSlider_3ValueChanged(app, event)
            stage_error_counter = stage_error(app,app.app_stage,12,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                app.normalisation_plot_windows = round(app.NumberofwindowsSlider_3.Value);
                app.NumberofwindowsSlider_3.Value = app.normalisation_plot_windows;
                app = normalisation_plotting(app);
            else
                event.Source.Value = event.Source.MajorTicks(1);
            end     

        end

        % Button pushed function: ProceedtomodelfitButton
        function ProceedtomodelfitButtonPushed(app, event)
            stage_error_counter = stage_error(app,app.app_stage,12,app.cropping_stage);
            if stage_error_counter == 0
                app.app_stage = 13;
                app.TabGroup.SelectedTab = app.ModelfittingTab;
            end
        end

        % Button pushed function: SaveandquitButton_2
        function SaveandquitButton_2Pushed(app, event)
            stage_error_counter = stage_error(app,app.app_stage,12,app.cropping_stage);
            if stage_error_counter == 0
        
                f = uifigure;
                d = uiprogressdlg(f,'Title','Saving and closing. Goodbye.','Indeterminate','on');

                name = 'opto_analyser_output.mat';
                %filter = {'*.mat';'*.*'};
                [fnm,pth] = uiputfile(name);
                if ischar(fnm)
                    save_function(app,pth,fnm);
                    close(d);
                    close(f);
                    reset_function(app);
                    startupFcn(app);
                end

            end
        
        end

        % Button pushed function: FitmodelButton
        function FitmodelButtonPushed(app, event)
            %app.model_fitting_flag=0;
            stage_error_counter = stage_error(app,app.app_stage,13,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                app.input_flag = 1;
                number_of_model_fits = length(app.seed_points_total)*2;
                model_fit_counter = 0;
                
                if app.IncludebackgroundsubtractionCheckBox.Value == true
                    bgd_label='yes';
                else
                    bgd_label='no';
                end
                
                if app.model_fitting_flag == 0
                    
                    app.k_off_lit_guess = zeros(number_of_model_fits,1) + app.LitoffrateEditField.Value;
                    app.k_off_unlit_guess = zeros(number_of_model_fits,1) + app.UnlitonrateEditField.Value;
                    app.k_on_lit_guess = zeros(number_of_model_fits,1) + app.LitonrateEditField.Value;
                    app.k_on_unlit_guess = zeros(number_of_model_fits,1) + app.UnlitoffrateEditField.Value;
                    app.k_imp_guess = zeros(number_of_model_fits,1) + app.ImportrateEditField.Value;
                    app.k_exp_guess = zeros(number_of_model_fits,1) + app.ExportrateEditField.Value;
                    fit_this_data=ones(number_of_model_fits,1);
                else
                    app.model_fitting_table_input=get(app.UITable2,'Data');
                    refit_include=app.model_fitting_table_input(:,1);
                    for I=1:number_of_model_fits
                        fit_this_data(I)=refit_include{I};
                        if fit_this_data(I) == 1
                            app.k_off_lit_guess(I) = app.LitoffrateEditField.Value;
                            app.k_off_unlit_guess(I) = app.UnlitoffrateEditField.Value;
                            app.k_on_lit_guess(I) = app.LitonrateEditField.Value;
                            app.k_on_unlit_guess(I) = app.UnlitonrateEditField.Value;
                            app.k_imp_guess(I) = app.ImportrateEditField.Value;
                            app.k_exp_guess(I) = app.ExportrateEditField.Value;
                        end
                    end
                end
                
                if max(isnan(app.k_off_lit_guess)) == 1 || max(isnan(app.k_off_unlit_guess)) == 1 ||max(isnan(app.k_on_lit_guess)) == 1 || max(isnan(app.k_on_unlit_guess)) == 1 || max(isnan(app.k_imp_guess)) == 1 || max(isnan(app.k_exp_guess)) == 1
                   lsm_type_error = ['Error. At least one parameter guess' newline 'is not a numeric value.'];
                   app.input_flag = 0;
                   app.error_counter = app.error_counter +1;
                   info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                   app.ErrorMessagesLabel.Text = info_label;
                   app.ErrorMessagesLabel.BackgroundColor = 'k';    
                end
                
                if app.input_flag ==1
                    app.UITable2.Data=[];
                    app.model_fitting_table_input =[];
                    app.model_fitting_table_background_colour=[];
                    app.UITable2.ForegroundColor=[0,0,0];
                    
                    f = uifigure;
                    d = uiprogressdlg(f,'Title','Please wait. Fitting data.');                
                    d.Value = 0;
                    time_nlinfit = repmat(app.time',3,1);
                                     
                    for J = 1:length(app.seed_points_total)
                        for I =1:2
                          
                            model_fit_counter = model_fit_counter+1;
                            if I == 1
                                mitochondria_model_input = app.compartment_data(J).mitochondria_norm_single_linear;
                                cytoplasm_model_input = app.compartment_data(J).cytoplasm_norm_single_linear;
                                nucleus_model_input = app.compartment_data(J).nucleus_norm_single_linear;
                            elseif I==2
                                mitochondria_model_input = app.compartment_data(J).mitochondria_norm_double_linear;
                                cytoplasm_model_input = app.compartment_data(J).cytoplasm_norm_double_linear;
                                nucleus_model_input = app.compartment_data(J).nucleus_norm_double_linear;
 
                            end
                            mitochondria_ss_guess = median(mitochondria_model_input(1:app.steady_state_end));
                            cytoplasm_ss_guess = median(cytoplasm_model_input(1:app.steady_state_end));
                            nucleus_ss_guess = median(nucleus_model_input(1:app.steady_state_end));
                            data_nlinfit=[mitochondria_model_input,cytoplasm_model_input,nucleus_model_input];
                            data_nlinfit=data_nlinfit';
                            weight_nlinfit=ones(3*app.total_frames,1);
                            weight_nlinfit(1:app.total_frames)=weight_nlinfit(1:app.total_frames)/(mitochondria_ss_guess);
                            weight_nlinfit(app.total_frames+1:2*app.total_frames)=weight_nlinfit(app.total_frames+1:2*app.total_frames)/(cytoplasm_ss_guess);
                            weight_nlinfit(2*app.total_frames+1:end)=2*weight_nlinfit(2*app.total_frames+1:end)/(nucleus_ss_guess);
                            if fit_this_data(model_fit_counter) == 1

                                parguess = [app.k_off_unlit_guess(model_fit_counter) app.k_on_unlit_guess(model_fit_counter) app.k_off_lit_guess(model_fit_counter) app.k_on_lit_guess(model_fit_counter) app.k_imp_guess(model_fit_counter) app.k_exp_guess(model_fit_counter) mitochondria_ss_guess];
                                opts = statset('MaxIter',100000,'TolX',1e-12,'TolFun',1e-12);
                                constants(2)=app.time(app.total_release_frames);
                                constants(1)=app.time(app.steady_state_end);
                                try
                                    mdl=fitnlm(time_nlinfit,data_nlinfit,@(beta,time_nlinfit)fitnlm_opto_analyser_odes(beta,constants,time_nlinfit),parguess,'Weights',weight_nlinfit,'options',opts);
                                    clear beta
                                    beta(1)=abs(mdl.Coefficients.Estimate(1));
                                    beta(2)=abs(mdl.Coefficients.Estimate(2));
                                    beta(3)=abs(mdl.Coefficients.Estimate(3));
                                    beta(4)=abs(mdl.Coefficients.Estimate(4));
                                    beta(5)=abs(mdl.Coefficients.Estimate(5));
                                    beta(6)=abs(mdl.Coefficients.Estimate(6));
                                    beta(7)=abs(mdl.Coefficients.Estimate(7));
                                    MSE=mdl.MSE;
                                    MAE=sum(weight_nlinfit.^0.5.*abs(mdl.Residuals.Raw))/length(mdl.Residuals.Raw);
                                catch ME
                                    ME.cause{1}
                                    beta = nan(1,7);
                                    MSE=nan;
                                    MAE=nan;
                                end
                                                               
                                [k_off_unlit_fitted,k_on_unlit_fitted,k_off_lit_fitted,k_on_lit_fitted,k_imp_fitted,k_exp_fitted,mitochondria_ss_fitted,cytoplasm_ss_fitted,nucleus_ss_fitted] = fitted_values(app,beta);
                                if I ==1
                                    app.compartment_data(J).single_k_off_unlit = k_off_unlit_fitted;
                                    app.compartment_data(J).single_k_on_unlit = k_on_unlit_fitted;
                                    app.compartment_data(J).single_k_off_lit = k_off_lit_fitted;
                                    app.compartment_data(J).single_k_on_lit = k_on_lit_fitted;
                                    app.compartment_data(J).single_k_imp = k_imp_fitted;
                                    app.compartment_data(J).single_k_exp = k_exp_fitted;
                                    app.compartment_data(J).single_mitochondria_ss = mitochondria_ss_fitted;
                                    app.compartment_data(J).single_cytoplasm_ss = cytoplasm_ss_fitted;
                                    app.compartment_data(J).single_nucleus_ss = nucleus_ss_fitted;
                                    app.compartment_data(J).single_whole_cell_MSE = MSE;
                                    app.compartment_data(J).single_whole_cell_MAE = MAE;
                                elseif I== 2
                                    app.compartment_data(J).double_k_off_unlit = k_off_unlit_fitted;
                                    app.compartment_data(J).double_k_on_unlit = k_on_unlit_fitted;
                                    app.compartment_data(J).double_k_off_lit = k_off_lit_fitted;
                                    app.compartment_data(J).double_k_on_lit = k_on_lit_fitted;
                                    app.compartment_data(J).double_k_imp = k_imp_fitted;
                                    app.compartment_data(J).double_k_exp = k_exp_fitted;
                                    app.compartment_data(J).double_mitochondria_ss = mitochondria_ss_fitted;
                                    app.compartment_data(J).double_cytoplasm_ss = cytoplasm_ss_fitted;
                                    app.compartment_data(J).double_nucleus_ss = nucleus_ss_fitted;
                                    app.compartment_data(J).double_whole_cell_MSE = MSE;
                                    app.compartment_data(J).double_whole_cell_MAE = MAE; 
                                end
                               
                                
                                if isnan(MSE)~=1
                                    output=fitnlm_opto_analyser_odes(beta,constants,time_nlinfit);
                                    mitochondria_time_interp = output(1:app.total_frames);
                                    cytoplasm_time_interp = output(app.total_frames+1:2*app.total_frames);
                                    nucleus_time_interp = output(2*app.total_frames+1:3*app.total_frames);
                                    SSE=weight_nlinfit.^0.5.*(output-data_nlinfit);
                                    SSE_m=SSE(1:app.total_frames);
                                    SSE_c=SSE(app.total_frames+1:2*app.total_frames);
                                    SSE_n=SSE(2*app.total_frames+1:3*app.total_frames);
                                    MSE_mitochondria = (SSE_m'*SSE_m)./(length(SSE_m)-length(beta));
                                    MSE_cytoplasm = (SSE_c'*SSE_c)./(length(SSE_c)-length(beta));
                                    MSE_nucleus = (SSE_n'*SSE_n)./(length(SSE_n)-length(beta));

                                    if I ==1
                                        app.compartment_data(J).single_model_fit_mitochondria_frames = mitochondria_time_interp;
                                        app.compartment_data(J).single_model_fit_cytoplasm_frames = cytoplasm_time_interp;
                                        app.compartment_data(J).single_model_fit_nucleus_frames = nucleus_time_interp;
                                        app.compartment_data(J).single_mitochondria_MSE = MSE_mitochondria;
                                        app.compartment_data(J).single_cytoplasm_MSE = MSE_cytoplasm;
                                        app.compartment_data(J).single_nucleus_MSE = MSE_nucleus;
                                    elseif I==2
                                        app.compartment_data(J).double_model_fit_mitochondria_frames = mitochondria_time_interp;
                                        app.compartment_data(J).double_model_fit_cytoplasm_frames = cytoplasm_time_interp;
                                        app.compartment_data(J).double_model_fit_nucleus_frames = nucleus_time_interp;
                                        app.compartment_data(J).double_mitochondria_MSE = MSE_mitochondria;
                                        app.compartment_data(J).double_cytoplasm_MSE = MSE_cytoplasm;
                                        app.compartment_data(J).double_nucleus_MSE = MSE_nucleus;                                       
                                    end
                                else
                                    
                                    if I ==1
                                        app.compartment_data(J).single_model_fit_mitochondria_frames = nan(app.total_frames,1);
                                        app.compartment_data(J).single_model_fit_cytoplasm_frames = nan(app.total_frames,1);
                                        app.compartment_data(J).single_model_fit_nucleus_frames = nan(app.total_frames,1);
                                        app.compartment_data(J).single_mitochondria_MSE = NaN;
                                        app.compartment_data(J).single_cytoplasm_MSE = NaN;
                                        app.compartment_data(J).single_nucleus_MSE = NaN;
                                    elseif I == 2
                                        app.compartment_data(J).double_model_fit_mitochondria_frames = nan(app.total_frames,1);
                                        app.compartment_data(J).double_model_fit_cytoplasm_frames = nan(app.total_frames,1);
                                        app.compartment_data(J).double_model_fit_nucleus_frames = nan(app.total_frames,1);
                                        app.compartment_data(J).double_mitochondria_MSE = NaN;
                                        app.compartment_data(J).double_cytoplasm_MSE = NaN;
                                        app.compartment_data(J).double_nucleus_MSE = NaN;                                        
                                    end                        
                                end
                            end
                            if I==1
                                input_data = {true app.seed_points_total(J) bgd_label 'single' app.k_off_lit_guess(model_fit_counter) app.k_off_unlit_guess(model_fit_counter) app.k_on_lit_guess(model_fit_counter) app.k_on_unlit_guess(model_fit_counter) app.k_imp_guess(model_fit_counter) app.k_exp_guess(model_fit_counter) mitochondria_ss_guess...
                                app.compartment_data(J).single_k_off_lit app.compartment_data(J).single_k_off_unlit app.compartment_data(J).single_k_on_lit app.compartment_data(J).single_k_on_unlit...
                                app.compartment_data(J).single_k_imp app.compartment_data(J).single_k_exp app.compartment_data(J).single_mitochondria_ss...
                                app.compartment_data(J).single_cytoplasm_ss app.compartment_data(J).single_nucleus_ss app.compartment_data(J).single_whole_cell_MSE...
                                app.compartment_data(J).single_nucleus_MSE app.compartment_data(J).single_cytoplasm_MSE app.compartment_data(J).single_mitochondria_MSE app.compartment_data(J).single_whole_cell_MAE...
                                app.compartment_data(J).cell_area app.compartment_data(J).cell_perimeter app.compartment_data(J).cell_eccentricity...
                                app.compartment_data(J).cell_circularity app.compartment_data(J).nuclear_area app.compartment_data(J).nuclear_perimeter...
                                app.compartment_data(J).nuclear_eccentricity app.compartment_data(J).nuclear_circularity app.compartment_data(J).nuc_cell_area_proportion...                       
                                app.compartment_data(J).mito_cell_area_proportion app.compartment_data(J).nonnormalised_cytoplasmic_concentration_ss app.compartment_data(J).nonnormalised_cytoplasmic_total_ss... 
                                app.compartment_data(J).nonnormalised_nuclear_concentration_ss app.compartment_data(J).nonnormalised_nuclear_total_ss app.compartment_data(J).nonnormalised_mitochondria_concentration_ss...
                                app.compartment_data(J).nonnormalised_mitochondria_total_ss};
                                if isnan(app.compartment_data(J).single_whole_cell_MSE)==0
                                    app.model_fitting_table_background_colour = [app.model_fitting_table_background_colour;[0 0.7 0]];
                                else
                                    app.model_fitting_table_background_colour = [app.model_fitting_table_background_colour;[0.7 0 0]];
                                end
                            elseif I == 2
                                input_data = {true app.seed_points_total(J) bgd_label 'double' app.k_off_lit_guess(model_fit_counter) app.k_off_unlit_guess(model_fit_counter) app.k_on_lit_guess(model_fit_counter) app.k_on_unlit_guess(model_fit_counter) app.k_imp_guess(model_fit_counter) app.k_exp_guess(model_fit_counter) mitochondria_ss_guess...
                                app.compartment_data(J).double_k_off_lit app.compartment_data(J).double_k_off_unlit app.compartment_data(J).double_k_on_lit app.compartment_data(J).double_k_on_unlit...
                                app.compartment_data(J).double_k_imp app.compartment_data(J).double_k_exp app.compartment_data(J).double_mitochondria_ss...
                                app.compartment_data(J).double_cytoplasm_ss app.compartment_data(J).double_nucleus_ss app.compartment_data(J).double_whole_cell_MSE...
                                app.compartment_data(J).double_nucleus_MSE app.compartment_data(J).double_cytoplasm_MSE app.compartment_data(J).double_mitochondria_MSE app.compartment_data(J).double_whole_cell_MAE...
                                app.compartment_data(J).cell_area app.compartment_data(J).cell_perimeter app.compartment_data(J).cell_eccentricity...
                                app.compartment_data(J).cell_circularity app.compartment_data(J).nuclear_area app.compartment_data(J).nuclear_perimeter...
                                app.compartment_data(J).nuclear_eccentricity app.compartment_data(J).nuclear_circularity app.compartment_data(J).nuc_cell_area_proportion...                      
                                app.compartment_data(J).mito_cell_area_proportion app.compartment_data(J).nonnormalised_cytoplasmic_concentration_ss app.compartment_data(J).nonnormalised_cytoplasmic_total_ss... 
                                app.compartment_data(J).nonnormalised_nuclear_concentration_ss app.compartment_data(J).nonnormalised_nuclear_total_ss app.compartment_data(J).nonnormalised_mitochondria_concentration_ss...
                                app.compartment_data(J).nonnormalised_mitochondria_total_ss};
                                if isnan(app.compartment_data(J).double_whole_cell_MSE)==0
                                    app.model_fitting_table_background_colour = [app.model_fitting_table_background_colour;[0 0.7 0]];
                                else
                                    app.model_fitting_table_background_colour = [app.model_fitting_table_background_colour;[0.7 0 0]];
                                end
                            end

                            app.UITable2.Data = [app.model_fitting_table_input ; input_data];
                            app.model_fitting_table_input  = app.UITable2.Data;
                            app.UITable2.BackgroundColor=app.model_fitting_table_background_colour;%[1 1 1 ;0.85 0.85 1];
                            d.Value = model_fit_counter/number_of_model_fits;                            
                            
                        end
                    end
                    app.model_fitting_flag = 1;
                    close(d)
                    close(f) 
                    app = model_fitting_plotting(app);                    
                end

            end
               

        end

        % Selection changed function: NormalisationButtonGroup_4
        function NormalisationButtonGroup_4SelectionChanged(app, event)
            stage_error_counter = stage_error(app,app.app_stage,13,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                if app.model_fitting_flag == 1
                    app = model_fitting_plotting(app);
                else
                    lsm_type_error = ['Error. Carry out model fitting' newline 'in Model fitting tab' newline 'prior to visualisation.'];
                    app.input_flag = 0;
                    app.error_counter = app.error_counter +1;
                    app.ErrorMessagesLabel.Text = [app.ErrorMessagesLabel.Text newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                    app.SinglelinearButton_3.Value = true;
                end
                
            else
                app.SinglelinearButton_3.Value = true;
            end
        end

        % Value changed function: NumberofwindowsSlider_5
        function NumberofwindowsSlider_5ValueChanged(app, event)
            stage_error_counter = stage_error(app,app.app_stage,13,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                if app.model_fitting_flag == 1
                    app = model_fitting_plotting(app);
                else
                    lsm_type_error = ['Error. Carry out model fitting' newline 'in Model fitting tab' newline 'prior to visualisation.'];
                    app.input_flag = 0;
                    app.error_counter = app.error_counter +1;
                    app.ErrorMessagesLabel.Text = [app.ErrorMessagesLabel.Text newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                    event.Source.Value = event.Source.MajorTicks(1);
                end
                
            else
                event.Source.Value = event.Source.MajorTicks(1);
            end
        end

        % Cell edit callback: UITable2
        function UITable2CellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            if  isnan(newData) == 1 
                app.UITable2.Data = app.model_fitting_table_input;
                lsm_type_error = ['Warning! Value must be numeric. ' newline 'Reverted to previous value.'];
                app.error_counter = app.error_counter +1;
                app.ErrorMessagesLabel.Text = [app.ErrorMessagesLabel.Text newline '(' num2str(app.error_counter) ')' lsm_type_error];
                app.ErrorMessagesLabel.BackgroundColor = 'y';

            else
                app = error_message_reset(app);
                app.model_fitting_table_input=app.UITable2.Data;
            end
        end

        % Button pushed function: SaveandquitButton_3
        function SaveandquitButton_3Pushed(app, event)
            
            stage_error_counter = stage_error(app,app.app_stage,13,app.cropping_stage);
            if stage_error_counter == 0
                f = uifigure;
                d = uiprogressdlg(f,'Title','Saving and closing. Goodbye.','Indeterminate','on');
                name = 'opto_analyser_output.mat';
                [fnm1,pth1] = uiputfile(name);
                if ischar(fnm1)
                    save_function(app,pth1,fnm1);
                end
                Data=app.model_fitting_table_input;
                Data=get(app.UITable2,'Data');
                ColumnName=get(app.UITable2,'ColumnName');
                CombData=[ColumnName';Data];
                name = 'opto_analyser_parameter_fits.csv';
                [fnm2,pth2] = uiputfile(name);
                if ischar(fnm2)
                    writecell(CombData,[pth2 fnm2])
                end
                if ischar(fnm1) && ischar(fnm2)
                    close(d);
                    close(f);
                    reset_function(app);
                    startupFcn(app);                        
                end
            end

        end

        % Button pushed function: ResetGuessesButton
        function ResetGuessesButtonPushed(app, event)
            app.LitoffrateEditField.Value=0.1;
            app.UnlitoffrateEditField.Value=0.02;
            app.LitonrateEditField.Value=0.03;
            app.UnlitonrateEditField.Value=0.02;
            app.ImportrateEditField.Value=0.005;
            app.ExportrateEditField.Value=0.005;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.opto_analyserUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {567, 567, 567};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {567, 567};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {224, '1x', 414};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.opto_analyserUIFigure = uifigure('Visible', 'off');
            app.opto_analyserUIFigure.Name = 'opto_analyser';
            app.opto_analyserUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.opto_analyserUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ImageIOLabel = uilabel(app.LeftPanel);
            app.ImageIOLabel.Text = 'Image I/O';
            app.NoofseedpointsLabel = uilabel(app.LeftPanel);
            app.NoofseedpointsLabel.Text = 'No. of seed points';
            app.ConfirmSelectionLabel = uilabel(app.LeftPanel);
            app.ConfirmSelectionLabel.Text = 'Confirm Selection';
            app.ErrorMessagesLabel = uilabel(app.LeftPanel);
            app.ErrorMessagesLabel.Text = 'Error Messages';
            app.TextArea = uitextarea(app.LeftPanel);
            app.SeedpointsselectedLabel = uilabel(app.LeftPanel);
            app.SeedpointsselectedLabel.Text = 'Seedpoints selected: ';
            app.LoadReleaseDataButton = uibutton(app.LeftPanel, 'push');
            app.LoadReleaseDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadReleaseDataButtonPushed, true);
            app.LoadReleaseDataButton.Text = 'Load Release Data';
            app.LoadRecoveryDataButton = uibutton(app.LeftPanel, 'push');
            app.LoadRecoveryDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadRecoveryDataButtonPushed, true);
            app.LoadRecoveryDataButton.Text = 'Load Recovery Data';
            app.OKButton = uibutton(app.LeftPanel, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.RESETButton = uibutton(app.LeftPanel, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.CenterPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.CenterPanel);
            app.ChannelselectionTab = uitab(app.TabGroup);
            app.ChannelselectionTab.Title = 'Channel selection';
            app.Channel1Label = uilabel(app.ChannelselectionTab);
            app.Channel1Label.Text = '  Channel 1';
            app.FrameSliderLabel = uilabel(app.ChannelselectionTab);
            app.FrameSliderLabel.Text = 'Frame';
            app.FrameSlider = uislider(app.ChannelselectionTab);
            app.FrameSlider.Limits = [1 100];
            app.FrameSlider.ValueChangingFcn = createCallbackFcn(app, @FrameSliderValueChanging, true);
            app.FrameSlider.Value = 1;
            app.Channel1ButtonGroup = uibuttongroup(app.ChannelselectionTab);
            app.Channel1ButtonGroup.Title = 'Channel 1';
            app.NoneButton_5 = uiradiobutton(app.Channel1ButtonGroup);
            app.NoneButton_5.Text = 'None';
            app.NoneButton_5.Value = true;
            app.MitochondriaButton = uiradiobutton(app.Channel1ButtonGroup);
            app.MitochondriaButton.Text = 'Mitochondria';
            app.ProteinButton = uiradiobutton(app.Channel1ButtonGroup);
            app.ProteinButton.Text = 'Protein';
            app.NucleusButton = uiradiobutton(app.Channel1ButtonGroup);
            app.NucleusButton.Text = 'Nucleus';
            app.Channel2ButtonGroup = uibuttongroup(app.ChannelselectionTab);
            app.Channel2ButtonGroup.Title = 'Channel 2';
            app.NoneButton_6 = uiradiobutton(app.Channel2ButtonGroup);
            app.NoneButton_6.Text = 'None';
            app.NoneButton_6.Value = true;
            app.MitochondriaButton_2 = uiradiobutton(app.Channel2ButtonGroup);
            app.MitochondriaButton_2.Text = 'Mitochondria';
            app.ProteinButton_2 = uiradiobutton(app.Channel2ButtonGroup);
            app.ProteinButton_2.Text = 'Protein';
            app.NucleusButton_2 = uiradiobutton(app.Channel2ButtonGroup);
            app.NucleusButton_2.Text = 'Nucleus';
            app.Channel3ButtonGroup = uibuttongroup(app.ChannelselectionTab);
            app.Channel3ButtonGroup.Title = 'Channel 3';
            app.NoneButton_7 = uiradiobutton(app.Channel3ButtonGroup);
            app.NoneButton_7.Text = 'None';
            app.NoneButton_7.Value = true;
            app.MitochondriaButton_3 = uiradiobutton(app.Channel3ButtonGroup);
            app.MitochondriaButton_3.Text = 'Mitochondria';
            app.ProteinButton_3 = uiradiobutton(app.Channel3ButtonGroup);
            app.ProteinButton_3.Text = 'Protein';
            app.NucleusButton_3 = uiradiobutton(app.Channel3ButtonGroup);
            app.NucleusButton_3.Text = 'Nucleus';
            app.Channel4ButtonGroup = uibuttongroup(app.ChannelselectionTab);
            app.Channel4ButtonGroup.Title = 'Channel 4';
            app.NoneButton_8 = uiradiobutton(app.Channel4ButtonGroup);
            app.NoneButton_8.Text = 'None';
            app.NoneButton_8.Value = true;
            app.MitochondriaButton_4 = uiradiobutton(app.Channel4ButtonGroup);
            app.MitochondriaButton_4.Text = 'Mitochondria';
            app.ProteinButton_4 = uiradiobutton(app.Channel4ButtonGroup);
            app.ProteinButton_4.Text = 'Protein';
            app.NucleusButton_4 = uiradiobutton(app.Channel4ButtonGroup);
            app.NucleusButton_4.Text = 'Nucleus';
            app.UIAxes = uiaxes(app.ChannelselectionTab);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.ChannelselectionTab);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes3 = uiaxes(app.ChannelselectionTab);
            title(app.UIAxes3, '')
            xlabel(app.UIAxes3, '')
            ylabel(app.UIAxes3, '')
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.UIAxes4 = uiaxes(app.ChannelselectionTab);
            title(app.UIAxes4, '')
            xlabel(app.UIAxes4, '')
            ylabel(app.UIAxes4, '')
            app.UIAxes4.XTick = [];
            app.UIAxes4.YTick = [];
            app.Channel2Label = uilabel(app.ChannelselectionTab);
            app.Channel2Label.Text = '  Channel 2';
            app.Channel3Label = uilabel(app.ChannelselectionTab);
            app.Channel3Label.Text = '  Channel 3';
            app.Channel4Label = uilabel(app.ChannelselectionTab);
            app.Channel4Label.Text = '  Channel 4';
            app.AcceptChannelsandCropImageButton = uibutton(app.ChannelselectionTab, 'push');
            app.AcceptChannelsandCropImageButton.ButtonPushedFcn = createCallbackFcn(app, @AcceptChannelsandCropImageButtonPushed, true);
            app.AcceptChannelsandCropImageButton.Text = 'Accept Channels and Crop Image';
            app.AcceptCropButton = uibutton(app.ChannelselectionTab, 'push');
            app.AcceptCropButton.ButtonPushedFcn = createCallbackFcn(app, @AcceptCropButtonPushed, true);
            app.AcceptCropButton.Text = 'Accept Crop';
            app.CompartmentviewingpercentilesTab = uitab(app.TabGroup);
            app.CompartmentviewingpercentilesTab.Title = 'Compartment viewing percentiles';
            app.UIAxes7 = uiaxes(app.CompartmentviewingpercentilesTab);
            title(app.UIAxes7, '')
            xlabel(app.UIAxes7, '')
            ylabel(app.UIAxes7, '')
            app.UIAxes7.XTick = [];
            app.UIAxes7.YTick = [];
            app.UIAxes7_2 = uiaxes(app.CompartmentviewingpercentilesTab);
            title(app.UIAxes7_2, '')
            xlabel(app.UIAxes7_2, '')
            ylabel(app.UIAxes7_2, '')
            app.UIAxes7_2.XTick = [];
            app.UIAxes7_2.YTick = [];
            app.PercentileSlider_2Label = uilabel(app.CompartmentviewingpercentilesTab);
            app.PercentileSlider_2Label.Text = 'Percentile';
            app.PercentileSlider_2 = uislider(app.CompartmentviewingpercentilesTab);
            app.PercentileSlider_2.ValueChangingFcn = createCallbackFcn(app, @PercentileSlider_2ValueChanging, true);
            app.PercentileSlider_3Label = uilabel(app.CompartmentviewingpercentilesTab);
            app.PercentileSlider_3Label.Text = 'Percentile';
            app.PercentileSlider_3 = uislider(app.CompartmentviewingpercentilesTab);
            app.PercentileSlider_3.ValueChangingFcn = createCallbackFcn(app, @PercentileSlider_3ValueChanging, true);
            app.NucleusLabel = uilabel(app.CompartmentviewingpercentilesTab);
            app.NucleusLabel.Text = 'Nucleus';
            app.MitochondriaLabel = uilabel(app.CompartmentviewingpercentilesTab);
            app.MitochondriaLabel.Text = 'Mitochondria';
            app.SeedpointSlider_2Label = uilabel(app.CompartmentviewingpercentilesTab);
            app.SeedpointSlider_2Label.Text = 'Seed point';
            app.SeedpointSlider_2 = uislider(app.CompartmentviewingpercentilesTab);
            app.SeedpointSlider_2.Limits = [1 10];
            app.SeedpointSlider_2.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.SeedpointSlider_2.ValueChangedFcn = createCallbackFcn(app, @SeedpointSlider_2ValueChanged, true);
            app.SeedpointSlider_2.MinorTicks = [];
            app.SeedpointSlider_2.Value = 1;
            app.SeedpointSlider_3Label = uilabel(app.CompartmentviewingpercentilesTab);
            app.SeedpointSlider_3Label.Text = 'Seed point';
            app.SeedpointSlider_3 = uislider(app.CompartmentviewingpercentilesTab);
            app.SeedpointSlider_3.Limits = [1 10];
            app.SeedpointSlider_3.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.SeedpointSlider_3.ValueChangedFcn = createCallbackFcn(app, @SeedpointSlider_3ValueChanged, true);
            app.SeedpointSlider_3.MinorTicks = [];
            app.SeedpointSlider_3.Value = 1;
            app.UIAxes7_3 = uiaxes(app.CompartmentviewingpercentilesTab);
            title(app.UIAxes7_3, '')
            xlabel(app.UIAxes7_3, '')
            ylabel(app.UIAxes7_3, '')
            app.UIAxes7_3.XTick = [];
            app.UIAxes7_3.YTick = [];
            app.PercentileSliderLabel = uilabel(app.CompartmentviewingpercentilesTab);
            app.PercentileSliderLabel.Text = 'Percentile';
            app.PercentileSlider = uislider(app.CompartmentviewingpercentilesTab);
            app.PercentileSlider.ValueChangingFcn = createCallbackFcn(app, @PercentileSliderValueChanging, true);
            app.SeedpointSliderLabel = uilabel(app.CompartmentviewingpercentilesTab);
            app.SeedpointSliderLabel.Text = 'Seed point';
            app.SeedpointSlider = uislider(app.CompartmentviewingpercentilesTab);
            app.SeedpointSlider.Limits = [1 10];
            app.SeedpointSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.SeedpointSlider.ValueChangedFcn = createCallbackFcn(app, @SeedpointSliderValueChanged, true);
            app.SeedpointSlider.MinorTicks = [];
            app.SeedpointSlider.Value = 1;
            app.AllcompartmentsLabel = uilabel(app.CompartmentviewingpercentilesTab);
            app.AllcompartmentsLabel.Text = 'All compartments';
            app.LoadNextWindowProfileButton = uibutton(app.CompartmentviewingpercentilesTab, 'push');
            app.LoadNextWindowProfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadNextWindowProfileButtonPushed, true);
            app.LoadNextWindowProfileButton.Text = 'Load Next Window Profile';
            app.AcceptPercentilesButton = uibutton(app.CompartmentviewingpercentilesTab, 'push');
            app.AcceptPercentilesButton.ButtonPushedFcn = createCallbackFcn(app, @AcceptPercentilesButtonPushed, true);
            app.AcceptPercentilesButton.Text = 'Accept Percentiles';
            app.CellboundarycropTab = uitab(app.TabGroup);
            app.CellboundarycropTab.Title = 'Cell boundary crop';
            app.UIAxes5 = uiaxes(app.CellboundarycropTab);
            title(app.UIAxes5, '')
            xlabel(app.UIAxes5, '')
            ylabel(app.UIAxes5, '')
            app.UIAxes5.XTick = [];
            app.UIAxes5.YTick = [];
            app.UIAxes6 = uiaxes(app.CellboundarycropTab);
            title(app.UIAxes6, '')
            xlabel(app.UIAxes6, '')
            ylabel(app.UIAxes6, '')
            app.UIAxes6.XTick = [];
            app.UIAxes6.YTick = [];
            app.UIAxes6_2 = uiaxes(app.CellboundarycropTab);
            title(app.UIAxes6_2, '')
            xlabel(app.UIAxes6_2, '')
            ylabel(app.UIAxes6_2, '')
            app.UIAxes6_2.XTick = [];
            app.UIAxes6_2.YTick = [];
            app.UIAxes6_3 = uiaxes(app.CellboundarycropTab);
            title(app.UIAxes6_3, '')
            xlabel(app.UIAxes6_3, '')
            ylabel(app.UIAxes6_3, '')
            app.UIAxes6_3.XTick = [];
            app.UIAxes6_3.YTick = [];
            app.ImageSelectionButtonGroup = uibuttongroup(app.CellboundarycropTab);
            app.ImageSelectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ImageSelectionButtonGroupSelectionChanged, true);
            app.ImageSelectionButtonGroup.Title = 'Image Selection';
            app.NullButton_2 = uitogglebutton(app.ImageSelectionButtonGroup);
            app.NullButton_2.Text = 'Null';
            app.NullButton_2.Value = true;
            app.Image1Button = uitogglebutton(app.ImageSelectionButtonGroup);
            app.Image1Button.Text = 'Image 1';
            app.Image2Button = uitogglebutton(app.ImageSelectionButtonGroup);
            app.Image2Button.Text = 'Image 2';
            app.Image3Button = uitogglebutton(app.ImageSelectionButtonGroup);
            app.Image3Button.Text = 'Image 3';
            app.Image4Button = uitogglebutton(app.ImageSelectionButtonGroup);
            app.Image4Button.Text = 'Image 4';
            app.UIAxes6_4 = uiaxes(app.CellboundarycropTab);
            title(app.UIAxes6_4, '')
            xlabel(app.UIAxes6_4, '')
            ylabel(app.UIAxes6_4, '')
            app.UIAxes6_4.XTick = [];
            app.UIAxes6_4.YTick = [];
            app.AllframesLabel = uilabel(app.CellboundarycropTab);
            app.AllframesLabel.Text = 'All frames';
            app.RegionframesLabel = uilabel(app.CellboundarycropTab);
            app.RegionframesLabel.Text = 'Region frames';
            app.MaxintensityallchannelsLabel = uilabel(app.CellboundarycropTab);
            app.MaxintensityallchannelsLabel.Text = {'Max intensity '; '- all channels'};
            app.MaxintensityallchannelsLabel_2 = uilabel(app.CellboundarycropTab);
            app.MaxintensityallchannelsLabel_2.Text = {'Max intensity '; '- all channels'};
            app.MaxintensityproteinchannelLabel = uilabel(app.CellboundarycropTab);
            app.MaxintensityproteinchannelLabel.Text = {'Max intensity '; '- protein channel'};
            app.MaxintensityproteinchannelLabel_2 = uilabel(app.CellboundarycropTab);
            app.MaxintensityproteinchannelLabel_2.Text = {'Max intensity '; '- protein channel'};
            app.RoughCropButton = uibutton(app.CellboundarycropTab, 'push');
            app.RoughCropButton.ButtonPushedFcn = createCallbackFcn(app, @RoughCropButtonPushed, true);
            app.RoughCropButton.Text = 'Rough Crop';
            app.ConfirmRoughCropButton = uibutton(app.CellboundarycropTab, 'push');
            app.ConfirmRoughCropButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmRoughCropButtonPushed, true);
            app.ConfirmRoughCropButton.Text = {'Confirm Rough'; 'Crop'};
            app.CellthresholdingTab = uitab(app.TabGroup);
            app.CellthresholdingTab.Title = 'Cell thresholding';
            app.UIAxes8 = uiaxes(app.CellthresholdingTab);
            title(app.UIAxes8, '')
            xlabel(app.UIAxes8, '')
            ylabel(app.UIAxes8, '')
            app.UIAxes8.XTick = [];
            app.UIAxes8.YTick = [];
            app.UIAxes8_2 = uiaxes(app.CellthresholdingTab);
            title(app.UIAxes8_2, '')
            xlabel(app.UIAxes8_2, '')
            ylabel(app.UIAxes8_2, '')
            app.UIAxes8_2.XTick = [];
            app.UIAxes8_2.YTick = [];
            app.UIAxes8_3 = uiaxes(app.CellthresholdingTab);
            title(app.UIAxes8_3, '')
            xlabel(app.UIAxes8_3, '')
            ylabel(app.UIAxes8_3, '')
            app.UIAxes8_3.XTick = [];
            app.UIAxes8_3.YTick = [];
            app.Thresholdscale01SliderLabel = uilabel(app.CellthresholdingTab);
            app.Thresholdscale01SliderLabel.Text = 'Threshold scale 0.1';
            app.Thresholdscale01Slider = uislider(app.CellthresholdingTab);
            app.Thresholdscale01Slider.Limits = [0 0.9];
            app.Thresholdscale01Slider.MajorTicks = [0 0.3 0.6 0.9];
            app.Thresholdscale01Slider.ValueChangedFcn = createCallbackFcn(app, @Thresholdscale01SliderValueChanged, true);
            app.Thresholdscale01Slider.MinorTicks = [0.1 0.2 0.3 0.4 0.5 0.7 0.8];
            app.Thresholdscale001SliderLabel = uilabel(app.CellthresholdingTab);
            app.Thresholdscale001SliderLabel.Text = 'Threshold scale 0.01';
            app.Thresholdscale001Slider = uislider(app.CellthresholdingTab);
            app.Thresholdscale001Slider.Limits = [0 0.09];
            app.Thresholdscale001Slider.MajorTicks = [0 0.03 0.06 0.09];
            app.Thresholdscale001Slider.ValueChangedFcn = createCallbackFcn(app, @Thresholdscale001SliderValueChanged, true);
            app.Thresholdscale001Slider.MinorTicks = [0.01 0.02 0.04 0.05 0.07 0.08];
            app.Thresholdscale0001SliderLabel = uilabel(app.CellthresholdingTab);
            app.Thresholdscale0001SliderLabel.Text = 'Threshold scale 0.001';
            app.Thresholdscale0001Slider = uislider(app.CellthresholdingTab);
            app.Thresholdscale0001Slider.Limits = [0 0.009];
            app.Thresholdscale0001Slider.MajorTicks = [0 0.003 0.006 0.009];
            app.Thresholdscale0001Slider.MajorTickLabels = {'0', '0.003', '0.006', '0.009', ''};
            app.Thresholdscale0001Slider.ValueChangedFcn = createCallbackFcn(app, @Thresholdscale0001SliderValueChanged, true);
            app.Thresholdscale0001Slider.MinorTicks = [0.001 0.002 0.004 0.005 0.007 0.008];
            app.Thresholdscale00001SliderLabel = uilabel(app.CellthresholdingTab);
            app.Thresholdscale00001SliderLabel.Text = 'Threshold scale 0.0001';
            app.Thresholdscale00001Slider = uislider(app.CellthresholdingTab);
            app.Thresholdscale00001Slider.Limits = [0 0.0009];
            app.Thresholdscale00001Slider.MajorTicks = [0 0.0003 0.0006 0.0009];
            app.Thresholdscale00001Slider.MajorTickLabels = {'0', '0.0003', '0.0006', '0.0009', ''};
            app.Thresholdscale00001Slider.ValueChangedFcn = createCallbackFcn(app, @Thresholdscale00001SliderValueChanged, true);
            app.Thresholdscale00001Slider.MinorTicks = [0.0001 0.0002 0.0004 0.0005 0.0007 0.0008];
            app.Thresholdvalue000000Label = uilabel(app.CellthresholdingTab);
            app.Thresholdvalue000000Label.Text = {'Threshold value:'; '0.00000'};
            app.AcceptthresholdButton = uibutton(app.CellthresholdingTab, 'push');
            app.AcceptthresholdButton.ButtonPushedFcn = createCallbackFcn(app, @AcceptthresholdButtonPushed, true);
            app.AcceptthresholdButton.Text = 'Accept threshold';
            app.CompartmentthresholdingTab = uitab(app.TabGroup);
            app.CompartmentthresholdingTab.Title = 'Compartment thresholding';
            app.UIAxes9 = uiaxes(app.CompartmentthresholdingTab);
            title(app.UIAxes9, '')
            xlabel(app.UIAxes9, '')
            ylabel(app.UIAxes9, '')
            app.UIAxes9.XTick = [];
            app.UIAxes9.YTick = [];
            app.UIAxes10 = uiaxes(app.CompartmentthresholdingTab);
            title(app.UIAxes10, '')
            xlabel(app.UIAxes10, '')
            ylabel(app.UIAxes10, '')
            app.UIAxes10.XTick = [];
            app.UIAxes10.YTick = [];
            app.UIAxes10_2 = uiaxes(app.CompartmentthresholdingTab);
            title(app.UIAxes10_2, '')
            xlabel(app.UIAxes10_2, '')
            ylabel(app.UIAxes10_2, '')
            app.UIAxes10_2.XTick = [];
            app.UIAxes10_2.YTick = [];
            app.UIAxes10_3 = uiaxes(app.CompartmentthresholdingTab);
            title(app.UIAxes10_3, '')
            xlabel(app.UIAxes10_3, '')
            ylabel(app.UIAxes10_3, '')
            app.UIAxes10_3.XTick = [];
            app.UIAxes10_3.YTick = [];
            app.UIAxes10_4 = uiaxes(app.CompartmentthresholdingTab);
            title(app.UIAxes10_4, '')
            xlabel(app.UIAxes10_4, '')
            ylabel(app.UIAxes10_4, '')
            app.UIAxes10_4.XTick = [];
            app.UIAxes10_4.YTick = [];
            app.UIAxes10_5 = uiaxes(app.CompartmentthresholdingTab);
            title(app.UIAxes10_5, '')
            xlabel(app.UIAxes10_5, '')
            ylabel(app.UIAxes10_5, '')
            app.UIAxes10_5.XTick = [];
            app.UIAxes10_5.YTick = [];
            app.UIAxes10_6 = uiaxes(app.CompartmentthresholdingTab);
            title(app.UIAxes10_6, '')
            xlabel(app.UIAxes10_6, '')
            ylabel(app.UIAxes10_6, '')
            app.UIAxes10_6.XTick = [];
            app.UIAxes10_6.YTick = [];
            app.Mitochondriathresholdscale01SliderLabel = uilabel(app.CompartmentthresholdingTab);
            app.Mitochondriathresholdscale01SliderLabel.Text = {'Mitochondria'; 'threshold scale 0.1'};
            app.Mitochondriathresholdscale01Slider = uislider(app.CompartmentthresholdingTab);
            app.Mitochondriathresholdscale01Slider.Limits = [0 0.9];
            app.Mitochondriathresholdscale01Slider.MajorTicks = [0 0.3 0.6 0.9];
            app.Mitochondriathresholdscale01Slider.ValueChangedFcn = createCallbackFcn(app, @Mitochondriathresholdscale01SliderValueChanged, true);
            app.Mitochondriathresholdscale01Slider.MinorTicks = [0.1 0.2 0.4 0.5 0.7 0.8];
            app.Mitochondriathresholdscale001SliderLabel = uilabel(app.CompartmentthresholdingTab);
            app.Mitochondriathresholdscale001SliderLabel.Text = {'Mitochondria '; 'threshold scale 0.01'};
            app.Mitochondriathresholdscale001Slider = uislider(app.CompartmentthresholdingTab);
            app.Mitochondriathresholdscale001Slider.Limits = [0 0.09];
            app.Mitochondriathresholdscale001Slider.MajorTicks = [0 0.03 0.06 0.09];
            app.Mitochondriathresholdscale001Slider.ValueChangedFcn = createCallbackFcn(app, @Mitochondriathresholdscale001SliderValueChanged, true);
            app.Mitochondriathresholdscale001Slider.MinorTicks = [0.01 0.02 0.04 0.05 0.07 0.08];
            app.Mitochondriathresholdscale0001SliderLabel = uilabel(app.CompartmentthresholdingTab);
            app.Mitochondriathresholdscale0001SliderLabel.Text = {'Mitochondria'; 'threshold scale 0.001'};
            app.Mitochondriathresholdscale0001Slider = uislider(app.CompartmentthresholdingTab);
            app.Mitochondriathresholdscale0001Slider.Limits = [0 0.009];
            app.Mitochondriathresholdscale0001Slider.MajorTicks = [0 0.003 0.006 0.009];
            app.Mitochondriathresholdscale0001Slider.ValueChangedFcn = createCallbackFcn(app, @Mitochondriathresholdscale0001SliderValueChanged, true);
            app.Mitochondriathresholdscale0001Slider.MinorTicks = [0.001 0.002 0.004 0.005 0.007 0.008];
            app.Mitochondriathresholdscale00001SliderLabel = uilabel(app.CompartmentthresholdingTab);
            app.Mitochondriathresholdscale00001SliderLabel.Text = {'Mitochondria'; 'threshold scale 0.0001'};
            app.Mitochondriathresholdscale00001Slider = uislider(app.CompartmentthresholdingTab);
            app.Mitochondriathresholdscale00001Slider.Limits = [0 0.0009];
            app.Mitochondriathresholdscale00001Slider.MajorTicks = [0 0.0003 0.0006 0.0009];
            app.Mitochondriathresholdscale00001Slider.ValueChangedFcn = createCallbackFcn(app, @Mitochondriathresholdscale00001SliderValueChanged, true);
            app.Mitochondriathresholdscale00001Slider.MinorTicks = [0.0001 0.0002 0.0004 0.0005 0.0007 0.0008];
            app.Nucleusthresholdscale01SliderLabel = uilabel(app.CompartmentthresholdingTab);
            app.Nucleusthresholdscale01SliderLabel.Text = {'Nucleus'; 'threshold scale 0.1'};
            app.Nucleusthresholdscale01Slider = uislider(app.CompartmentthresholdingTab);
            app.Nucleusthresholdscale01Slider.Limits = [0 0.9];
            app.Nucleusthresholdscale01Slider.MajorTicks = [0 0.3 0.6 0.9];
            app.Nucleusthresholdscale01Slider.ValueChangedFcn = createCallbackFcn(app, @Nucleusthresholdscale01SliderValueChanged, true);
            app.Nucleusthresholdscale01Slider.MinorTicks = [0.1 0.2 0.4 0.5 0.7 0.8];
            app.Nucleusthresholdscale001SliderLabel = uilabel(app.CompartmentthresholdingTab);
            app.Nucleusthresholdscale001SliderLabel.Text = {'Nucleus'; 'threshold scale 0.01'};
            app.Nucleusthresholdscale001Slider = uislider(app.CompartmentthresholdingTab);
            app.Nucleusthresholdscale001Slider.Limits = [0 0.09];
            app.Nucleusthresholdscale001Slider.MajorTicks = [0 0.03 0.06 0.09];
            app.Nucleusthresholdscale001Slider.ValueChangedFcn = createCallbackFcn(app, @Nucleusthresholdscale001SliderValueChanged, true);
            app.Nucleusthresholdscale001Slider.MinorTicks = [0.01 0.02 0.04 0.05 0.07 0.08];
            app.Nucleusthresholdscale0001SliderLabel = uilabel(app.CompartmentthresholdingTab);
            app.Nucleusthresholdscale0001SliderLabel.Text = {'Nucleus'; 'threshold scale 0.001'};
            app.Nucleusthresholdscale0001Slider = uislider(app.CompartmentthresholdingTab);
            app.Nucleusthresholdscale0001Slider.Limits = [0 0.009];
            app.Nucleusthresholdscale0001Slider.MajorTicks = [0 0.003 0.006 0.009];
            app.Nucleusthresholdscale0001Slider.ValueChangedFcn = createCallbackFcn(app, @Nucleusthresholdscale0001SliderValueChanged, true);
            app.Nucleusthresholdscale0001Slider.MinorTicks = [0.001 0.002 0.004 0.005 0.007 0.008];
            app.Nucleusthresholdscale00001SliderLabel = uilabel(app.CompartmentthresholdingTab);
            app.Nucleusthresholdscale00001SliderLabel.Text = {'Nucleus '; 'threshold scale 0.0001'};
            app.Nucleusthresholdscale00001Slider = uislider(app.CompartmentthresholdingTab);
            app.Nucleusthresholdscale00001Slider.Limits = [0 0.0009];
            app.Nucleusthresholdscale00001Slider.MajorTicks = [0 0.0003 0.0006 0.0009];
            app.Nucleusthresholdscale00001Slider.ValueChangedFcn = createCallbackFcn(app, @Nucleusthresholdscale00001SliderValueChanged, true);
            app.Nucleusthresholdscale00001Slider.MinorTicks = [0.0001 0.0002 0.0004 0.0005 0.0007 0.0008];
            app.Mitochondrialthresholdvalue000000Label = uilabel(app.CompartmentthresholdingTab);
            app.Mitochondrialthresholdvalue000000Label.Text = {'Mitochondrial threshold value: 0.00000'; 'Nucleus threshold value: 0.00000'};
            app.AcceptthresholdsButton = uibutton(app.CompartmentthresholdingTab, 'push');
            app.AcceptthresholdsButton.ButtonPushedFcn = createCallbackFcn(app, @AcceptthresholdsButtonPushed, true);
            app.AcceptthresholdsButton.Text = 'Accept thresholds';
            app.InterpolationTab = uitab(app.TabGroup);
            app.InterpolationTab.Title = 'Interpolation';
            app.InterpolatedOutputLabel = uilabel(app.InterpolationTab);
            app.InterpolatedOutputLabel.Text = 'Interpolated Output';
            app.UIAxes11_5 = uiaxes(app.InterpolationTab);
            title(app.UIAxes11_5, 'Mitochondria')
            xlabel(app.UIAxes11_5, 'Frame number')
            ylabel(app.UIAxes11_5, 'Intensity')
            app.UIAxes11_5.XLim = [0 100];
            app.UIAxes11_5.YLim = [0 2];
            app.UIAxes11_5.XTick = [0 50 100];
            app.UIAxes11_6 = uiaxes(app.InterpolationTab);
            title(app.UIAxes11_6, 'Cytoplasm')
            xlabel(app.UIAxes11_6, 'Frame number')
            ylabel(app.UIAxes11_6, 'Intensity')
            app.UIAxes11_6.XLim = [0 100];
            app.UIAxes11_6.YLim = [0 2];
            app.UIAxes11_6.XTick = [0 50 100];
            app.UIAxes11_7 = uiaxes(app.InterpolationTab);
            title(app.UIAxes11_7, 'Nucleus')
            xlabel(app.UIAxes11_7, 'Frame number')
            ylabel(app.UIAxes11_7, 'Intensity')
            app.UIAxes11_7.XLim = [0 100];
            app.UIAxes11_7.YLim = [0 2];
            app.UIAxes11_7.XTick = [0 50 100];
            app.UIAxes11_8 = uiaxes(app.InterpolationTab);
            title(app.UIAxes11_8, 'Total')
            xlabel(app.UIAxes11_8, 'Frame number')
            ylabel(app.UIAxes11_8, 'Intensity')
            app.UIAxes11_8.XLim = [0 100];
            app.UIAxes11_8.YLim = [0 2];
            app.UIAxes11_8.XTick = [0 50 100];
            app.SavedataandquitButton = uibutton(app.InterpolationTab, 'push');
            app.SavedataandquitButton.ButtonPushedFcn = createCallbackFcn(app, @SavedataandquitButtonPushed, true);
            app.SavedataandquitButton.Text = 'Save data and quit';
            app.ProceedtonormalisationButton = uibutton(app.InterpolationTab, 'push');
            app.ProceedtonormalisationButton.ButtonPushedFcn = createCallbackFcn(app, @ProceedtonormalisationButtonPushed, true);
            app.ProceedtonormalisationButton.Text = 'Proceed to normalisation';
            app.BackgroundsubtractionTab = uitab(app.TabGroup);
            app.BackgroundsubtractionTab.Title = 'Background subtraction';
            app.UIAxes12 = uiaxes(app.BackgroundsubtractionTab);
            title(app.UIAxes12, '')
            xlabel(app.UIAxes12, '')
            ylabel(app.UIAxes12, '')
            app.UIAxes12.XTick = [];
            app.UIAxes12.YTick = [];
            app.ProteinchannelLabel = uilabel(app.BackgroundsubtractionTab);
            app.ProteinchannelLabel.Text = 'Protein channel';
            app.FramenumberSliderLabel = uilabel(app.BackgroundsubtractionTab);
            app.FramenumberSliderLabel.Text = 'Frame number';
            app.FramenumberSlider = uislider(app.BackgroundsubtractionTab);
            app.FramenumberSlider.ValueChangingFcn = createCallbackFcn(app, @FramenumberSliderValueChanging, true);
            app.SelectBackgroundRegionButton = uibutton(app.BackgroundsubtractionTab, 'push');
            app.SelectBackgroundRegionButton.ButtonPushedFcn = createCallbackFcn(app, @SelectBackgroundRegionButtonPushed, true);
            app.SelectBackgroundRegionButton.Text = 'Select Background Region';
            app.AcceptBackgroundRegionButton = uibutton(app.BackgroundsubtractionTab, 'push');
            app.AcceptBackgroundRegionButton.ButtonPushedFcn = createCallbackFcn(app, @AcceptBackgroundRegionButtonPushed, true);
            app.AcceptBackgroundRegionButton.Text = 'Accept Background Region';
            app.IncludebackgroundsubtractionCheckBox = uicheckbox(app.BackgroundsubtractionTab);
            app.IncludebackgroundsubtractionCheckBox.Text = 'Include background subtraction';
            app.IncludebackgroundsubtractionCheckBox.Value = true;
            app.NormalisationregionsTab = uitab(app.TabGroup);
            app.NormalisationregionsTab.Title = 'Normalisation regions';
            app.UIAxes13 = uiaxes(app.NormalisationregionsTab);
            title(app.UIAxes13, 'Mitochondria')
            xlabel(app.UIAxes13, 'Frame number')
            ylabel(app.UIAxes13, 'Intensity')
            app.UIAxes13.XLim = [0 100];
            app.UIAxes13.YLim = [0 2];
            app.UIAxes13_2 = uiaxes(app.NormalisationregionsTab);
            title(app.UIAxes13_2, 'Cytoplasm')
            xlabel(app.UIAxes13_2, 'Frame number')
            ylabel(app.UIAxes13_2, 'Intensity')
            app.UIAxes13_2.XLim = [0 100];
            app.UIAxes13_2.YLim = [0 2];
            app.ReleasestartLabel = uilabel(app.NormalisationregionsTab);
            app.ReleasestartLabel.Text = {'Release '; 'start'};
            app.ReleasestartSlider = uislider(app.NormalisationregionsTab);
            app.ReleasestartSlider.Limits = [1 100];
            app.ReleasestartSlider.ValueChangedFcn = createCallbackFcn(app, @ReleasestartSliderValueChanged, true);
            app.ReleasestartSlider.Value = 1;
            app.ReleaseendLabel = uilabel(app.NormalisationregionsTab);
            app.ReleaseendLabel.Text = {'Release '; 'end'};
            app.ReleaseendSlider = uislider(app.NormalisationregionsTab);
            app.ReleaseendSlider.Limits = [1 100];
            app.ReleaseendSlider.ValueChangedFcn = createCallbackFcn(app, @ReleaseendSliderValueChanged, true);
            app.ReleaseendSlider.Value = 100;
            app.RecoverystartLabel = uilabel(app.NormalisationregionsTab);
            app.RecoverystartLabel.Text = {'Recovery '; 'start'};
            app.RecoverystartSlider = uislider(app.NormalisationregionsTab);
            app.RecoverystartSlider.Limits = [101 200];
            app.RecoverystartSlider.ValueChangedFcn = createCallbackFcn(app, @RecoverystartSliderValueChanged, true);
            app.RecoverystartSlider.Value = 101;
            app.RecoveryendLabel = uilabel(app.NormalisationregionsTab);
            app.RecoveryendLabel.Text = {'Recovery '; 'end'};
            app.RecoveryendSlider = uislider(app.NormalisationregionsTab);
            app.RecoveryendSlider.Limits = [101 200];
            app.RecoveryendSlider.ValueChangedFcn = createCallbackFcn(app, @RecoveryendSliderValueChanged, true);
            app.RecoveryendSlider.Value = 101;
            app.AcceptNormalisationRegionsButton = uibutton(app.NormalisationregionsTab, 'push');
            app.AcceptNormalisationRegionsButton.ButtonPushedFcn = createCallbackFcn(app, @AcceptNormalisationRegionsButtonPushed, true);
            app.AcceptNormalisationRegionsButton.Text = 'Accept Normalisation Regions';
            app.NumberofwindowsLabel = uilabel(app.NormalisationregionsTab);
            app.NumberofwindowsLabel.Text = 'Number of windows';
            app.NumberofwindowsSlider = uislider(app.NormalisationregionsTab);
            app.NumberofwindowsSlider.Limits = [1 10];
            app.NumberofwindowsSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.NumberofwindowsSlider.ValueChangedFcn = createCallbackFcn(app, @NumberofwindowsSliderValueChanged, true);
            app.NumberofwindowsSlider.MinorTicks = [];
            app.NumberofwindowsSlider.Value = 1;
            app.UIAxes13_3 = uiaxes(app.NormalisationregionsTab);
            title(app.UIAxes13_3, 'Total')
            xlabel(app.UIAxes13_3, 'Frame number')
            ylabel(app.UIAxes13_3, 'Intensity')
            app.UIAxes13_3.XLim = [0 100];
            app.UIAxes13_3.YLim = [0 2];
            app.TimeandSSinputTab = uitab(app.TabGroup);
            app.TimeandSSinputTab.Title = 'Time and SS input';
            app.UIAxes14 = uiaxes(app.TimeandSSinputTab);
            title(app.UIAxes14, 'Mitochondria')
            xlabel(app.UIAxes14, 'Frame number')
            ylabel(app.UIAxes14, 'Intensity')
            app.UIAxes14.XLim = [0 100];
            app.UIAxes14.YLim = [0 2];
            app.SteadystateendframeSpinnerLabel = uilabel(app.TimeandSSinputTab);
            app.SteadystateendframeSpinnerLabel.Text = 'Steady-state end frame';
            app.SteadystateendframeSpinner = uispinner(app.TimeandSSinputTab);
            app.SteadystateendframeSpinner.Limits = [1 Inf];
            app.SteadystateendframeSpinner.ValueChangedFcn = createCallbackFcn(app, @SteadystateendframeSpinnerValueChanged, true);
            app.SteadystateendframeSpinner.Value = 1;
            app.TimeinformationButtonGroup = uibuttongroup(app.TimeandSSinputTab);
            app.TimeinformationButtonGroup.Title = 'Time information';
            app.UseframesastimeButton = uitogglebutton(app.TimeinformationButtonGroup);
            app.UseframesastimeButton.Text = 'Use frames as time';
            app.UseframesastimeButton.Value = true;
            app.LoadtimedataButton = uitogglebutton(app.TimeinformationButtonGroup);
            app.LoadtimedataButton.Text = 'Load time data';
            app.ConfirmButton = uibutton(app.TimeandSSinputTab, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.UIAxes14_2 = uiaxes(app.TimeandSSinputTab);
            title(app.UIAxes14_2, 'Cytoplasm')
            xlabel(app.UIAxes14_2, 'Frame number')
            ylabel(app.UIAxes14_2, 'Intensity')
            app.UIAxes14_2.XLim = [0 100];
            app.UIAxes14_2.YLim = [0 2];
            app.NumberofwindowsSlider_2Label = uilabel(app.TimeandSSinputTab);
            app.NumberofwindowsSlider_2Label.Text = 'Number of windows';
            app.NumberofwindowsSlider_2 = uislider(app.TimeandSSinputTab);
            app.NumberofwindowsSlider_2.Limits = [1 10];
            app.NumberofwindowsSlider_2.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.NumberofwindowsSlider_2.ValueChangedFcn = createCallbackFcn(app, @NumberofwindowsSlider_2ValueChanged, true);
            app.NumberofwindowsSlider_2.MinorTicks = [];
            app.NumberofwindowsSlider_2.Value = 1;
            app.NormalisationplottingTab = uitab(app.TabGroup);
            app.NormalisationplottingTab.Title = 'Normalisation plotting';
            app.UIAxes15 = uiaxes(app.NormalisationplottingTab);
            title(app.UIAxes15, 'Mitochondria')
            xlabel(app.UIAxes15, 'Time')
            ylabel(app.UIAxes15, 'Intensity')
            app.UIAxes15.XLim = [0 100];
            app.UIAxes15.YLim = [0 2];
            app.UIAxes15_2 = uiaxes(app.NormalisationplottingTab);
            title(app.UIAxes15_2, 'Cytoplasm')
            xlabel(app.UIAxes15_2, 'Time')
            ylabel(app.UIAxes15_2, 'Intensity')
            app.UIAxes15_2.XLim = [0 100];
            app.UIAxes15_2.YLim = [0 2];
            app.UIAxes15_3 = uiaxes(app.NormalisationplottingTab);
            title(app.UIAxes15_3, 'Nucleus')
            xlabel(app.UIAxes15_3, 'Time')
            ylabel(app.UIAxes15_3, 'Intensity')
            app.UIAxes15_3.XLim = [0 100];
            app.UIAxes15_3.YLim = [0 2];
            app.UIAxes15_4 = uiaxes(app.NormalisationplottingTab);
            title(app.UIAxes15_4, 'Total')
            xlabel(app.UIAxes15_4, 'Time')
            ylabel(app.UIAxes15_4, 'Intensity')
            app.UIAxes15_4.XLim = [0 100];
            app.UIAxes15_4.YLim = [0 2];
            app.NormalisationButtonGroup_3 = uibuttongroup(app.NormalisationplottingTab);
            app.NormalisationButtonGroup_3.SelectionChangedFcn = createCallbackFcn(app, @NormalisationButtonGroup_3SelectionChanged, true);
            app.NormalisationButtonGroup_3.Title = 'Normalisation';
            app.SinglelinearButton = uiradiobutton(app.NormalisationButtonGroup_3);
            app.SinglelinearButton.Text = 'Single linear';
            app.SinglelinearButton.Value = true;
            app.DoublelinearButton = uiradiobutton(app.NormalisationButtonGroup_3);
            app.DoublelinearButton.Text = 'Double linear';
            app.NumberofwindowsSlider_3Label = uilabel(app.NormalisationplottingTab);
            app.NumberofwindowsSlider_3Label.Text = {'Number of '; 'windows'};
            app.NumberofwindowsSlider_3 = uislider(app.NormalisationplottingTab);
            app.NumberofwindowsSlider_3.Limits = [1 10];
            app.NumberofwindowsSlider_3.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.NumberofwindowsSlider_3.ValueChangedFcn = createCallbackFcn(app, @NumberofwindowsSlider_3ValueChanged, true);
            app.NumberofwindowsSlider_3.MinorTicks = [];
            app.NumberofwindowsSlider_3.Value = 1;
            app.ProceedtomodelfitButton = uibutton(app.NormalisationplottingTab, 'push');
            app.ProceedtomodelfitButton.ButtonPushedFcn = createCallbackFcn(app, @ProceedtomodelfitButtonPushed, true);
            app.ProceedtomodelfitButton.Text = 'Proceed to model fit';
            app.ButtonGroup = uibuttongroup(app.NormalisationplottingTab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.PrenormalisationButton = uiradiobutton(app.ButtonGroup);
            app.PrenormalisationButton.Text = 'Pre-normalisation';
            app.PrenormalisationButton.Value = true;
            app.PostnormalisationButton = uiradiobutton(app.ButtonGroup);
            app.PostnormalisationButton.Text = 'Post-normalisation';
            app.SaveandquitButton_2 = uibutton(app.NormalisationplottingTab, 'push');
            app.SaveandquitButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveandquitButton_2Pushed, true);
            app.SaveandquitButton_2.Text = 'Save and quit';
            app.ModelfittingTab = uitab(app.TabGroup);
            app.ModelfittingTab.Title = 'Model fitting';
            app.LitoffrateEditFieldLabel = uilabel(app.ModelfittingTab);
            app.LitoffrateEditFieldLabel.Text = 'Lit off rate';
            app.LitoffrateEditField = uieditfield(app.ModelfittingTab, 'numeric');
            app.LitoffrateEditField.Limits = [0 Inf];
            app.LitoffrateEditField.Value = 0.1;
            app.UnlitoffrateEditFieldLabel = uilabel(app.ModelfittingTab);
            app.UnlitoffrateEditFieldLabel.Text = 'Unlit off rate';
            app.UnlitoffrateEditField = uieditfield(app.ModelfittingTab, 'numeric');
            app.UnlitoffrateEditField.Limits = [0 Inf];
            app.UnlitoffrateEditField.Value = 0.02;
            app.LitonrateEditFieldLabel = uilabel(app.ModelfittingTab);
            app.LitonrateEditFieldLabel.Text = 'Lit on rate';
            app.LitonrateEditField = uieditfield(app.ModelfittingTab, 'numeric');
            app.LitonrateEditField.Limits = [0 Inf];
            app.LitonrateEditField.Value = 0.03;
            app.ImportrateEditFieldLabel = uilabel(app.ModelfittingTab);
            app.ImportrateEditFieldLabel.Text = 'Import rate';
            app.ImportrateEditField = uieditfield(app.ModelfittingTab, 'numeric');
            app.ImportrateEditField.Limits = [0 Inf];
            app.ImportrateEditField.Value = 0.005;
            app.ExportrateEditFieldLabel = uilabel(app.ModelfittingTab);
            app.ExportrateEditFieldLabel.Text = 'Export rate';
            app.ExportrateEditField = uieditfield(app.ModelfittingTab, 'numeric');
            app.ExportrateEditField.Limits = [0 Inf];
            app.ExportrateEditField.Value = 0.005;
            app.UITable2 = uitable(app.ModelfittingTab);
            app.UITable2.ColumnName = {'Re-fit'; 'No. windows'; 'Background subtraction'; 'Normalisation'; 'GUESS: Lit off rate'; 'GUESS: Unlit off rate'; 'GUESS: Lit on rate'; 'GUESS: Unlit on rate'; 'GUESS: Import rate'; 'GUESS: Export rate'; 'GUESS: Mitochondria SS'; 'FITTED: Lit off rate'; 'FITTED: Unlit off rate'; 'FITTED: Lit on rate'; 'FITTED: Unlit on rate'; 'FITTED: Import rate'; 'FITTED: Export rate'; 'FITTED: Mitochondria SS'; 'FITTED: Cytoplasm SS'; 'FITTED: Nucleus SS'; 'Whole cell MSE'; 'Nucleus MSE'; 'Cytoplasm MSE'; 'Mitochondria MSE'; 'Whole Cell MAE'; 'Cell Area'; 'Cell Perimeter'; 'Cell Eccentricity'; 'Cell Circularity'; 'Nuclear Area'; 'Nuclear Perimeter'; 'Nuclear Eccentricity'; 'Nuclear Circularity'; 'Nuclear area proportion of cell'; 'Mitochondrial area proportion of cell'; 'Raw non-normalised cytoplasmic concentration'; 'Raw non-normalised cytoplasmic total population'; 'Raw non-normalised nuclear concentration'; 'Raw non-normalised nuclear total population'; 'Raw non-normalised mitochondrial concentration'; 'Raw non-normalised mitochondrial total population'};
            app.UITable2.RowName = {};
            app.UITable2.ColumnEditable = [true false false false false false false false false false false false false false false false false false false false false false false false false false false false false false false false false false false false false false];
            app.UITable2.CellEditCallback = createCallbackFcn(app, @UITable2CellEdit, true);
            app.FitmodelButton = uibutton(app.ModelfittingTab, 'push');
            app.FitmodelButton.ButtonPushedFcn = createCallbackFcn(app, @FitmodelButtonPushed, true);
            app.FitmodelButton.Text = 'Fit model';
            app.SaveandquitButton_3 = uibutton(app.ModelfittingTab, 'push');
            app.SaveandquitButton_3.ButtonPushedFcn = createCallbackFcn(app, @SaveandquitButton_3Pushed, true);
            app.SaveandquitButton_3.Text = 'Save and quit';
            app.UnlitonrateEditFieldLabel = uilabel(app.ModelfittingTab);
            app.UnlitonrateEditFieldLabel.Text = 'Unlit on rate';
            app.UnlitonrateEditField = uieditfield(app.ModelfittingTab, 'numeric');
            app.UnlitonrateEditField.Limits = [0 Inf];
            app.UnlitonrateEditField.Value = 0.02;
            app.ResetGuessesButton = uibutton(app.ModelfittingTab, 'push');
            app.ResetGuessesButton.ButtonPushedFcn = createCallbackFcn(app, @ResetGuessesButtonPushed, true);
            app.ResetGuessesButton.Text = 'Reset Guesses';
            app.ModelfittingoutputTab = uitab(app.TabGroup);
            app.ModelfittingoutputTab.Title = 'Model fitting output';
            app.UIAxes17 = uiaxes(app.ModelfittingoutputTab);
            title(app.UIAxes17, 'Mitochondria')
            xlabel(app.UIAxes17, 'Time')
            ylabel(app.UIAxes17, 'Intensity')
            app.UIAxes17.XLim = [0 100];
            app.UIAxes17.YLim = [0 2];
            app.UIAxes17_2 = uiaxes(app.ModelfittingoutputTab);
            title(app.UIAxes17_2, 'Cytoplasm')
            xlabel(app.UIAxes17_2, 'Time')
            ylabel(app.UIAxes17_2, 'Intensity')
            app.UIAxes17_2.XLim = [0 100];
            app.UIAxes17_2.YLim = [0 2];
            app.UIAxes17_3 = uiaxes(app.ModelfittingoutputTab);
            title(app.UIAxes17_3, 'Nucleus')
            xlabel(app.UIAxes17_3, 'Time')
            ylabel(app.UIAxes17_3, 'Intensity')
            app.UIAxes17_3.XLim = [0 100];
            app.UIAxes17_3.YLim = [0 2];
            app.NormalisationButtonGroup_4 = uibuttongroup(app.ModelfittingoutputTab);
            app.NormalisationButtonGroup_4.SelectionChangedFcn = createCallbackFcn(app, @NormalisationButtonGroup_4SelectionChanged, true);
            app.NormalisationButtonGroup_4.Title = 'Normalisation';
            app.SinglelinearButton_3 = uiradiobutton(app.NormalisationButtonGroup_4);
            app.SinglelinearButton_3.Text = 'Single linear';
            app.SinglelinearButton_3.Value = true;
            app.DoublelinearButton_3 = uiradiobutton(app.NormalisationButtonGroup_4);
            app.DoublelinearButton_3.Text = 'Double linear';
            app.NumberofwindowsLabel_2 = uilabel(app.ModelfittingoutputTab);
            app.NumberofwindowsLabel_2.Text = 'Number of windows';
            app.NumberofwindowsSlider_5 = uislider(app.ModelfittingoutputTab);
            app.NumberofwindowsSlider_5.Limits = [1 10];
            app.NumberofwindowsSlider_5.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.NumberofwindowsSlider_5.ValueChangedFcn = createCallbackFcn(app, @NumberofwindowsSlider_5ValueChanged, true);
            app.NumberofwindowsSlider_5.MinorTicks = [];
            app.NumberofwindowsSlider_5.Value = 1;
            app.RightPanel = uipanel(app.GridLayout);
            app.OutputLabel = uilabel(app.RightPanel);
            app.OutputLabel.Text = 'Output';
            app.UIAxes11 = uiaxes(app.RightPanel);
            title(app.UIAxes11, 'Mitochondria')
            xlabel(app.UIAxes11, 'Frame number')
            ylabel(app.UIAxes11, 'Intensity')
            app.UIAxes11.XLim = [0 100];
            app.UIAxes11.YLim = [0 2];
            app.UIAxes11.XTick = [0 50 100];
            app.UIAxes11_2 = uiaxes(app.RightPanel);
            title(app.UIAxes11_2, 'Cytoplasm')
            xlabel(app.UIAxes11_2, 'Frame number')
            ylabel(app.UIAxes11_2, 'Intensity')
            app.UIAxes11_2.XLim = [0 100];
            app.UIAxes11_2.YLim = [0 2];
            app.UIAxes11_2.XTick = [0 50 100];
            app.UIAxes11_3 = uiaxes(app.RightPanel);
            title(app.UIAxes11_3, 'Nucleus')
            xlabel(app.UIAxes11_3, 'Frame number')
            ylabel(app.UIAxes11_3, 'Intensity')
            app.UIAxes11_3.XLim = [0 100];
            app.UIAxes11_3.YLim = [0 2];
            app.UIAxes11_3.XTick = [0 50 100];
            app.UIAxes11_4 = uiaxes(app.RightPanel);
            title(app.UIAxes11_4, 'Total')
            xlabel(app.UIAxes11_4, 'Frame number')
            ylabel(app.UIAxes11_4, 'Intensity')
            app.UIAxes11_4.XLim = [0 100];
            app.UIAxes11_4.YLim = [0 2];
            app.UIAxes11_4.XTick = [0 50 100];
            app.UITable = uitable(app.RightPanel);
            app.UITable.ColumnName = {'No. of intervals'; 'Interval'; 'Cell percentile'; 'Mitochondria percentile'; 'Nucleus Percentile'; 'Cell threshold'; 'Mitochondria threshold'; 'Nucleus threshold'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.opto_analyserUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = opto_analyser_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.opto_analyserUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.opto_analyserUIFigure)
        end
    end
end